classdef Interference_Assess < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Panel       matlab.ui.container.Panel
        GridLayout  matlab.ui.container.GridLayout
        th2_min     matlab.ui.control.NumericEditField
        Label_2     matlab.ui.control.Label
        th1_max     matlab.ui.control.NumericEditField
        Label       matlab.ui.control.Label
        Button_3    matlab.ui.control.Button
        Button_2    matlab.ui.control.Button
        UITable     matlab.ui.control.Table
        Button      matlab.ui.control.Button
        UIAxes      matlab.ui.control.UIAxes
        UIAxes6     matlab.ui.control.UIAxes
        UIAxes5     matlab.ui.control.UIAxes
        UIAxes4     matlab.ui.control.UIAxes
        UIAxes3     matlab.ui.control.UIAxes
        UIAxes2     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        main; % Description
        cellArrayText; % Description
    end
    
    methods (Access = public)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app_parent)
            app.main = app_parent;
            app.UITable.ColumnName = {'时频域压制范围比', '压制范围内平均能量强度', '压制范围内干扰能量强度方差', '压制时间比', ...
                '时频域压制范围比2', '压制范围内平均能量强度2', '压制范围内干扰能量强度方差2', '压制时间比2' };
            

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %% 干扰信号机理的分析
            Ts = evalin('base', 'model.Parameters.NoiseGen.NoiseTs');
            fs = 1/Ts;
            
            %% 链路信号分析
            simOut = evalin('base','simOut');       
            UAV_signal = reshape(squeeze(simOut.sending_modulation_wave.Data), 1, []) ;
            plot(app.UIAxes, real(UAV_signal))
            
            data_1_effective = real(UAV_signal);
            
            % filename = strcat(pwd, '\Data\UAV_Signal');
            % save(filename, 'data_1_effective');
            
            %% 整体时频图
            win_len = 850;
            [data_stft,stft_f,stft_t] = stft(data_1_effective,fs,'Window',hann(win_len,"periodic"));
            
            imagesc(app.UIAxes2, stft_t, stft_f, 10.*log10(abs(data_stft)));  % 注意此处的时频坐标度量  %caxis([-6 -3]);
            axis(app.UIAxes2, "xy");
            colorbar(app.UIAxes2,"eastoutside");
            xlabel(app.UIAxes2,'时间t/s')
            ylabel(app.UIAxes2,'频率f/Hz')
            
            %% 干扰信号分析
            jamming_signal = reshape(squeeze(simOut.jamming_wave1.Data), 1, []) ;
            
            
            plot(app.UIAxes3, real(jamming_signal))
            
            data_2_effective = real(jamming_signal);
            
            % filename = strcat(pwd, '\Data\UAV_Signal');
            % save(filename, 'data_2_effective');
            
            %% 整体时频图
            win_len = 850;
            [data_stft_2,stft_f_2,stft_t_2] = stft(data_2_effective,fs,'Window',hann(win_len,"periodic"));
            
            imagesc(app.UIAxes4, stft_t_2, stft_f_2, 10.*log10(abs(data_stft_2)));  % 注意此处的时频坐标度量  %caxis([-6 -3]);
            axis(app.UIAxes4, "xy");
            colorbar(app.UIAxes4,"eastoutside");
            xlabel(app.UIAxes4,'时间t/s')
            ylabel(app.UIAxes4,'频率f/Hz')
            
            %% 两图同时显示
            T = stft_t_2;
            F = stft_f_2;
            A = 10.*log10(abs(data_stft_2));
            B = 10.*log10(abs(data_stft));
            
            
            % 归一化矩阵
            A_norm = (A - min(A(:))) / (max(A(:)) - min(A(:)));
            B_norm = (B - min(B(:))) / (max(B(:)) - min(B(:)));
            
            % 融合颜色（示例：红色表示A，蓝色表示B）
            merged_map = cat(3, A_norm, zeros(size(A)), B_norm);
            assignin("base", 'merged_map', merged_map);
            assignin("base", 'stft_T', T);
            assignin("base", 'stft_F', F);
            
            %% 计算指标前的预处理
            stft_threshold = max(B,[],"all")- app.th1_max.Value;
            A(A < stft_threshold) = 0;  % 干扰
            B(B < stft_threshold) = 0;  % 信号
            
            imagesc(app.UIAxes6, stft_t, stft_f, A);  % 注意此处的时频坐标度量  %caxis([-6 -3]);
            axis(app.UIAxes6, "xy");
            colorbar(app.UIAxes6, "eastoutside");
            xlabel(app.UIAxes6,'时间t/s')
            ylabel(app.UIAxes6,'频率f/Hz')
            
            imagesc(app.UIAxes5, stft_t_2, stft_f_2, B);  % 注意此处的时频坐标度量  %caxis([-6 -3]);
            axis(app.UIAxes5, "xy");
            colorbar(app.UIAxes5,"eastoutside");
            xlabel(app.UIAxes5, '时间t/s')
            ylabel(app.UIAxes5, '频率f/Hz')
            
            %% 指标一：时频域压制范围比（干扰功率大于等于信号功率的3db）
            % clc(app);
            mask_2 = (B ~= 0);
            
            % 生成逻辑矩阵：对应位置均非零
            % mask_1 = (A ~= 0) & (B ~= 0);
            
            mask_1_1 = ( (A - B)>= 3 );
            mask_1 = mask_1_1 & mask_2;
            
            % 获取位置索引
            linear_idx = find(mask_1);   % 线性索引
            
            
            % 获取位置索引
            linear_idx_2 = find(mask_2);   % 线性索引
            
            Time_frequency_domain_suppression_range_ratio = length(linear_idx)/length(linear_idx_2)*100;
            
            %% 指标二：压制范围内的平均能量强度（对信号的平均能量强度做归一化，因为单位为db，所以用减）
            % average_energy_intensity = mean(A(mask_1)) - mean(B(mask_1));
            average_energy_intensity = mean(A(mask_1)) ; % 不能做归一化，因为归一化的标准不一样

            
            %% 指标三：压制范围内干扰的能量强度方差
            average_energy_variance = std(A(mask_1)) ;
            
            %% 指标四：压制的时间比（计算每一时刻，干扰功率大于等于信号功率的3db的频率点，当超过信号频率点x%时算一个有效压制时刻,x可以自定义）
            
            % 按列求和
            column_sums_1 = sum(mask_1);
            column_sums_2 = sum(mask_2);
            Spectrum_suppression_probability = column_sums_1 ./ column_sums_2 *100;
            Suppression_time_ratio = length(column_sums_1(Spectrum_suppression_probability > 10)) ./ length(column_sums_1) * 100;

            
            %% 指标五：主要能量成分的信号的时频域压制范围比、平均能量强度、压制时间比
            A = 10.*log10(abs(data_stft_2));
            B = 10.*log10(abs(data_stft));
            
            stft_threshold = max(B,[],"all") - app.th2_min.Value;
            A(A < stft_threshold) = 0;  % 干扰
            B(B < stft_threshold) = 0;  % 信号
            
            % 时频域压制范围比
            mask_2 = (B ~= 0);
            
            % 生成逻辑矩阵：对应位置均非零
            % mask_1 = (A ~= 0) & (B ~= 0);
            
            mask_1_1 = ( (A - B)>= 3 );
            mask_1 = mask_1_1 & mask_2;
            
            % 获取位置索引
            linear_idx = find(mask_1);   % 线性索引
            
            
            % 获取位置索引
            linear_idx_2 = find(mask_2);   % 线性索引
            
            Time_frequency_domain_suppression_range_ratio_2 = length(linear_idx)/length(linear_idx_2)*100;
            
            
            % 压制范围内的平均能量强度
            average_energy_intensity_2 = mean(A(mask_1)) ; % 不能做归一化，因为归一化的标准不一样
            
            % 压制范围内的干扰的能量强度方差
            average_energy_variance_2 = std(A(mask_1)) ;
            
            
            % 压制的时间比，按列求和
            column_sums_1 = sum(mask_1);
            column_sums_2 = sum(mask_2);
            Spectrum_suppression_probability = column_sums_1 ./ column_sums_2 *100;
            Suppression_time_ratio_2 = length(column_sums_1(Spectrum_suppression_probability > 10)) ./ length(column_sums_1) * 100;
            
            T = table(Time_frequency_domain_suppression_range_ratio, average_energy_intensity, average_energy_variance, Suppression_time_ratio, ...
                Time_frequency_domain_suppression_range_ratio_2, average_energy_intensity_2, average_energy_variance_2, Suppression_time_ratio_2);
            
            app.UITable.Data = [app.UITable.Data; T];
            assignin("base", 'T', T);

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            try
                app.UITable.Data(end,:) = [];
            catch ME
                disp(ME.message);
                uiconfirm(app.UIFigure,ME.message,'Process Prompt','Icon','warning');
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            try
                app_TwoTone = TwoTone_Display(app);
            catch ME
                disp(ME.message);
                uiconfirm(app.UIFigure,'无法打开','Process Prompt','Icon','warning');
            end

            T = evalin('base', 'stft_T');
            F = evalin('base', 'stft_F');
            merged_map = evalin('base', 'merged_map');

            imagesc(app_TwoTone.UIAxes, T, F, merged_map);
            axis(app_TwoTone.UIAxes, "xy");
            axis(app_TwoTone.UIAxes,"tight");

            xlabel(app_TwoTone.UIAxes, '时间 (s)');
            ylabel(app_TwoTone.UIAxes, '频率 (Hz)');

            set(app_TwoTone.UIAxes, 'FontSize', 12,...         % 刻度字体
                     'GridLineStyle', '--',...  % 网格线型
                     'YMinorTick', 'on',...     % 显示次要刻度
                     'XMinorTick', 'on',...
                     'GridAlpha', 0.3);         % 网格透明度


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '干扰评估';
            app.GridLayout = uigridlayout(app.Panel);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, '信号stft图')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, '干扰信号')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, '干扰stft图')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.GridLayout);
            title(app.UIAxes5, '预处理后的无人机信号')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.GridLayout);
            title(app.UIAxes6, '预处理后的干扰信号')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, '无人机信号')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始评估';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '清除行数据';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '双色融合显示';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '大能量范围/db';
            app.th1_max = uieditfield(app.GridLayout, 'numeric');
            app.th1_max.Value = 20;
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '小能量范围/db';
            app.th2_min = uieditfield(app.GridLayout, 'numeric');
            app.th2_min.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interference_Assess(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end