classdef Interference_Optimization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Panel                 matlab.ui.container.Panel
        GridLayout            matlab.ui.container.GridLayout
        clear_text            matlab.ui.control.Button
        TextArea              matlab.ui.control.TextArea
        Label_21              matlab.ui.control.Label
        Panel_6               matlab.ui.container.Panel
        GridLayout6           matlab.ui.container.GridLayout
        Button_3              matlab.ui.control.Button
        go_on                 matlab.ui.control.Button
        go_stop               matlab.ui.control.Button
        track_idx             matlab.ui.control.EditField
        Label_20              matlab.ui.control.Label
        Button                matlab.ui.control.Button
        Panel_5               matlab.ui.container.Panel
        GridLayout5           matlab.ui.container.GridLayout
        singleWayCf           matlab.ui.control.EditField
        Label_11              matlab.ui.control.Label
        singleWayBw           matlab.ui.control.EditField
        EditField_5Label_2    matlab.ui.control.Label
        singleWaySection      matlab.ui.control.NumericEditField
        EditField_4Label_3    matlab.ui.control.Label
        singleWayMethod       matlab.ui.control.EditField
        EditField_3Label_3    matlab.ui.control.Label
        singleWaySrc          matlab.ui.control.EditField
        Label_10              matlab.ui.control.Label
        Button_2              matlab.ui.control.Button
        Panel_4               matlab.ui.container.Panel
        GridLayout4           matlab.ui.container.GridLayout
        constrain3            matlab.ui.control.NumericEditField
        EditField_4Label_2    matlab.ui.control.Label
        constrain1            matlab.ui.control.NumericEditField
        EditField_3Label_2    matlab.ui.control.Label
        constrain2            matlab.ui.control.NumericEditField
        EditField_2Label_2    matlab.ui.control.Label
        Panel_3               matlab.ui.container.Panel
        GridLayout3           matlab.ui.container.GridLayout
        HopSweep_timeSection  matlab.ui.control.NumericEditField
        Label_18              matlab.ui.control.Label
        dwellNumBand          matlab.ui.control.EditField
        Label_17              matlab.ui.control.Label
        intBasebandFreq       matlab.ui.control.NumericEditField
        Label_14              matlab.ui.control.Label
        maxMutiFreq           matlab.ui.control.NumericEditField
        Label_16              matlab.ui.control.Label
        narrowBwBand          matlab.ui.control.EditField
        Label_15              matlab.ui.control.Label
        power_controller_W    matlab.ui.control.NumericEditField
        Label_8               matlab.ui.control.Label
        Panel_2               matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        resumeFlag            matlab.ui.control.CheckBox
        swarmSize             matlab.ui.control.NumericEditField
        Label_7               matlab.ui.control.Label
        neighborRatio         matlab.ui.control.NumericEditField
        Label_6               matlab.ui.control.Label
        inertiaWeight         matlab.ui.control.EditField
        Label_5               matlab.ui.control.Label
        socialWeight          matlab.ui.control.NumericEditField
        EditField_5Label      matlab.ui.control.Label
        selfWeight            matlab.ui.control.NumericEditField
        Label_4               matlab.ui.control.Label
        maxIteration          matlab.ui.control.NumericEditField
        Label_2               matlab.ui.control.Label
        maxTime               matlab.ui.control.NumericEditField
        Label                 matlab.ui.control.Label
    end

    
    properties (Access = private)

    end
    
    properties (Access = public)
        main; % Description
        isPaused; % 用于全局优化的暂停
    end
    
    methods (Access = private)
        
        function ber = getBER(~, params, Source_type, Interference_method, Interference_number, Interference_sequence_number, Passband_centerFreq)
            % try
                % 加载模型，开始评估代价函数
                app_noise.source.Value = Source_type;
                app_noise.sequence.Value = Interference_method;
                app_noise.polit_centerFreq = Passband_centerFreq;
                app_noise.polit_filter_num = length(app_noise.polit_centerFreq);
        
                % 滤波器的带宽参数
                if isfield(params, 'Passband_bw')
                    app_noise.pass_bw = params.Passband_bw;
                    app_noise.transitionWidth = params.Transition_bw;
                else
                    app_noise.pass_bw = 2e6;
                    app_noise.transitionWidth = 4e5;
                end
        
                error_flag = checkConstraints_filter(app_noise);
                if error_flag == 1
                    ber = 0;
                    fprintf('不满足滤波器设计约束\n');
                    return
                end
        
                if strcmp(app_noise.source.Value, "单音正弦噪声") && strcmp(app_noise.sequence.Value, "步进调频") % 将根数结合了进去
                    app_noise.SinF.Value = params.SinF;
        
                    app_noise.StepSweepFmax.Value = params.StepSweepFmax;
                    app_noise.StepSweepFmin.Value = params.StepSweepFmin;
                    app_noise.StepSweepT.Value = params.StepSweepT;
                    app_noise.StepSweepN.Value = params.StepSweepN;
        
                    app_noise.MultiFreqSkipSequence.Value =  '0';
                    app_noise.MultiFreqSkipDistance.Value = 1e6;
                    app_noise.MultiFreqSkipTime.Value = params.StepSweepT;
        
                    if Interference_number==1
                        app_noise.MultiFreqF.Value = '0';
                    else
                        MultiFreqF_numeric = [];
                        for i = 1:Interference_number
                            MultiFreqF_numeric = [MultiFreqF_numeric eval(strcat('params.MultiFreqFValue',num2str(i)))] ;
                        end
                        app_noise.MultiFreqF.Value = num2str(MultiFreqF_numeric);
                    end
        
                    [model] = configureNoiseParameter(app_noise);
        
                    error_flag = checkConstraints_stepSweep(app_noise, Interference_number);
                    if error_flag == 1
                        ber = 0;
                        fprintf('不满足步进扫频约束\n');
                        return
                    end
        
                elseif strcmp(app_noise.source.Value, "单音正弦噪声") && strcmp(app_noise.sequence.Value, "跳频干扰")
                    app_noise.SinF.Value = params.SinF;
                    app_noise.MultiFreqSkipDistance.Value = params.MultiFreqSkipDistance;
                    app_noise.MultiFreqSkipTime.Value = params.MultiFreqSkipTime;
        
                    MultiFreqSkipSequence = [];
                    for i = 1:Interference_sequence_number
                        MultiFreqSkipSequence = [MultiFreqSkipSequence eval(strcat('params.MultiFreqSkipSequenceValue',num2str(i)))] ;
                    end
                    app_noise.MultiFreqSkipSequence.Value = num2str(MultiFreqSkipSequence);


                    if Interference_number==1
                        app_noise.MultiFreqF.Value = '0';
                    else
                        MultiFreqF_numeric = [];
                        for i = 1:Interference_number
                            MultiFreqF_numeric = [MultiFreqF_numeric eval(strcat('params.MultiFreqFValue',num2str(i)))] ;
                        end
                        app_noise.MultiFreqF.Value = num2str(MultiFreqF_numeric);
                    end
        
                   [model] = configureNoiseParameter(app_noise);
                   error_flag = checkConstraints_hopping(app_noise, Interference_number);
                   if error_flag == 1
                        ber = 0;
                        fprintf('不满足跳频扫频约束\n');
                        return
                   end
        
                elseif strcmp(app_noise.source.Value, "随机二元码调制噪声") && strcmp(app_noise.sequence.Value, "步进调频")
                    app_noise.RandBFZero.Value = params.RandBFZero;
                    app_noise.RandBFF.Value = params.RandBFF;
                    app_noise.RandBCodeP.Value = params.RandBCodeP;
        
        
                    app_noise.StepSweepFmax.Value = params.StepSweepFmax;
                    app_noise.StepSweepFmin.Value = params.StepSweepFmin;
                    app_noise.StepSweepT.Value = params.StepSweepT;
                    app_noise.StepSweepN.Value = params.StepSweepN;
        
                    app_noise.MultiFreqSkipSequence.Value =  '0';
                    app_noise.MultiFreqSkipDistance.Value = 1e6;
                    app_noise.MultiFreqSkipTime.Value = params.StepSweepT;
        
                    if Interference_number==1
                        app_noise.MultiFreqF.Value = '0';
                    else
                        MultiFreqF_numeric = [];
                        for i = 1:Interference_number
                            MultiFreqF_numeric = [MultiFreqF_numeric eval(strcat('params.MultiFreqFValue',num2str(i)))] ;
                        end
                        app_noise.MultiFreqF.Value = num2str(MultiFreqF_numeric);
                    end
        
                   [model] = configureNoiseParameter(app_noise);
        
                   error_flag = checkConstraints_stepSweep(app_noise, Interference_number);
                   if error_flag == 1
                        ber = 0;
                        fprintf('不满足步进扫频约束\n');
                        return
                   end
        
                elseif strcmp(app_noise.source.Value, "随机二元码调制噪声") && strcmp(app_noise.sequence.Value, "跳频干扰")
                    app_noise.RandBFZero.Value = params.RandBFZero;
                    app_noise.RandBFF.Value = params.RandBFF;
                    app_noise.RandBCodeP.Value = params.RandBCodeP;
        
                    MultiFreqSkipSequence = [];
                    for i = 1:Interference_sequence_number
                        MultiFreqSkipSequence = [MultiFreqSkipSequence eval(strcat('params.MultiFreqSkipSequenceValue',num2str(i)))] ;
                    end
                    app_noise.MultiFreqSkipSequence.Value = num2str(MultiFreqSkipSequence);
                    app_noise.MultiFreqSkipDistance.Value = params.MultiFreqSkipDistance;
                    app_noise.MultiFreqSkipTime.Value = params.MultiFreqSkipTime;
        
                    if Interference_number==1
                        app_noise.MultiFreqF.Value = '0';
                    else
                        MultiFreqF_numeric = [];
                        for i = 1:Interference_number
                            MultiFreqF_numeric = [MultiFreqF_numeric eval(strcat('params.MultiFreqFValue',num2str(i)))] ;
                        end
                        app_noise.MultiFreqF.Value = num2str(MultiFreqF_numeric);
                    end
        
                   [model] = configureNoiseParameter(app_noise);
                   error_flag = checkConstraints_hopping(app_noise, Interference_number);
                   if error_flag == 1
                        ber = 0;
                        fprintf('不满足跳频扫频约束\n');
                        return
                   end
        
              elseif strcmp(app_noise.source.Value, "PN码MSK噪声") && strcmp(app_noise.sequence.Value, "步进调频")
                    app_noise.PNMSKM.Value = params.PNMSKM;
                    app_noise.PNMSKF.Value = params.PNMSKF;
        
                     app_noise.StepSweepFmax.Value = params.StepSweepFmax;
                    app_noise.StepSweepFmin.Value = params.StepSweepFmin;
                    app_noise.StepSweepT.Value = params.StepSweepT;
                    app_noise.StepSweepN.Value = params.StepSweepN;
        
                    app_noise.MultiFreqSkipSequence.Value =  '0';
                    app_noise.MultiFreqSkipDistance.Value = 1e6;
                    app_noise.MultiFreqSkipTime.Value = params.StepSweepT;
        
                    if Interference_number==1
                        app_noise.MultiFreqF.Value = '0';
                    else
                        MultiFreqF_numeric = [];
                        for i = 1:Interference_number
                            MultiFreqF_numeric = [MultiFreqF_numeric eval(strcat('params.MultiFreqFValue',num2str(i)))] ;
                        end
                        app_noise.MultiFreqF.Value = num2str(MultiFreqF_numeric);
                    end
        
                   [model] = configureNoiseParameter(app_noise);
        
                   error_flag = checkConstraints_stepSweep(app_noise, Interference_number);
                   if error_flag == 1
                        ber = 0;
                        fprintf('不满足步进扫频约束\n');
                        return
                   end
        
               elseif strcmp(app_noise.source.Value, "PN码MSK噪声") && strcmp(app_noise.sequence.Value, "跳频干扰")
                    app_noise.PNMSKM.Value = params.PNMSKM;
                    app_noise.PNMSKF.Value = params.PNMSKF;
        
                    MultiFreqSkipSequence = [];
                    for i = 1:Interference_sequence_number
                        MultiFreqSkipSequence = [MultiFreqSkipSequence eval(strcat('params.MultiFreqSkipSequenceValue',num2str(i)))] ;
                    end
                    app_noise.MultiFreqSkipSequence.Value = num2str(MultiFreqSkipSequence);
                    app_noise.MultiFreqSkipDistance.Value = params.MultiFreqSkipDistance;
                    app_noise.MultiFreqSkipTime.Value = params.MultiFreqSkipTime;
        
                    if Interference_number==1
                        app_noise.MultiFreqF.Value = '0';
                    else
                        MultiFreqF_numeric = [];
                        for i = 1:Interference_number
                            MultiFreqF_numeric = [MultiFreqF_numeric eval(strcat('params.MultiFreqFValue',num2str(i)))] ;
                        end
                        app_noise.MultiFreqF.Value = num2str(MultiFreqF_numeric);
                    end
        
                   [model] = configureNoiseParameter(app_noise);
                   error_flag = checkConstraints_hopping(app_noise, Interference_number);
                   if error_flag == 1
                        ber = 0;
                        fprintf('不满足跳频扫频约束\n');
                        return
                   end
        
                elseif strcmp(app_noise.source.Value, "脉冲噪声") && strcmp(app_noise.sequence.Value, "步进调频")
                    app_noise.pulseNoise_centerFreq.Value = params.pulseNoise_centerFreq;
                    app_noise.pulseNoise_pass_bw.Value = params.pulseNoise_pass_bw;
                    
                    app_noise.StepSweepFmax.Value = params.StepSweepFmax;
                    app_noise.StepSweepFmin.Value = params.StepSweepFmin;
                    app_noise.StepSweepT.Value = params.StepSweepT;
                    app_noise.StepSweepN.Value = params.StepSweepN;
        
                    app_noise.MultiFreqSkipSequence.Value =  '0';
                    app_noise.MultiFreqSkipDistance.Value = 1e6;
                    app_noise.MultiFreqSkipTime.Value = params.StepSweepT;
        
                    if Interference_number==1
                        app_noise.MultiFreqF.Value = '0';
                    else
                        MultiFreqF_numeric = [];
                        for i = 1:Interference_number
                            MultiFreqF_numeric = [MultiFreqF_numeric eval(strcat('params.MultiFreqFValue',num2str(i)))] ;
                        end
                        app_noise.MultiFreqF.Value = num2str(MultiFreqF_numeric);
                    end
        
                   [model] = configureNoiseParameter(app_noise);
        
                   error_flag = checkConstraints_stepSweep(app_noise, Interference_number);
                   if error_flag == 1
                        ber = 0;
                        fprintf('不满足步进扫频约束\n');
                        return
                   end
        
               elseif strcmp(app_noise.source.Value, "脉冲噪声") && strcmp(app_noise.sequence.Value, "跳频干扰")
                   app_noise.pulseNoise_centerFreq.Value = params.pulseNoise_centerFreq;
                   app_noise.pulseNoise_pass_bw.Value = params.pulseNoise_pass_bw;
                   
                   MultiFreqSkipSequence = [];
                    for i = 1:Interference_sequence_number
                        MultiFreqSkipSequence = [MultiFreqSkipSequence eval(strcat('params.MultiFreqSkipSequenceValue',num2str(i)))] ;
                    end
                    app_noise.MultiFreqSkipSequence.Value = num2str(MultiFreqSkipSequence);
                    app_noise.MultiFreqSkipDistance.Value = params.MultiFreqSkipDistance;
                    app_noise.MultiFreqSkipTime.Value = params.MultiFreqSkipTime;
        
                    if Interference_number==1
                        app_noise.MultiFreqF.Value = '0';
                    else
                        MultiFreqF_numeric = [];
                        for i = 1:Interference_number
                            MultiFreqF_numeric = [MultiFreqF_numeric eval(strcat('params.MultiFreqFValue',num2str(i)))] ;
                        end
                        app_noise.MultiFreqF.Value = num2str(MultiFreqF_numeric);
                    end
        
                   [model] = configureNoiseParameter(app_noise);
                   error_flag = checkConstraints_hopping(app_noise, Interference_number);
                   if error_flag == 1
                        ber = 0;
                        fprintf('不满足跳频扫频约束\n');
                        return
                   end
        
                end
        
        %%     将设定好的干扰源参数输入，并将链路参数输入，将APP解耦，直接运行模型
                % 其他参数设置
                global DataLink_config; 
                global Interference_other_config; 
        
                power_controller_coefficience = 1;%初始默认功率控制系数为1
                power_controller_W = Interference_other_config.power_controller_W;
                quick_simulation_flag = 0;
                assignin('base','quick_simulation_flag',quick_simulation_flag);
            
                % 设置噪声源的采样时间、干扰功率系数，以及根据各判断类型生成对应的噪声参数
                sampleTGap = 3.5e-9;  % 示例参数
                noise_simulation_time = DataLink_config.UAV_datalink_simulation_time;
            
                model.Parameters.NoiseGen.NoiseTs = sampleTGap;
                model.Parameters.jammer_power_controller = sqrt(power_controller_coefficience);
            
                % 针对噪声源干扰单独设置的误码率延时和链路延时结构功能
                model.Parameters.ErrorRateDelay = Interference_other_config.ErrorRateDelay;
        
                % 设置干扰源的带宽
                model.Parameters.NoiseGen.noise_filter_b = designMutiBandpassFilter(app_noise.polit_filter_num, app_noise.polit_centerFreq, app_noise.pass_bw, app_noise.transitionWidth, 1/sampleTGap);
                if strcmp(app_noise.source.Value, "脉冲噪声")
                    model.Parameters.NoiseGen.pulse_noise_filter_b = designMutiBandpassFilter(1, app_noise.pulseNoise_centerFreq.Value, app_noise.pulseNoise_pass_bw.Value, app_noise.transitionWidth, 1/sampleTGap);
                end
        
                %运行Jammer_Generator.slx模型并设定仿真时间
                assignin('base','model',model);
                simOut = sim("Jammer_Generator.slx", 'stoptime', num2str(noise_simulation_time));
                model.Parameters.NoiseGen.NoiseData = squeeze(simOut.Noise.Data);
        
                 %% 这部分为添加的二次仿真，目的是使仿真的功率控制可以一次实现
                extend_multiple_2 = ceil(length(simOut.tout) / length(simOut.NoisePowerEstimation.Data));
                Power_Estimate = reshape(repmat(reshape(simOut.NoisePowerEstimation.Data,1,[]),extend_multiple_2,1),1,[]);
                Power_Estimate_mean = mean(Power_Estimate(end-round(length(Power_Estimate)/2):end));
        
                % 功率估计，显示最终时间的平均功率值
                str_display = strcat( "平均功率估计为：", num2str(Power_Estimate_mean));
                disp(str_display);
        
                if ~strcmp(app_noise.source.Value, "随机二元码调制噪声") && ~strcmp(app_noise.source.Value, "PN码MSK噪声")
                    Power_error = Interference_other_config.Power_error_1;
                else
                    Power_error = Interference_other_config.Power_error_2;
                end
                
        
                while (abs(Power_Estimate_mean - power_controller_W) >= Power_error )
                    power_controller_coefficience = power_controller_coefficience * power_controller_W / Power_Estimate_mean;
                    model.Parameters.jammer_power_controller = sqrt(power_controller_coefficience);
                    assignin('base','model',model);
                    simOut = sim("Jammer_Generator.slx", 'stoptime', num2str(noise_simulation_time));
                    model.Parameters.NoiseGen.NoiseData = squeeze(simOut.Noise.Data);
                    extend_multiple_2 = ceil(length(simOut.tout) / length(simOut.NoisePowerEstimation.Data));
                    Power_Estimate = reshape(repmat(reshape(simOut.NoisePowerEstimation.Data,1,[]),extend_multiple_2,1),1,[]);
                    Power_Estimate_mean = mean(Power_Estimate(end-round(length(Power_Estimate)/2):end));
        
                    % 功率估计，显示最终时间的平均功率值
                    str_display = strcat( "平均功率估计为：", num2str(Power_Estimate_mean));
                    disp(str_display);
                end
            
                % 输入信号传参
                model.user_defined.Repeating_Sequence_Stair.output_vector = DataLink_config.Repeating_Sequence_Stair_output_vector;
                model.user_defined.Repeating_Sequence_Stair.CodeTs = DataLink_config.Repeating_Sequence_Stair_CodeTs;
                model.user_defined.CarrierSkipF.SkipF_vector = DataLink_config.CarrierSkipF_SkipF_vector;
                model.user_defined.CarrierSkipF.SkipF_Num = DataLink_config.CarrierSkipF_SkipF_Num ;
                model.user_defined.CarrierSkipF.SkipF_distance = DataLink_config.CarrierSkipF_SkipF_distance ;
                model.user_defined.CarrierSkipF.SkipF_time = DataLink_config.CarrierSkipF_SkipF_time ;
                model.user_defined.M_PAM_Modulator_Baseband.M_ary_number = DataLink_config.M_PAM_Modulator_Baseband_M_ary_number ;
            
                model.user_defined.Raised_Cosine_Transmit_Filter.Rolloff_factor = DataLink_config.Rolloff_factor ;
                model.user_defined.Raised_Cosine_Transmit_Filter.Filter_span_in_symbols = DataLink_config.Filter_span_in_symbols ;
                model.user_defined.Raised_Cosine_Transmit_Filter.Output_samples_per_symbol = DataLink_config.Output_samples_per_symbol ;
            
                model.user_defined.Digital_Clock2.CarrierTs = DataLink_config.Digital_Clock2_CarrierTs ;
                model.user_defined.func1.f_chirp_max = DataLink_config.f_chirp_max ;
                model.user_defined.func1.f_chirp_min = DataLink_config.f_chirp_min ;
                model.user_defined.func1.T_chirp = DataLink_config.T_chirp ;
            
                model.user_defined.gain3.value = DataLink_config.gain3 ;
                model.user_defined.gain1.value = DataLink_config.gain1 ;
            
                model.user_defined.Awgn.SNR = DataLink_config.Awgn_SNR ;
                model.user_defined.Awgn.input_power = DataLink_config.Awgn_input_power ;
                model.user_defined.TR.Sig_power_controller_coeffience = DataLink_config.TR_Sig_power_controller_coeffience ;
            
                assignin("base", 'model', model);
                assignin('base','UAV_datalink_simulation_time',DataLink_config.UAV_datalink_simulation_time );
            
                simOut = sim("Simple_Model_Packaging_7.slx",'stoptime',num2str(DataLink_config.UAV_datalink_simulation_time )); %运行Sine.slx模型并设定仿真时间为B
            
                % 误码率曲线
                error_bit_output = simOut.CodeBER.Data;
                extend_multiple = ceil(length(simOut.tout) / length(error_bit_output));
                error_rate = reshape(repmat(error_bit_output(:,1)',extend_multiple,1),1,[]);
                error_rate = error_rate(1:length(simOut.tout));
            
                ber = error_rate(end);
            
            % catch ME
            %     ber = 0;
            %     fprintf('评估函数存在问题: %s\n', ME.message);
            % end


end
        
         % 回调函数实现
            function stop = save_results(~, optimValues, state)
                persistent history;
                if isempty(history)
                    history = struct('X', {}, 'Fval', {}, 'Iter', {});
                end
                % 记录当前迭代信息
                history(end+1).X = optimValues.bestx;
                history(end).Fval = optimValues.bestfval;
                history(end).Iter = optimValues.iteration;
            
                global MM_config;
            
                if strcmp(state,'done')
                    history(end+1).X = [];
                    history(end).Fval = [];
                    history(end).Iter = [];
            
                    % 保存到文件（追加模式）
                    if exist(MM_config.history_weight_parameter_file_dir, 'file')
                        save(MM_config.history_weight_parameter_file_dir, 'history', '-append');
                    else
                        save(MM_config.history_weight_parameter_file_dir, 'history');
                    end
                end
            
                if strcmp(state,'iter') | strcmp(state,'done')
                    files = dir(fullfile(MM_config.cache_dir, '/*'));
                    total_size = sum(arrayfun(@(x) x.bytes, files));
                    disp(['当前缓存占用: ', num2str(total_size/1e6), 'MB']);  
            
                    if total_size > MM_config.MinM_room % 当占用空间>40GB时
                        Simulink.sdi.clear;
                    end
                end
            
                cachePath = fullfile(prefdir, 'slcache');
                slprjPath = fullfile(pwd, 'slprj');
            
                % 删除缓存目录
                if exist(cachePath, 'dir')
                    rmdir(cachePath, 's');
                end
                if exist(slprjPath, 'dir')
                    rmdir(slprjPath, 's');
                end
            
                stop = false; % 继续迭代
            end
    end
    
    methods (Access = public)
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app_parent)
            app.main = app_parent;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % 初始化参数
            global_config = struct(...
                'MaxTime', round(app.maxTime.Value * 3600),...  % 最大优化时间，单位为秒
                'MaxIterations', app.maxIteration.Value,...   % 最大迭代次数，推荐为500
                'SwarmSize', app.swarmSize.Value,...       % 种群数目，推荐为50
                'Second_optimization_MaxIterations', 2, ...  % 二次优化的最大迭代次数，推荐为100
                'UseParallel', false,...                     % 是否使用并行
                'Parallel_CoreNum', 10,...                   % 若使用并行，并行核数设置
                'Resume_flag', app.resumeFlag.Value,...              % 是否接续训练
                'SelfAdjustmentWeight', app.selfWeight.Value, ...     % 粒子群的个体学习因子
                'SocialAdjustmentWeight', app.socialWeight.Value, ... % 粒子群的社会学习因子
                'InertiaRange', str2num(app.inertiaWeight.Value), ... % 粒子群的惯性权重范围
                'MinNeighborsFraction', app.neighborRatio.Value, ...  % 粒子群的邻域粒子比例
                'HybridFcn', [], ...                         % 二次优化器选择，可选项为'fmincon'、'fminsearch'、'fminunc'、'patternsearch'、[]
                'SourceTypes', 2, ...                        % 生成干扰策略时的干扰源类型数目
                'InterferenceTypes', 2, ...                  % 生成干扰策略时的干扰方式数目
                'BandwidthOptions', 2, ...                   % 生成干扰策略时的带宽选项数目
                'SequenceNum', app.HopSweep_timeSection.Value, ...                        % 生成干扰策略时的干扰时域跳频序列数目
                'InterferenceNums', app.singleWaySection.Value ...                    % 生成干扰策略时的干扰划分频段数
            );
            
            global MM_config; 
            MM_config = struct(...
                'cache_dir', 'C:/Users/89778/AppData/Local/Temp',...    % simulink缓存路径，定期删除会用到
                'history_weight_parameter_file_dir', './Weight_optimize/pso_history7.mat',...     % 优化过程中的历史权重文件目录
                'final_weight_parameter_file_dir',   './Weight_optimize/pso_final_result7',...     % 优化后的权重文件目录
                'MinM_room', 40e9 ...                                   % simulink缓存占用后的最小剩余空间
            );
            
            global DataLink_config; 
            DataLink_config = struct(...
                'Repeating_Sequence_Stair_output_vector', str2num(app.main.output_vector.Value) ,...   % 数据链的重复序列周期单元
                'Repeating_Sequence_Stair_CodeTs', app.main.CodeTs.Value,...                      % 数据链的码元速率
                'CarrierSkipF_SkipF_vector', str2num(app.main.SkipF_vector.Value) , ... % 数据链的跳频周期序列
                'CarrierSkipF_SkipF_Num', app.main.SkipF_Num.Value, ...                                  % 数据链的最大跳频点数
                'CarrierSkipF_SkipF_distance', app.main.SkipF_distance.Value, ...                            % 数据链的跳频间隔
                'CarrierSkipF_SkipF_time', app.main.Skip_time.Value, ...                              % 数据链的跳频驻留时间
                'M_PAM_Modulator_Baseband_M_ary_number', app.main.M_Number.Value, ...                    % 数据链的基带调制进制数
                'Rolloff_factor', 0.3, ...                                         % 数据链的滚降系数
                'Filter_span_in_symbols', 10, ...                                  % 数据链的滚降滤波器脉冲响应��覆盖的符号周期数��
                'Output_samples_per_symbol', 100, ...                              % 数据链的滚降滤波每个符号的输出样本数
                'Digital_Clock2_CarrierTs', app.main.Sample_Rate.Value, ...                           % 数据链发射机载波的采样周期
                'f_chirp_max', app.main.f_chirp_max.Value, ...                                        % 数据链发射机最低频段载波的变频终点
                'f_chirp_min', app.main.f_chirp_min.Value, ...                                        % 数据链发射机最低频段载波的变频起点
                'T_chirp', app.main.T_chirp.Value, ...                                            % 数据链发射机载波的变频周期
                'gain3', 0.4, ...                                                  % 数据链发射机信号中本地载波的分量
                'gain1', 0.714, ...                                                % 数据链发射机混合信号的第一次缩放倍数
                'Awgn_SNR', 15, ...                                                % 数据链的信噪比
                'Awgn_input_power' , app.main.Signal_Power.Value, ...                                            % 数据链发射信号的功率，用于确定信噪比的噪声功率
                'TR_Sig_power_controller_coeffience', sqrt(app.main.sig_power_controller_coeffience.Value), ...  % 数据链发射信号的放大倍数，用于控制发射功率
                'UAV_datalink_simulation_time', app.main.simulation_time.Value ...                               % 数据链的仿真时间，现为一个跳频序列周期
            );
            
            global Interference_other_config; 
            Interference_other_config = struct(...
                'power_controller_W', app.power_controller_W.Value, ...       % 干扰源功率
                'ErrorRateDelay', 920, ...          % 接收机与发射机误码比较延时
                'Power_error_1', 0.1,  ...          % 干扰源功率标定误差范围1，用于"单音正弦噪声"和"脉冲噪声"
                'Power_error_2', 0.4 ...            % 干扰源功率标定误差范围2，用于"随机二元码调制噪声"和"PN码MSK噪声"
            );
            
            global Constraint_condition_config;
            Constraint_condition_config = struct(...
                'Transition_zone_minimum_gap', app.constrain1.Value, ...    % 确保多通带滤波器设计时，各通带之间的阻带至少有150kHz
                'Sweep_freq_rate_upperLimit_factor', app.constrain2.Value, ... % 用于限制扫频速率上限，主要原因还是驻留时间存在最小值限制，要确保扫频时每个频域波束能量足够集中
                'Sweep_freq_Highest_freq', app.constrain3.Value * 2  ...   % 扫频范围限制，防止信号扫出信号频率太多范围，造成功率浪费
            );


            global Interference_source_config;
            PulseNoise_bwBand = str2num(app.narrowBwBand.Value);
            Interference_source_config = struct(...
                'source_list', ["单音正弦噪声","脉冲噪声"], ...   % 四种干扰源类型
                'method_list', ["步进调频","跳频干扰"], ...        % 两种干扰方式
                'bw_list', ["True","False"], ...                  % 是否优化带宽选项
                'SinNoise_lb', app.intBasebandFreq.Value,  ...                        % 正弦干扰下限，主要是载频
                'SinNoise_ub', app.intBasebandFreq.Value, ...                         % 正弦干扰上限
                'PulseNoise_lb', [app.intBasebandFreq.Value PulseNoise_bwBand(1)], ...             % 脉冲干扰下限，主要是中心频率、带宽
                'PulseNoise_ub', [app.intBasebandFreq.Value PulseNoise_bwBand(2)] ...              % 脉冲干扰上限                   
            );
            
            Optimized_interference_sources_config = struct(...
                'Passband_centerFreq', eval(app.singleWayCf.Value), ... % 干扰源的通带中心频率设置
                'Interference_number', app.singleWaySection.Value ...     % 干扰分段频段数选择
            );

            % 干扰方式须额外添加
            MutiFreqInt_Endpoint = app.maxMutiFreq.Value - Interference_source_config.SinNoise_lb;
            
            Sweep_eachFreqband = MutiFreqInt_Endpoint/Optimized_interference_sources_config.Interference_number;
            StepSweep_freq_up = Sweep_eachFreqband - (Interference_source_config.SinNoise_ub-Interference_source_config.SinNoise_lb);
            StepSweep_time_up = app.main.Skip_time.Value;

            HopSweep_freq_up = Sweep_eachFreqband/Optimized_interference_sources_config.Interference_number;
            HopSweep_time_up = app.main.Skip_time.Value/global_config.SequenceNum ;     
            
            dwellPointNumBand = str2num(app.dwellNumBand.Value);
            Interference_source_config.StepSweep_lb =  [0, 0, StepSweep_time_up, dwellPointNumBand(1)];                       % 步进扫频下限，主要是扫频频率上限、扫频频率下限、步进扫频周期、扫频点数
            Interference_source_config.StepSweep_ub =  [StepSweep_freq_up, StepSweep_freq_up, StepSweep_time_up, dwellPointNumBand(2)];  % 步进扫频上限
            Interference_source_config.HopSweep_lb =  [1e5, HopSweep_time_up];                % 跳频扫频下限，主要是跳频频率最小间隔、跳频驻留时间
            Interference_source_config.HopSweep_ub =  [HopSweep_freq_up, HopSweep_time_up];   % 跳频扫频上限
            Interference_source_config.Interference_freq_start_point = 0;                     % 多频段分段干扰时，扫频干扰频率起点
            Interference_source_config.Interference_freq_end_point = MutiFreqInt_Endpoint;    % 多频段分段干扰时，扫频干扰频率终点
            Interference_source_config.Sweep_freq_offset = 2e6;                               % 多频段分段干扰时，扫频范围偏移
            
            % 定义一个结构体数组，每个元素对应一种情况
            scenarios = generateScenarios('SourceTypes', global_config.SourceTypes, 'InterferenceNums',global_config.InterferenceNums, 'InterferenceTypes', ...
                global_config.InterferenceTypes, 'BandwidthOptions', global_config.BandwidthOptions, 'SequenceNum', global_config.SequenceNum);
            
            % 创建中断检查标志文件路径
            interruptFile = fullfile(pwd, 'INTERRUPT_PAUSE.flag');

            delete('INTERRUPT_PAUSE.flag'); % 删除标记文件
            app.isPaused = false;  % 直接修改属性解除阻塞
            
            % 选取待优化的干扰策略
            track_sequence = eval(app.track_idx.Value);
            
            for Interference_strategy_index_idx = 1 :length(track_sequence)

                Interference_strategy_index = track_sequence(Interference_strategy_index_idx);

                % ====================== 添加循环信息打印 ======================
                fprintf('\n==== 当前运行: %d/%d (干扰策略索引 %d) ====\n', ...
                        Interference_strategy_index, ...  % 当前计数
                        length(track_sequence), ...           % 总数量
                        Interference_strategy_index);         % 绝对索引

                app.TextArea.Value = [app.TextArea.Value; strcat('==== 当前运行:',num2str(Interference_strategy_index),'/', num2str(length(track_sequence)),'(干扰策略索引 ',num2str(Interference_strategy_index),') ====') datestr(now, 'HH:MM:SS')];
                
                % ====================== 添加暂停检查点 ======================
                if exist(interruptFile, 'file')
                    fprintf('\n>>>> 检测到中断文件 (INTERRUPT_PAUSE.flag) <<<<\n');
                    fprintf('>>>> 程序已暂停 <<<<\n');
                    app.TextArea.Value = [app.TextArea.Value;'>>>> 检测到中断文件 (INTERRUPT_PAUSE.flag) <<<<' datestr(now, 'HH:MM:SS')];
                    app.TextArea.Value = [app.TextArea.Value;'>>>> 程序已暂停 <<<<' datestr(now, 'HH:MM:SS')];

                    % 等待用户操作（删除文件或按回车）
                    while app.isPaused && isvalid(app.UIFigure)
                        pause(0.05);      % 短暂暂停降低 CPU 占用
                        drawnow;          % 允许 GUI 回调执行
                    end
                    fprintf('>>>> 程序继续执行 <<<<\n');
                    app.TextArea.Value = [app.TextArea.Value;'>>>> 程序继续执行 <<<<' datestr(now, 'HH:MM:SS')];
                end
            
                % 查找当前情况对应的参数
                matching_scenarios = scenarios(Interference_strategy_index);
            
                % 检查是否找到了匹配的情况
                if isempty(matching_scenarios)
                    error('No matching scenario found.');
                end
            
                scenario = matching_scenarios(1); % 获取第一个匹配的情况
            
                % 加载历史记录（若存在）
                if global_config.Resume_flag
                    load(MM_config.history_weight_parameter_file_dir, 'history');
                    best_idx = find([history.Fval] == min([history.Fval]), 1); % 提取历史最优解作为初始值
                    x0 = history(best_idx).X;
                else
                    history = [];
                    x0 = scenario.x0; % 默认初始值
                end
            
                % 二次优化参数选择
                if isempty(global_config.HybridFcn)
                    HybridFcn_total = [];
                else
                    hybridopts = optimoptions(global_config.HybridFcn, ...
                        'OptimalityTolerance',1e-7, ...
                        'Algorithm', 'interior-point', ... % 使用支持并行的算法
                        'UseParallel', global_config.UseParallel, ...           % 启用并行计算
                        'Display', 'iter', ...
                        'MaxIterations', global_config.Second_optimization_MaxIterations);
                    HybridFcn_total = {global_config.HybridFcn, hybridopts};
                end
            
                % 粒子群优化参数选择
                options = optimoptions( ...
                    'particleswarm', 'SwarmSize', global_config.SwarmSize, ...
                    'MaxIterations', global_config.MaxIterations, ...
                    'PlotFcn', @pswplotbestf, ...
                    'Display', 'iter', ...
                    'OutputFcn', @(x, state) save_results(app, x, state), ...
                    'DisplayInterval', 1, ...
                    'InitialSwarmMatrix', x0, ...
                    'UseParallel', global_config.UseParallel, ...
                    'MaxTime', global_config.MaxTime, ...
                    'HybridFcn', HybridFcn_total, ...
                    'SelfAdjustmentWeight',global_config.SelfAdjustmentWeight, ...
                    'SocialAdjustmentWeight',global_config.SocialAdjustmentWeight, ...
                    'InertiaRange', global_config.InertiaRange, ...
                    'MinNeighborsFraction', global_config.MinNeighborsFraction, ...
                    'PlotFcn', 'pswplotbestf' );
            
                % 可以构造匿名函数的文本表示来解决：eval，根据额外参数的数量调整适应度函数
                fun_str = createBERFunction(scenario);
                fun = eval(fun_str);
                % fun = @(x)-getBER(app, struct('SinF',round(x(1)),'StepSweepFmax',round(x(2)),'StepSweepFmin',round(x(3)),'StepSweepT',x(4),'StepSweepN',round(x(5))),"单音正弦噪声","步进调频",scenario.Interference_number,scenario.Interference_sequence_number,Optimized_interference_sources_config.Passband_centerFreq);
            
                % 粒子群优化范围
                lb = scenario.lb;
                ub = scenario.ub;
                feature_num = numel(lb);
            
                % 是否启用并行，启用的话Need to switch all workers to a separate tempdir in case any code is generated for instance for StateFlow, or any other
                % file artifacts are  created by the model.
                if global_config.UseParallel == true
                    if ~isempty(gcp('nocreate'))
                        parpool('local', global_config.Parallel_CoreNum);
                    end
                    load_system("Jammer_Generator.slx");
                    load_system("Simple_Model_Packaging_7.slx");
                    spmd
                        % Setup tempdir and cd into it
                        currDir = pwd;
                        addpath(currDir);
                        tmpDir = tempname;
                        mkdir(tmpDir);
                        cd(tmpDir);
                        % Load the model on the worker
                        load_system("Jammer_Generator.slx");
                        load_system("Simple_Model_Packaging_7.slx");
                    end
                end
            
                cachePath = fullfile(prefdir, 'slcache');
                slprjPath = fullfile(pwd, 'slprj');
            
                % 删除缓存目录
                if exist(cachePath, 'dir')
                    rmdir(cachePath, 's');
                end
                if exist(slprjPath, 'dir')
                    rmdir(slprjPath, 's');
                end
            
                % 启用粒子群优化
                % profile on
                [x,fval,exitflag,output] = particleswarm(fun, feature_num, lb, ub, options);
            
                % profile off
                % profile viewer
            
                % 是否启用并行，启用的话就Switch all of the workers back to their original folder.
                if global_config.UseParallel == true
                    if ~isempty(gcp('nocreate'))
                        spmd
                            cd(currDir);
                            rmdir(tmpDir,'s');
                            rmpath(currDir);
                            close_system("Jammer_Generator.slx", 0);
                            close_system("Simple_Model_Packaging_7.slx", 0);
                        end
            
                        close_system("Jammer_Generator.slx", 0);
                        close_system("Simple_Model_Packaging_7.slx", 0);
                        delete(gcp('nocreate'));
                    end
                end
            
            
                % 保存变量
                filename = sprintf([MM_config.final_weight_parameter_file_dir, '_%s.mat'], datestr(now, 'yyyy-mm-dd_HH-MM-SS')) ;
                save(filename, 'x', 'fval', 'exitflag', 'output', 'fun');
            
                % ===> 显示结果 ====
                result = strcat('最佳参数结果:',num2str(x,'%.1f'));
                disp(result);
                result_2 = strcat('最佳目标结果:',num2str(fval,'%.4f'));
                disp(result_2);

                app.TextArea.Value = [app.TextArea.Value;result];
                app.TextArea.Value = [app.TextArea.Value;result_2];

                % ====================== 添加进度条分隔符 ======================
                progress = Interference_strategy_index/ length(track_sequence);
                fprintf([repmat('=', 1, round(50 * progress)), '>\n']);
            end


        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
          
            % 初始化参数
            global_config = struct(...
                'MaxTime', round(app.maxTime.Value * 3600),...  % 最大优化时间，单位为秒
                'MaxIterations', app.maxIteration.Value,...   % 最大迭代次数，推荐为500
                'SwarmSize', app.swarmSize.Value,...       % 种群数目，推荐为50
                'Second_optimization_MaxIterations', 2, ...  % 二次优化的最大迭代次数，推荐为100
                'UseParallel', false,...                     % 是否使用并行
                'Resume_flag', app.resumeFlag.Value,...      % 是否接续训练
                'Parallel_CoreNum', 10,...                   % 若使用并行，并行核数设置
                'SelfAdjustmentWeight', app.selfWeight.Value, ...      % 粒子群的个体学习因子
                'SocialAdjustmentWeight', app.socialWeight.Value, ...  % 粒子群的社会学习因子
                'InertiaRange', str2num(app.inertiaWeight.Value), ...  % 粒子群的惯性权重范围
                'MinNeighborsFraction', app.neighborRatio.Value, ...   % 粒子群的邻域粒子比例
                'HybridFcn', [], ...                         % 二次优化器选择，可选项为'fmincon'、'fminsearch'、'fminunc'、'patternsearch'、[]
                'SourceTypes', 2, ...                        % 生成干扰策略时的干扰源类型数目
                'InterferenceTypes', 2, ...                  % 生成干扰策略时的干扰方式数目
                'BandwidthOptions', 2, ...                   % 生成干扰策略时的带宽选项数目
                'SequenceNum', app.HopSweep_timeSection.Value, ...                        % 生成干扰策略时的干扰时域跳频序列数目
                'InterferenceNums', app.singleWaySection.Value ...                    % 生成干扰策略时的干扰划分频段数
            );
            
            global MM_config; 
            MM_config = struct(...
                'cache_dir', 'C:/Users/89778/AppData/Local/Temp',...    % simulink缓存路径，定期删除会用到
                'history_weight_parameter_file_dir', './Weight_optimize/pso_final_result7',...     % 优化过程中的历史权重文件目录
                'final_weight_parameter_file_dir',   './Weight_optimize/pso_final_result7',...     % 优化后的权重文件目录
                'MinM_room', 40e9 ...                                   % simulink缓存占用后的最小剩余空间
            );
            
            global DataLink_config; 
            DataLink_config = struct(...
                'Repeating_Sequence_Stair_output_vector', str2num(app.main.output_vector.Value) ,...   % 数据链的重复序列周期单元
                'Repeating_Sequence_Stair_CodeTs', app.main.CodeTs.Value,...                      % 数据链的码元速率
                'CarrierSkipF_SkipF_vector', str2num(app.main.SkipF_vector.Value) , ... % 数据链的跳频周期序列
                'CarrierSkipF_SkipF_Num', app.main.SkipF_Num.Value, ...                                  % 数据链的最大跳频点数
                'CarrierSkipF_SkipF_distance', app.main.SkipF_distance.Value, ...                            % 数据链的跳频间隔
                'CarrierSkipF_SkipF_time', app.main.Skip_time.Value, ...                              % 数据链的跳频驻留时间
                'M_PAM_Modulator_Baseband_M_ary_number', app.main.M_Number.Value, ...                    % 数据链的基带调制进制数
                'Rolloff_factor', 0.3, ...                                         % 数据链的滚降系数
                'Filter_span_in_symbols', 10, ...                                  % 数据链的滚降滤波器脉冲响应��覆盖的符号周期数��
                'Output_samples_per_symbol', 100, ...                              % 数据链的滚降滤波每个符号的输出样本数
                'Digital_Clock2_CarrierTs', app.main.Sample_Rate.Value, ...                           % 数据链发射机载波的采样周期
                'f_chirp_max', app.main.f_chirp_max.Value, ...                                        % 数据链发射机最低频段载波的变频终点
                'f_chirp_min', app.main.f_chirp_min.Value, ...                                        % 数据链发射机最低频段载波的变频起点
                'T_chirp', app.main.T_chirp.Value, ...                                            % 数据链发射机载波的变频周期
                'gain3', 0.4, ...                                                  % 数据链发射机信号中本地载波的分量
                'gain1', 0.714, ...                                                % 数据链发射机混合信号的第一次缩放倍数
                'Awgn_SNR', 15, ...                                                % 数据链的信噪比
                'Awgn_input_power' , app.main.Signal_Power.Value, ...                                            % 数据链发射信号的功率，用于确定信噪比的噪声功率
                'TR_Sig_power_controller_coeffience', sqrt(app.main.sig_power_controller_coeffience.Value), ...  % 数据链发射信号的放大倍数，用于控制发射功率
                'UAV_datalink_simulation_time', app.main.simulation_time.Value ...                               % 数据链的仿真时间，现为一个跳频序列周期
            );
            
            global Interference_other_config; 
            Interference_other_config = struct(...
                'power_controller_W', app.power_controller_W.Value, ...       % 干扰源功率
                'ErrorRateDelay', 920, ...          % 接收机与发射机误码比较延时
                'Power_error_1', 0.1,  ...          % 干扰源功率标定误差范围1，用于"单音正弦噪声"和"脉冲噪声"
                'Power_error_2', 0.4 ...            % 干扰源功率标定误差范围2，用于"随机二元码调制噪声"和"PN码MSK噪声"
            );
            
            global Constraint_condition_config;
            Constraint_condition_config = struct(...
                'Transition_zone_minimum_gap', app.constrain1.Value, ...    % 确保多通带滤波器设计时，各通带之间的阻带至少有150kHz
                'Sweep_freq_rate_upperLimit_factor', app.constrain2.Value, ... % 用于限制扫频速率上限，主要原因还是驻留时间存在最小值限制，要确保扫频时每个频域波束能量足够集中
                'Sweep_freq_Highest_freq', app.constrain3.Value * 2  ...   % 扫频范围限制，防止信号扫出信号频率太多范围，造成功率浪费
            );
            
            global Interference_source_config;
            PulseNoise_bwBand = str2num(app.narrowBwBand.Value);
            Interference_source_config = struct(...
                'source_list', ["单音正弦噪声","脉冲噪声"], ...   % 四种干扰源类型
                'method_list', ["步进调频","跳频干扰"], ...        % 两种干扰方式
                'bw_list', ["True","False"], ...                  % 是否优化带宽选项
                'SinNoise_lb', app.intBasebandFreq.Value,  ...                        % 正弦干扰下限，主要是载频
                'SinNoise_ub', app.intBasebandFreq.Value, ...                         % 正弦干扰上限
                'PulseNoise_lb', [app.intBasebandFreq.Value PulseNoise_bwBand(1)], ...             % 脉冲干扰下限，主要是中心频率、带宽
                'PulseNoise_ub', [app.intBasebandFreq.Value PulseNoise_bwBand(2)] ...              % 脉冲干扰上限                   
            );
            
            % global Optimized_interference_sources_config;
            Optimized_interference_sources_config = struct(...
                'Source_type',  app.singleWaySrc.Value, ...         % 干扰源类型选择
                'Interference_method', app.singleWayMethod.Value, ...      % 干扰源调制方式选择
                'Interference_number', app.singleWaySection.Value, ...     % 干扰分段频段数选择
                'Bw_is_variable', app.singleWayBw.Value, ...             % 是否优化带宽选择
                'Passband_centerFreq', eval(app.singleWayCf.Value) ... % 干扰源的通带中心频率设置
            );
            
            % 干扰方式须额外添加
            MutiFreqInt_Endpoint = app.maxMutiFreq.Value - Interference_source_config.SinNoise_lb;
            
            Sweep_eachFreqband = MutiFreqInt_Endpoint/Optimized_interference_sources_config.Interference_number;
            StepSweep_freq_up = Sweep_eachFreqband - (Interference_source_config.SinNoise_ub-Interference_source_config.SinNoise_lb);
            StepSweep_time_up = app.main.Skip_time.Value;

            HopSweep_freq_up = Sweep_eachFreqband/Optimized_interference_sources_config.Interference_number;
            HopSweep_time_up = app.main.Skip_time.Value/global_config.SequenceNum ;     
            
            dwellPointNumBand = str2num(app.dwellNumBand.Value);
            Interference_source_config.StepSweep_lb =  [0, 0, StepSweep_time_up, dwellPointNumBand(1)];                       % 步进扫频下限，主要是扫频频率上限、扫频频率下限、步进扫频周期、扫频点数
            Interference_source_config.StepSweep_ub =  [StepSweep_freq_up, StepSweep_freq_up, StepSweep_time_up, dwellPointNumBand(2)];  % 步进扫频上限
            Interference_source_config.HopSweep_lb =  [1e5, HopSweep_time_up];                % 跳频扫频下限，主要是跳频频率最小间隔、跳频驻留时间
            Interference_source_config.HopSweep_ub =  [HopSweep_freq_up, HopSweep_time_up];   % 跳频扫频上限
            Interference_source_config.Interference_freq_start_point = 0;                     % 多频段分段干扰时，扫频干扰频率起点
            Interference_source_config.Interference_freq_end_point = MutiFreqInt_Endpoint;    % 多频段分段干扰时，扫频干扰频率终点
            Interference_source_config.Sweep_freq_offset = 2e6;                               % 多频段分段干扰时，扫频范围偏移
            
            % 定义一个结构体数组，每个元素对应一种情况
            scenarios = generateScenarios('SourceTypes', global_config.SourceTypes, 'InterferenceNums',global_config.InterferenceNums, 'InterferenceTypes', ...
                global_config.InterferenceTypes, 'BandwidthOptions', global_config.BandwidthOptions, 'SequenceNum', global_config.SequenceNum);
            
            Signal_delay_length = 0;
            
            % 查找当前情况对应的参数
            matching_scenarios = scenarios(strcmp({scenarios.Source_type}, Optimized_interference_sources_config.Source_type) & ...
                                           strcmp({scenarios.Interference_method}, Optimized_interference_sources_config.Interference_method) & ...
                                           ([scenarios.Interference_number] == Optimized_interference_sources_config.Interference_number) & ...
                                           strcmp({scenarios.Bw_is_variable}, Optimized_interference_sources_config.Bw_is_variable) );
            
            % 检查是否找到了匹配的情况
            if isempty(matching_scenarios)
                error('No matching scenario found.');
            end
            
            scenario = matching_scenarios(1); % 获取第一个匹配的情况
            
            % 加载历史记录（若存在）
            if global_config.Resume_flag
            % if exist(MM_config.history_weight_parameter_file_dir, 'file')
                % load(MM_config.history_weight_parameter_file_dir, 'history');
                % best_idx = find([history.Fval] == min([history.Fval]), 1); % 提取历史最优解作为初始值
                % x0 = history(best_idx).X;
                load(MM_config.history_weight_parameter_file_dir, 'x');
                x0 = x;
            else
                history = [];
                x0 = scenario.x0; % 默认初始值
            end
            
            % 二次优化参数选择
            if isempty(global_config.HybridFcn)
                HybridFcn_total = [];
            else
                hybridopts = optimoptions(global_config.HybridFcn, ...
                    'OptimalityTolerance',1e-7, ...
                    'Algorithm', 'interior-point', ... % 使用支持并行的算法
                    'UseParallel', global_config.UseParallel, ...           % 启用并行计算
                    'Display', 'iter', ...
                    'MaxIterations', global_config.Second_optimization_MaxIterations);
                HybridFcn_total = {global_config.HybridFcn, hybridopts};
            end
            
            % 粒子群优化参数选择
            options = optimoptions( ...
                'particleswarm', 'SwarmSize', global_config.SwarmSize, ...
                'MaxIterations', global_config.MaxIterations, ...
                'PlotFcn', @pswplotbestf, ... 
                'Display', 'iter', ...
                'OutputFcn', @(x, state) save_results(app, x, state), ...
                'DisplayInterval', 1, ...
                'InitialSwarmMatrix', x0, ...
                'UseParallel', global_config.UseParallel, ...
                'MaxTime', global_config.MaxTime, ...
                'HybridFcn', HybridFcn_total, ...
                'SelfAdjustmentWeight',global_config.SelfAdjustmentWeight, ...
                'SocialAdjustmentWeight',global_config.SocialAdjustmentWeight, ...
                'InertiaRange', global_config.InertiaRange, ...
                'MinNeighborsFraction', global_config.MinNeighborsFraction, ...
                'PlotFcn', 'pswplotbestf' );
            
            % 可以构造匿名函数的文本表示来解决：eval，根据额外参数的数量调整适应度函数
            fun_str = createBERFunction(scenario);
            fun = eval(fun_str);
            % fun = @(x)- getBER(app, struct('SinF',round(x(1)),'StepSweepFmax',round(x(2)),'StepSweepFmin',round(x(3)),'StepSweepT',x(4),'StepSweepN',round(x(5)),'MultiFreqFValue1',round(x(6)),'MultiFreqFValue2',round(x(7)),'MultiFreqFValue3',round(x(8)),'MultiFreqFValue4',round(x(9)),'MultiFreqFValue5',round(x(10)),'MultiFreqFValue6',round(x(11)),'MultiFreqFValue7',round(x(12)),'MultiFreqFValue8',round(x(13)),'MultiFreqFValue9',round(x(14)),'MultiFreqFValue10',round(x(15)),'MultiFreqFValue11',round(x(16))),"单音正弦噪声","步进调频",scenario.Interference_number,scenario.Interference_sequence_number,Optimized_interference_sources_config.Passband_centerFreq);
            
            % 粒子群优化范围
            lb = scenario.lb;
            ub = scenario.ub; 
            feature_num = numel(lb);
            
            % 是否启用并行，启用的话Need to switch all workers to a separate tempdir in case any code is generated for instance for StateFlow, or any other 
            % file artifacts are  created by the model.
            if global_config.UseParallel == true
                if ~isempty(gcp('nocreate'))
                    parpool('local', global_config.Parallel_CoreNum);
                end
                load_system("Jammer_Generator.slx");
                load_system("Simple_Model_Packaging_7.slx");
                spmd
                    % Setup tempdir and cd into it
                    currDir = pwd;
                    addpath(currDir);
                    tmpDir = tempname;
                    mkdir(tmpDir);
                    cd(tmpDir);
                    % Load the model on the worker
                    load_system("Jammer_Generator.slx");
                    load_system("Simple_Model_Packaging_7.slx");
                end
            end
            
            cachePath = fullfile(prefdir, 'slcache');
            slprjPath = fullfile(pwd, 'slprj');
            
            % 删除缓存目录
            if exist(cachePath, 'dir')
                rmdir(cachePath, 's');
            end
            if exist(slprjPath, 'dir')
                rmdir(slprjPath, 's');
            end
            
            % 启用粒子群优化
            % profile on
            [x,fval,exitflag,output] = particleswarm(fun, feature_num, lb, ub, options);
            
            % profile off
            % profile viewer
            
            % 是否启用并行，启用的话就Switch all of the workers back to their original folder.
            if global_config.UseParallel == true
                if ~isempty(gcp('nocreate'))
                    spmd
                        cd(currDir);
                        rmdir(tmpDir,'s');
                        rmpath(currDir);
                        close_system("Jammer_Generator.slx", 0);
                        close_system("Simple_Model_Packaging_7.slx", 0);
                    end
            
                    close_system("Jammer_Generator.slx", 0);
                    close_system("Simple_Model_Packaging_7.slx", 0);
                    delete(gcp('nocreate'));
                end
            end
            
            
            % 保存变量
            filename = sprintf([MM_config.final_weight_parameter_file_dir, '_%s.mat'], datestr(now, 'yyyy-mm-dd_HH-MM-SS')) ;
            save(filename, 'x', 'fval', 'exitflag', 'output', 'fun');
            
            % ===> 显示结果 ====
            result = strcat('最佳参数结果:',num2str(x,'%.1f'));
            disp(result);
            result_2 = strcat('最佳目标结果:',num2str(fval,'%.4f'));
            disp(result_2);
            
            app.TextArea.Value = [app.TextArea.Value;result];
            app.TextArea.Value = [app.TextArea.Value;result_2];


% 删除并行的崩溃文件
% myCluster = parcluster('Processes');
% delete(myCluster.Jobs);

% 运行完一次后需要做的事：
% 1、记录每次的最优参数到word
% 2、保存优化的历史数据
% 3、清除C盘的temp文件夹内容 system('del /q /s /f C:\Users\89778\AppData\Local\Temp\*');


        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
             try
                app_TrackVi = Track_Visiable(app);
            catch ME
                disp(ME.message);
                uiconfirm(app.UIFigure,'无法打开','Process Prompt','Icon','warning');
             end

            app_TrackVi.UITable.ColumnName = {'Source_type', 'Interference_method', 'Interference_number', 'Interference_sequence_number', ...
                'Bw_is_variable', 'lb', 'ub', 'x0', 'ExtraParams'};

            
             % 初始化参数
            global_config = struct(...
                'MaxTime', round(app.maxTime.Value * 3600),...  % 最大优化时间，单位为秒
                'MaxIterations', app.maxIteration.Value,...   % 最大迭代次数，推荐为500
                'SwarmSize', app.swarmSize.Value,...       % 种群数目，推荐为50
                'Second_optimization_MaxIterations', 2, ...  % 二次优化的最大迭代次数，推荐为100
                'UseParallel', false,...                     % 是否使用并行
                'Resume_flag', app.resumeFlag.Value,...      % 是否接续训练
                'Parallel_CoreNum', 10,...                   % 若使用并行，并行核数设置
                'SelfAdjustmentWeight', app.selfWeight.Value, ...      % 粒子群的个体学习因子
                'SocialAdjustmentWeight', app.socialWeight.Value, ...  % 粒子群的社会学习因子
                'InertiaRange', str2num(app.inertiaWeight.Value), ...  % 粒子群的惯性权重范围
                'MinNeighborsFraction', app.neighborRatio.Value, ...   % 粒子群的邻域粒子比例
                'HybridFcn', [], ...                         % 二次优化器选择，可选项为'fmincon'、'fminsearch'、'fminunc'、'patternsearch'、[]
                'SourceTypes', 2, ...                        % 生成干扰策略时的干扰源类型数目
                'InterferenceTypes', 2, ...                  % 生成干扰策略时的干扰方式数目
                'BandwidthOptions', 2, ...                   % 生成干扰策略时的带宽选项数目
                'SequenceNum', app.HopSweep_timeSection.Value, ...                        % 生成干扰策略时的干扰时域跳频序列数目
                'InterferenceNums', app.singleWaySection.Value ...                    % 生成干扰策略时的干扰划分频段数
            );
            
            global MM_config; 
            MM_config = struct(...
                'cache_dir', 'C:/Users/89778/AppData/Local/Temp',...    % simulink缓存路径，定期删除会用到
                'history_weight_parameter_file_dir', './Weight_optimize/pso_final_result7',...     % 优化过程中的历史权重文件目录
                'final_weight_parameter_file_dir',   './Weight_optimize/pso_final_result7',...     % 优化后的权重文件目录
                'MinM_room', 40e9 ...                                   % simulink缓存占用后的最小剩余空间
            );
            
            global DataLink_config; 
            DataLink_config = struct(...
                'Repeating_Sequence_Stair_output_vector', str2num(app.main.output_vector.Value) ,...   % 数据链的重复序列周期单元
                'Repeating_Sequence_Stair_CodeTs', app.main.CodeTs.Value,...                      % 数据链的码元速率
                'CarrierSkipF_SkipF_vector', str2num(app.main.SkipF_vector.Value) , ... % 数据链的跳频周期序列
                'CarrierSkipF_SkipF_Num', app.main.SkipF_Num.Value, ...                                  % 数据链的最大跳频点数
                'CarrierSkipF_SkipF_distance', app.main.SkipF_distance.Value, ...                            % 数据链的跳频间隔
                'CarrierSkipF_SkipF_time', app.main.Skip_time.Value, ...                              % 数据链的跳频驻留时间
                'M_PAM_Modulator_Baseband_M_ary_number', app.main.M_Number.Value, ...                    % 数据链的基带调制进制数
                'Rolloff_factor', 0.3, ...                                         % 数据链的滚降系数
                'Filter_span_in_symbols', 10, ...                                  % 数据链的滚降滤波器脉冲响应��覆盖的符号周期数��
                'Output_samples_per_symbol', 100, ...                              % 数据链的滚降滤波每个符号的输出样本数
                'Digital_Clock2_CarrierTs', app.main.Sample_Rate.Value, ...                           % 数据链发射机载波的采样周期
                'f_chirp_max', app.main.f_chirp_max.Value, ...                                        % 数据链发射机最低频段载波的变频终点
                'f_chirp_min', app.main.f_chirp_min.Value, ...                                        % 数据链发射机最低频段载波的变频起点
                'T_chirp', app.main.T_chirp.Value, ...                                            % 数据链发射机载波的变频周期
                'gain3', 0.4, ...                                                  % 数据链发射机信号中本地载波的分量
                'gain1', 0.714, ...                                                % 数据链发射机混合信号的第一次缩放倍数
                'Awgn_SNR', 15, ...                                                % 数据链的信噪比
                'Awgn_input_power' , app.main.Signal_Power.Value, ...                                            % 数据链发射信号的功率，用于确定信噪比的噪声功率
                'TR_Sig_power_controller_coeffience', sqrt(app.main.sig_power_controller_coeffience.Value), ...  % 数据链发射信号的放大倍数，用于控制发射功率
                'UAV_datalink_simulation_time', app.main.simulation_time.Value ...                               % 数据链的仿真时间，现为一个跳频序列周期
            );
            
            global Interference_other_config; 
            Interference_other_config = struct(...
                'power_controller_W', app.power_controller_W.Value, ...       % 干扰源功率
                'ErrorRateDelay', 920, ...          % 接收机与发射机误码比较延时
                'Power_error_1', 0.1,  ...          % 干扰源功率标定误差范围1，用于"单音正弦噪声"和"脉冲噪声"
                'Power_error_2', 0.4 ...            % 干扰源功率标定误差范围2，用于"随机二元码调制噪声"和"PN码MSK噪声"
            );
            
            global Constraint_condition_config;
            Constraint_condition_config = struct(...
                'Transition_zone_minimum_gap', app.constrain1.Value, ...    % 确保多通带滤波器设计时，各通带之间的阻带至少有150kHz
                'Sweep_freq_rate_upperLimit_factor', app.constrain2.Value, ... % 用于限制扫频速率上限，主要原因还是驻留时间存在最小值限制，要确保扫频时每个频域波束能量足够集中
                'Sweep_freq_Highest_freq', app.constrain3.Value * 2  ...   % 扫频范围限制，防止信号扫出信号频率太多范围，造成功率浪费
            );
            
            global Interference_source_config;
            PulseNoise_bwBand = str2num(app.narrowBwBand.Value);
            Interference_source_config = struct(...
                'source_list', ["单音正弦噪声","脉冲噪声"], ...   % 四种干扰源类型
                'method_list', ["步进调频","跳频干扰"], ...        % 两种干扰方式
                'bw_list', ["True","False"], ...                  % 是否优化带宽选项
                'SinNoise_lb', app.intBasebandFreq.Value,  ...                        % 正弦干扰下限，主要是载频
                'SinNoise_ub', app.intBasebandFreq.Value, ...                         % 正弦干扰上限
                'PulseNoise_lb', [app.intBasebandFreq.Value PulseNoise_bwBand(1)], ...             % 脉冲干扰下限，主要是中心频率、带宽
                'PulseNoise_ub', [app.intBasebandFreq.Value PulseNoise_bwBand(2)] ...              % 脉冲干扰上限                   
            );
            
            % global Optimized_interference_sources_config;
            Optimized_interference_sources_config = struct(...
                'Source_type',  app.singleWaySrc.Value, ...         % 干扰源类型选择
                'Interference_method', app.singleWayMethod.Value, ...      % 干扰源调制方式选择
                'Interference_number', app.singleWaySection.Value, ...     % 干扰分段频段数选择
                'Bw_is_variable', app.singleWayBw.Value, ...             % 是否优化带宽选择
                'Passband_centerFreq', eval(app.singleWayCf.Value) ... % 干扰源的通带中心频率设置
            );
            
            % 干扰方式须额外添加
            MutiFreqInt_Endpoint = app.maxMutiFreq.Value - Interference_source_config.SinNoise_lb;
            
            Sweep_eachFreqband = MutiFreqInt_Endpoint/Optimized_interference_sources_config.Interference_number;
            StepSweep_freq_up = Sweep_eachFreqband - (Interference_source_config.SinNoise_ub-Interference_source_config.SinNoise_lb);
            StepSweep_time_up = app.main.Skip_time.Value;

            HopSweep_freq_up = Sweep_eachFreqband/Optimized_interference_sources_config.Interference_number;
            HopSweep_time_up = app.main.Skip_time.Value/global_config.SequenceNum ;     
            
            dwellPointNumBand = str2num(app.dwellNumBand.Value);
            Interference_source_config.StepSweep_lb =  [0, 0, StepSweep_time_up, dwellPointNumBand(1)];                       % 步进扫频下限，主要是扫频频率上限、扫频频率下限、步进扫频周期、扫频点数
            Interference_source_config.StepSweep_ub =  [StepSweep_freq_up, StepSweep_freq_up, StepSweep_time_up, dwellPointNumBand(2)];  % 步进扫频上限
            Interference_source_config.HopSweep_lb =  [1e5, HopSweep_time_up];                % 跳频扫频下限，主要是跳频频率最小间隔、跳频驻留时间
            Interference_source_config.HopSweep_ub =  [HopSweep_freq_up, HopSweep_time_up];   % 跳频扫频上限
            Interference_source_config.Interference_freq_start_point = 0;                     % 多频段分段干扰时，扫频干扰频率起点
            Interference_source_config.Interference_freq_end_point = MutiFreqInt_Endpoint;    % 多频段分段干扰时，扫频干扰频率终点
            Interference_source_config.Sweep_freq_offset = 2e6;                               % 多频段分段干扰时，扫频范围偏移
            
            % 定义一个结构体数组，每个元素对应一种情况
            scenarios = generateScenarios('SourceTypes', global_config.SourceTypes, 'InterferenceNums',global_config.InterferenceNums, 'InterferenceTypes', ...
                global_config.InterferenceTypes, 'BandwidthOptions', global_config.BandwidthOptions, 'SequenceNum', global_config.SequenceNum);

            assignin('base', "scenarios", scenarios);
            
            app_TrackVi.UITable.Data = table({scenarios.Source_type}', {scenarios.Interference_method}', {scenarios.Interference_number}', {scenarios.Interference_sequence_number}',  {scenarios.Bw_is_variable}', {scenarios.lb}', {scenarios.ub}', {scenarios.x0}', {scenarios.ExtraParams}');

        end

        % Button pushed function: go_stop
        function go_stopButtonPushed(app, event)
            fclose(fopen('INTERRUPT_PAUSE.flag', 'w')); % 创建标记文件
            disp('>>>>优化将在执行完当前策略后暂停<<<<')
            app.TextArea.Value = [app.TextArea.Value;'>>>>优化将在执行完当前策略后暂停<<<<' datestr(now, 'HH:MM:SS')];
            app.isPaused = true;
        end

        % Button pushed function: go_on
        function go_onButtonPushed(app, event)
            if exist('INTERRUPT_PAUSE.flag', 'file')
                 delete('INTERRUPT_PAUSE.flag'); % 删除标记文件
                 app.isPaused = false;  % 直接修改属性解除阻塞
            end
            disp('>>>> 优化继续 <<<<');
            app.TextArea.Value = [app.TextArea.Value;'>>>> 优化继续 <<<<' datestr(now, 'HH:MM:SS')];
        end

        % Button pushed function: clear_text
        function clear_textButtonPushed(app, event)
            app.TextArea.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '干扰源优化';
            app.GridLayout = uigridlayout(app.Panel);
            app.Panel_2 = uipanel(app.GridLayout);
            app.Panel_2.Title = '优化参数设计';
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '最大时间/h';
            app.maxTime = uieditfield(app.GridLayout2, 'numeric');
            app.maxTime.Value = 1;
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = '最大迭代次数';
            app.maxIteration = uieditfield(app.GridLayout2, 'numeric');
            app.maxIteration.Value = 2;
            app.Label_4 = uilabel(app.GridLayout2);
            app.Label_4.Text = '个体学习因子';
            app.selfWeight = uieditfield(app.GridLayout2, 'numeric');
            app.selfWeight.Value = 2;
            app.EditField_5Label = uilabel(app.GridLayout2);
            app.EditField_5Label.Text = '社会学习因子';
            app.socialWeight = uieditfield(app.GridLayout2, 'numeric');
            app.socialWeight.Value = 2.2;
            app.Label_5 = uilabel(app.GridLayout2);
            app.Label_5.Text = '惯性权重范围';
            app.inertiaWeight = uieditfield(app.GridLayout2, 'text');
            app.inertiaWeight.Value = '[0.4, 0.9]';
            app.Label_6 = uilabel(app.GridLayout2);
            app.Label_6.Text = '领域粒子比例';
            app.neighborRatio = uieditfield(app.GridLayout2, 'numeric');
            app.neighborRatio.Value = 0.4;
            app.Label_7 = uilabel(app.GridLayout2);
            app.Label_7.Text = '种群数目';
            app.swarmSize = uieditfield(app.GridLayout2, 'numeric');
            app.swarmSize.Value = 2;
            app.resumeFlag = uicheckbox(app.GridLayout2);
            app.resumeFlag.Text = '是否接续训练';
            app.Panel_3 = uipanel(app.GridLayout);
            app.Panel_3.Title = '干扰源优化范围';
            app.GridLayout3 = uigridlayout(app.Panel_3);
            app.Label_8 = uilabel(app.GridLayout3);
            app.Label_8.Text = '干扰功率';
            app.power_controller_W = uieditfield(app.GridLayout3, 'numeric');
            app.power_controller_W.Value = 40;
            app.Label_15 = uilabel(app.GridLayout3);
            app.Label_15.Text = '窄带干扰bw范围';
            app.narrowBwBand = uieditfield(app.GridLayout3, 'text');
            app.narrowBwBand.Tooltip = {'中心频率、带宽'};
            app.narrowBwBand.Value = '[5e5,2.5e6]';
            app.Label_16 = uilabel(app.GridLayout3);
            app.Label_16.Text = '多频干扰最大值';
            app.maxMutiFreq = uieditfield(app.GridLayout3, 'numeric');
            app.maxMutiFreq.Value = 67500000;
            app.Label_14 = uilabel(app.GridLayout3);
            app.Label_14.Text = '正弦干扰基频';
            app.intBasebandFreq = uieditfield(app.GridLayout3, 'numeric');
            app.intBasebandFreq.Value = 4500000;
            app.Label_17 = uilabel(app.GridLayout3);
            app.Label_17.Text = '步进扫频驻留频点数范围';
            app.dwellNumBand = uieditfield(app.GridLayout3, 'text');
            app.dwellNumBand.Value = '[2, 20]';
            app.Label_18 = uilabel(app.GridLayout3);
            app.Label_18.Text = '随机干扰时域分段数';
            app.HopSweep_timeSection = uieditfield(app.GridLayout3, 'numeric');
            app.HopSweep_timeSection.Value = 3;
            app.Panel_4 = uipanel(app.GridLayout);
            app.Panel_4.Title = '优化约束限制';
            app.GridLayout4 = uigridlayout(app.Panel_4);
            app.EditField_2Label_2 = uilabel(app.GridLayout4);
            app.EditField_2Label_2.Text = '扫频速率的上限因子';
            app.constrain2 = uieditfield(app.GridLayout4, 'numeric');
            app.constrain2.Value = 10;
            app.EditField_3Label_2 = uilabel(app.GridLayout4);
            app.EditField_3Label_2.Text = '带通滤波的最小阻带';
            app.constrain1 = uieditfield(app.GridLayout4, 'numeric');
            app.constrain1.Value = 150000;
            app.EditField_4Label_2 = uilabel(app.GridLayout4);
            app.EditField_4Label_2.Text = '扫频范围限制';
            app.constrain3 = uieditfield(app.GridLayout4, 'numeric');
            app.constrain3.Value = 68000000;
            app.Panel_5 = uipanel(app.GridLayout);
            app.Panel_5.Title = '单一策略优化';
            app.GridLayout5 = uigridlayout(app.Panel_5);
            app.Button_2 = uibutton(app.GridLayout5, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '开启优化';
            app.Label_10 = uilabel(app.GridLayout5);
            app.Label_10.Text = '干扰源';
            app.singleWaySrc = uieditfield(app.GridLayout5, 'text');
            app.singleWaySrc.Value = '单音正弦噪声';
            app.EditField_3Label_3 = uilabel(app.GridLayout5);
            app.EditField_3Label_3.Text = '干扰方式';
            app.singleWayMethod = uieditfield(app.GridLayout5, 'text');
            app.singleWayMethod.Value = '步进调频';
            app.EditField_4Label_3 = uilabel(app.GridLayout5);
            app.EditField_4Label_3.Text = '干扰段数';
            app.singleWaySection = uieditfield(app.GridLayout5, 'numeric');
            app.singleWaySection.Value = 22;
            app.EditField_5Label_2 = uilabel(app.GridLayout5);
            app.EditField_5Label_2.Text = '是否优化带宽';
            app.singleWayBw = uieditfield(app.GridLayout5, 'text');
            app.singleWayBw.Value = 'False';
            app.Label_11 = uilabel(app.GridLayout5);
            app.Label_11.Text = '通带中心频率';
            app.singleWayCf = uieditfield(app.GridLayout5, 'text');
            app.singleWayCf.Value = '5.5e6:3e6:68.5e6';
            app.Panel_6 = uipanel(app.GridLayout);
            app.Panel_6.Title = '全局策略优化';
            app.GridLayout6 = uigridlayout(app.Panel_6);
            app.Button = uibutton(app.GridLayout6, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '全局优化';
            app.Label_20 = uilabel(app.GridLayout6);
            app.Label_20.Text = '策略序号索引';
            app.track_idx = uieditfield(app.GridLayout6, 'text');
            app.track_idx.Value = '1:1:length(scenarios)';
            app.go_stop = uibutton(app.GridLayout6, 'push');
            app.go_stop.ButtonPushedFcn = createCallbackFcn(app, @go_stopButtonPushed, true);
            app.go_stop.Text = '优化暂停';
            app.go_on = uibutton(app.GridLayout6, 'push');
            app.go_on.ButtonPushedFcn = createCallbackFcn(app, @go_onButtonPushed, true);
            app.go_on.Text = '优化继续';
            app.Button_3 = uibutton(app.GridLayout6, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '策略可视化';
            app.Label_21 = uilabel(app.GridLayout);
            app.Label_21.Text = '优化记录显示';
            app.TextArea = uitextarea(app.GridLayout);
            app.clear_text = uibutton(app.GridLayout, 'push');
            app.clear_text.ButtonPushedFcn = createCallbackFcn(app, @clear_textButtonPushed, true);
            app.clear_text.Text = '显示清除';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interference_Optimization(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end