classdef Link < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        Panel_5                 matlab.ui.container.Panel
        GridLayout6             matlab.ui.container.GridLayout
        Button_7                matlab.ui.control.StateButton
        Button_6                matlab.ui.control.StateButton
        Button_5                matlab.ui.control.Button
        Button_4                matlab.ui.control.Button
        Panel_4                 matlab.ui.container.Panel
        GridLayout5             matlab.ui.container.GridLayout
        Button_2                matlab.ui.control.Button
        Button_3                matlab.ui.control.Button
        Power_Estimate_axis     matlab.ui.control.UIAxes
        Panel_3                 matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        Image                   matlab.ui.control.Image
        Button                  matlab.ui.control.StateButton
        Tab_2                   matlab.ui.container.Tab
        GridLayout4             matlab.ui.container.GridLayout
        TimeScope               matlab.ui.scope.TimeScope
        ISDFJ_CodePeriod        matlab.ui.control.NumericEditField
        Label_12                matlab.ui.control.Label
        ISDFJ_RandomPhaseFlag   matlab.ui.control.CheckBox
        ISDFJ_PowerCoefficient  matlab.ui.control.NumericEditField
        Label_11                matlab.ui.control.Label
        ISDFJ_ForwardingTimes   matlab.ui.control.NumericEditField
        Label_10                matlab.ui.control.Label
        ISDFJ_SampleTime        matlab.ui.control.NumericEditField
        Label_9                 matlab.ui.control.Label
        CheckBox                matlab.ui.control.CheckBox
        Panel_2                 matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        Mean_Power_Display      matlab.ui.control.TextArea
        start_sim               matlab.ui.control.Button
        simulation_time         matlab.ui.control.NumericEditField
        Label_8                 matlab.ui.control.Label
        sig_power_controller_coeffience  matlab.ui.control.NumericEditField
        Label_7                 matlab.ui.control.Label
        Signal_Power            matlab.ui.control.NumericEditField
        EditField_15Label       matlab.ui.control.Label
        T_chirp                 matlab.ui.control.NumericEditField
        EditField_18Label_3     matlab.ui.control.Label
        f_chirp_min             matlab.ui.control.NumericEditField
        EditField_18Label_2     matlab.ui.control.Label
        f_chirp_max             matlab.ui.control.NumericEditField
        PAMLabel_2              matlab.ui.control.Label
        Sample_Rate             matlab.ui.control.NumericEditField
        EditField_18Label       matlab.ui.control.Label
        Skip_time               matlab.ui.control.NumericEditField
        Label_6                 matlab.ui.control.Label
        SkipF_distance          matlab.ui.control.NumericEditField
        Label_5                 matlab.ui.control.Label
        SkipF_Num               matlab.ui.control.NumericEditField
        Label_4                 matlab.ui.control.Label
        SkipF_vector            matlab.ui.control.EditField
        Label_3                 matlab.ui.control.Label
        M_Number                matlab.ui.control.NumericEditField
        PAMLabel                matlab.ui.control.Label
        CodeTs                  matlab.ui.control.NumericEditField
        Label_2                 matlab.ui.control.Label
        output_vector           matlab.ui.control.EditField
        Label                   matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        BER_end; % Description
    end
    
    properties (Access = public)
        datalink_sampleRate; % Description
        datalink_selector_num; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.datalink_sampleRate = 5e-8;
            app.datalink_selector_num = 3;
        end

        % Button pushed function: start_sim
        function start_simButtonPushed(app, event)
            fbar = waitbar(0,'请等待..');
            
            % 输入信号传参
            model.user_defined.Repeating_Sequence_Stair.output_vector = str2num(app.output_vector.Value);
            model.user_defined.Repeating_Sequence_Stair.CodeTs = app.CodeTs.Value;
            
             % 载波跳频调制
            model.user_defined.CarrierSkipF.SkipF_vector = str2num(app.SkipF_vector.Value);
            model.user_defined.CarrierSkipF.SkipF_Num = app.SkipF_Num.Value ;
            model.user_defined.CarrierSkipF.SkipF_distance = app.SkipF_distance.Value ;
            model.user_defined.CarrierSkipF.SkipF_time = app.Skip_time.Value ;
            model.user_defined.M_PAM_Modulator_Baseband.M_ary_number = app.M_Number.Value;
            
            model.user_defined.Raised_Cosine_Transmit_Filter.Rolloff_factor = 0.3;
            model.user_defined.Raised_Cosine_Transmit_Filter.Filter_span_in_symbols = 10;
            model.user_defined.Raised_Cosine_Transmit_Filter.Output_samples_per_symbol = 100;
            
            model.user_defined.Digital_Clock2.CarrierTs = app.Sample_Rate.Value;
            model.user_defined.func1.f_chirp_max = app.f_chirp_max.Value;
            model.user_defined.func1.f_chirp_min = app.f_chirp_min.Value;
            model.user_defined.func1.T_chirp = app.T_chirp.Value;
            
            model.user_defined.gain3.value = 0.4;
            model.user_defined.gain1.value = 0.741;
            
            model.user_defined.Awgn.SNR = 15;
            model.user_defined.Awgn.input_power = app.Signal_Power.Value;
            model.user_defined.TR.Sig_power_controller_coeffience = sqrt(app.sig_power_controller_coeffience.Value);
            
            assignin("base", 'model', model);

            % 进度条窗口显示数据已经加载完成
            waitbar(.25,fbar,'完成数据加载');

            % 进度条窗口显示开始运行Simulink模型
            waitbar(.5,fbar,'开始运行Simulink模型');
            simOut = sim("Simple_Model_Packaging_7_1.slx",'stoptime',num2str(app.simulation_time.Value)); %运行Sine.slx模型并设定仿真时间为B

            % 进度条窗口显示数据后处理
            waitbar(.75,fbar,'数据后处理');

            % 功率估计,画图
            extend_multiple_2 = ceil(length(simOut.tout) / length(simOut.power_estimation_result.Data));
            Power_Estimate = reshape(repmat(reshape(simOut.power_estimation_result.Data,1,[]),extend_multiple_2,1),1,[]);
            plot(app.Power_Estimate_axis,simOut.power_estimation_result.Time, Power_Estimate,'Marker','none')
            xlabel(app.Power_Estimate_axis,"t/s");
            ylabel(app.Power_Estimate_axis,"Amplitude");
            hold(app.Power_Estimate_axis, "on");

            % 功率估计，显示最终时间的平均功率值
            Power_Estimate_mean = mean(Power_Estimate(end-length(Power_Estimate)/2:end));
            str_display = [ "平均功率：", num2str(round(Power_Estimate_mean,4)) ];
            app.Mean_Power_Display.Value = str_display;

            % 进度条窗口显示处理结束
            waitbar(1,fbar,'处理结束');
            close(fbar);
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            switch value
                case 0
                   app.TabGroup.SelectedTab = app.Tab;
                   
                case 1
                   app.TabGroup.SelectedTab = app.Tab_2;
            end
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
             % 打开噪声产生器的GUI
            try
                app.datalink_sampleRate = evalin('base','model.Parameters.NoiseGen.NoiseTs');
                Noise_new(app, app.datalink_sampleRate, app.datalink_selector_num);
                app.Button.Value = 0;
            catch ME
                disp(ME.message);
                uiconfirm(app.UIFigure,'无法打开','Process Prompt','Icon','warning');
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            fbar = waitbar(0,'请等待..');
            try
                basemodel = evalin('base','model');
                if isfield(basemodel,'Parameters')
                    if ~isfield(basemodel.Parameters,'NoiseGen')
                        run("Scripts/defaultmodelstruct.m")
                    else
                        model.Parameters = basemodel.Parameters;
                    end
                else
                    run("Scripts/defaultmodelstruct.m")
                end 
            catch ME
                disp(ME.message);
                uiconfirm(app.UIFigure,ME.message,'Process Prompt','Icon','warning');
            end

            % 输入信号传参
            model.user_defined.Repeating_Sequence_Stair.output_vector = str2num(app.output_vector.Value);
            model.user_defined.Repeating_Sequence_Stair.CodeTs = app.CodeTs.Value;

            % 载波跳频调制
            model.user_defined.CarrierSkipF.SkipF_vector = str2num(app.SkipF_vector.Value);
            model.user_defined.CarrierSkipF.SkipF_Num = app.SkipF_Num.Value ;
            model.user_defined.CarrierSkipF.SkipF_distance = app.SkipF_distance.Value ;
            model.user_defined.CarrierSkipF.SkipF_time = app.Skip_time.Value ;
            model.user_defined.M_PAM_Modulator_Baseband.M_ary_number = app.M_Number.Value;
            
            model.user_defined.Raised_Cosine_Transmit_Filter.Rolloff_factor = 0.3;
            model.user_defined.Raised_Cosine_Transmit_Filter.Filter_span_in_symbols = 10;
            model.user_defined.Raised_Cosine_Transmit_Filter.Output_samples_per_symbol = 100;
            
            model.user_defined.Digital_Clock2.CarrierTs = app.Sample_Rate.Value;
            model.user_defined.func1.f_chirp_max = app.f_chirp_max.Value;
            model.user_defined.func1.f_chirp_min = app.f_chirp_min.Value;
            model.user_defined.func1.T_chirp = app.T_chirp.Value;
            
            model.user_defined.gain3.value = 0.4;
            model.user_defined.gain1.value = 0.741;
            
            model.user_defined.Awgn.SNR = 15;
            model.user_defined.Awgn.input_power = app.Signal_Power.Value;
            model.user_defined.TR.Sig_power_controller_coeffience = sqrt(app.sig_power_controller_coeffience.Value);

            if app.CheckBox.Value == 0
                Signal_delay_length = 0;
                assignin("base", 'Signal_delay_length', Signal_delay_length);
                
                assignin("base", 'model', model);
                assignin('base','UAV_datalink_simulation_time',app.simulation_time.Value);
                load_system('Simple_Model_Packaging_7');
                % open_system('Simple_Model_Packaging_7');
                set_param('Simple_Model_Packaging_7/信道模型封装/间歇转发干扰','commented','on');
                set_param('Simple_Model_Packaging_7/信道模型封装/NosieGen','commented','off'); 
                set_param('Simple_Model_Packaging_7/信道模型封装/Buffer','commented','off'); 

            else
                % run("Scripts/defaultmodelstruct.m")
                assignin("base", 'model', model);
                assignin('base','UAV_datalink_simulation_time',app.simulation_time.Value);

                % 导入间歇式采样转发干扰源
                Sample_time = app.ISDFJ_SampleTime.Value;
                Forwarding_times = app.ISDFJ_ForwardingTimes.Value;
                RandomPhaseFlag = app.ISDFJ_RandomPhaseFlag.Value ;
                power_coefficient = app.ISDFJ_PowerCoefficient.Value ;
                CodePeriod = app.ISDFJ_CodePeriod.Value ;       
                
                ISDFJ_parameter = struct('tau', Sample_time, ...
                    'n', Forwarding_times, ...
                    'Ts', (Forwarding_times +1) * Sample_time, ...
                    'buffer_size', round(Sample_time * 1/model.Parameters.NoiseGen.NoiseTs), ...
                    'RandomPhaseFlag', RandomPhaseFlag, ...
                    'power_coefficient', power_coefficient, ...
                    'CodePeriod', CodePeriod ...
                    );
                assignin("base", 'ISDFJ_parameter', ISDFJ_parameter);
                
                % 信号延迟项
                Signal_delay_length = 1;
                assignin("base", 'Signal_delay_length', Signal_delay_length);
    
                load_system('Simple_Model_Packaging_7');
                % open_system('Simple_Model_Packaging_7');
                set_param('Simple_Model_Packaging_7/信道模型封装/间歇转发干扰','commented','off'); 
                set_param('Simple_Model_Packaging_7/信道模型封装/NosieGen','commented','on'); 
                set_param('Simple_Model_Packaging_7/信道模型封装/Buffer','commented','on'); 
            end

            waitbar(.25,fbar,'完成数据加载');
            simOut = sim("Simple_Model_Packaging_7.slx",'stoptime',num2str(app.simulation_time.Value)); %运行Sine.slx模型并设定仿真时间为B
            assignin("base", 'simOut', simOut);

            if app.CheckBox.Value ~= 0
                extend_multiple_5 = ceil(length(simOut.tout) / length(reshape(simOut.NoisePowerISDFJ.Data,1,[])));
                NoisePower_ISDFJ = reshape(repmat(reshape(simOut.NoisePowerISDFJ.Data,1,[]),extend_multiple_5,1),1,[]);
                NoisePower_ISDFJ = NoisePower_ISDFJ(1:length(simOut.tout));
                plot(app.ISDFJ_PowerEstimation,simOut.tout, NoisePower_ISDFJ,'Marker','none')
                xlabel(app.ISDFJ_PowerEstimation,"t/s");
                ylabel(app.ISDFJ_PowerEstimation,"Amplitude");
                hold(app.ISDFJ_PowerEstimation, "on");
            end

            waitbar(1,fbar,'处理结束');
            close(fbar);


        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
             try
                app_RD = Result_Display(app);
             catch ME
                disp(ME.message);
                uiconfirm(app.UIFigure,'无法打开','Process Prompt','Icon','warning');
            end
            
            simOut = evalin('base','simOut'); 
            % 发送端信源
            extend_multiple_3 = ceil(length(simOut.tout) / length(simOut.sending_coding.Data));
            Signal_Source = reshape(repmat(reshape(simOut.sending_coding.Data,1,[]),extend_multiple_3,1),1,[]);
            Signal_Source = Signal_Source(1:length(simOut.tout));
            plot(app_RD.Signal_Source_Generator_axis,simOut.tout,Signal_Source,'Marker','none')
            xlabel(app_RD.Signal_Source_Generator_axis,"t/s");
            ylabel(app_RD.Signal_Source_Generator_axis,"Amplitude");

            % 接收端解码信号
            extend_multiple_4 = ceil(length(simOut.tout) / length(reshape(simOut.receiving_coding.Data,1,[])));
            Decoder_Signal = reshape(repmat(reshape(simOut.receiving_coding.Data,1,[]),extend_multiple_4,1),1,[]);
            Decoder_Signal = Decoder_Signal(1:length(simOut.tout));
            plot(app_RD.Decoder_axis,simOut.tout,Decoder_Signal,'Marker','none')
            xlabel(app_RD.Decoder_axis,"t/s");
            ylabel(app_RD.Decoder_axis,"Amplitude");

            % 发送端调制信号波形
            extend_multiple_5 = ceil(length(simOut.tout) / length(reshape(simOut.sending_modulation_wave.Data,1,[])));
            Modulation_Signal_Wave = reshape(repmat(reshape(simOut.sending_modulation_wave.Data,1,[]),extend_multiple_5,1),1,[]);
            Modulation_Signal_Wave = Modulation_Signal_Wave(1:length(simOut.tout));
            plot(app_RD.Modulation_Signal_Wave_axis, simOut.tout,Modulation_Signal_Wave,'Marker','none')
            xlabel(app_RD.Modulation_Signal_Wave_axis, "t/s");
            ylabel(app_RD.Modulation_Signal_Wave_axis, "Amplitude");

            % 误码率曲线
            error_bit_output = simOut.CodeBER.Data;
            extend_multiple = ceil(length(simOut.tout) / length(error_bit_output));
            error_rate = reshape(repmat(error_bit_output(:,1)',extend_multiple,1),1,[]);
            error_rate = error_rate(1:length(simOut.tout));
            plot(app_RD.error_rate_axis, simOut.tout, error_rate*100,'Marker','none') % 因为误码率百分号，所以乘以100
            xlabel(app_RD.error_rate_axis, "t/s");
            ylabel(app_RD.error_rate_axis, "Error rate/%");

            app.BER_end = error_rate(end);% 保存最后时刻的误码率 

            % run('Scripts\Interference_mechanism.m');
        end

        % Callback function
        function LoadInputButtonSizeChanged(app, event)
            position = app.LoadInputButton.Position;
            
        end

        % Value changed function: Button_6
        function Button_6ValueChanged(app, event)
            try
                app_IntOpti = Interference_Optimization(app);
                app.Button_6.Value = 0;
            catch ME
                disp(ME.message);
                uiconfirm(app.UIFigure,'无法打开','Process Prompt','Icon','warning');
            end
            
        end

        % Value changed function: Button_7
        function Button_7ValueChanged(app, event)
            try
                app_IntAssess = Interference_Assess(app);
                app.Button_7.Value = 0;
            catch ME
                disp(ME.message);
                uiconfirm(app.UIFigure,'无法打开','Process Prompt','Icon','warning');
            end


        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            try
                app_ParaAnaly = Parameter_Analysis(app);
            catch ME
                disp(ME.message);
                uiconfirm(app.UIFigure,'无法打开','Process Prompt','Icon','warning');
            end


        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            try
                app_Similarity = Similarity_Display();
            catch ME
                disp(ME.message);
                uiconfirm(app.UIFigure,'无法打开','Process Prompt','Icon','warning');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '无人机纯导频链路';
            app.GridLayout = uigridlayout(app.Panel);
            app.Panel_2 = uipanel(app.GridLayout);
            app.Panel_2.Title = '无人机链路参数输入';
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '重复序列单元';
            app.output_vector = uieditfield(app.GridLayout2, 'text');
            app.output_vector.Editable = 'off';
            app.output_vector.Value = '0,3,1,2,3,2,1,3';
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = '码元周期';
            app.CodeTs = uieditfield(app.GridLayout2, 'numeric');
            app.CodeTs.Editable = 'off';
            app.CodeTs.Value = 3.5e-07;
            app.PAMLabel = uilabel(app.GridLayout2);
            app.PAMLabel.Text = 'PAM基带调制的进制数';
            app.M_Number = uieditfield(app.GridLayout2, 'numeric');
            app.M_Number.Editable = 'off';
            app.M_Number.Value = 4;
            app.Label_3 = uilabel(app.GridLayout2);
            app.Label_3.Text = '跳频序列';
            app.SkipF_vector = uieditfield(app.GridLayout2, 'text');
            app.SkipF_vector.Value = '0,3,1,2';
            app.Label_4 = uilabel(app.GridLayout2);
            app.Label_4.Text = '跳频点数';
            app.SkipF_Num = uieditfield(app.GridLayout2, 'numeric');
            app.SkipF_Num.Value = 32;
            app.Label_5 = uilabel(app.GridLayout2);
            app.Label_5.Text = '最小跳频间隔';
            app.SkipF_distance = uieditfield(app.GridLayout2, 'numeric');
            app.SkipF_distance.Value = 3000000;
            app.Label_6 = uilabel(app.GridLayout2);
            app.Label_6.Text = '跳频驻留时间';
            app.Skip_time = uieditfield(app.GridLayout2, 'numeric');
            app.Skip_time.Value = 0.0005;
            app.EditField_18Label = uilabel(app.GridLayout2);
            app.EditField_18Label.Text = '载波采样时间间隔';
            app.Sample_Rate = uieditfield(app.GridLayout2, 'numeric');
            app.Sample_Rate.Value = 3.5e-09;
            app.PAMLabel_2 = uilabel(app.GridLayout2);
            app.PAMLabel_2.Text = '线性调频最高频率';
            app.f_chirp_max = uieditfield(app.GridLayout2, 'numeric');
            app.f_chirp_max.Value = 30500000;
            app.EditField_18Label_2 = uilabel(app.GridLayout2);
            app.EditField_18Label_2.Text = '线性调频最低频率';
            app.f_chirp_min = uieditfield(app.GridLayout2, 'numeric');
            app.f_chirp_min.Value = 28500000;
            app.EditField_18Label_3 = uilabel(app.GridLayout2);
            app.EditField_18Label_3.Text = '线性调频变频周期';
            app.T_chirp = uieditfield(app.GridLayout2, 'numeric');
            app.T_chirp.Value = 2.8e-06;
            app.EditField_15Label = uilabel(app.GridLayout2);
            app.EditField_15Label.Text = '输入信号功率';
            app.Signal_Power = uieditfield(app.GridLayout2, 'numeric');
            app.Signal_Power.Value = 20;
            app.Label_7 = uilabel(app.GridLayout2);
            app.Label_7.Text = '功率控制系数';
            app.sig_power_controller_coeffience = uieditfield(app.GridLayout2, 'numeric');
            app.sig_power_controller_coeffience.Value = 163.1;
            app.Label_8 = uilabel(app.GridLayout2);
            app.Label_8.Text = '仿真时间';
            app.simulation_time = uieditfield(app.GridLayout2, 'numeric');
            app.simulation_time.Value = 0.004;
            app.start_sim = uibutton(app.GridLayout2, 'push');
            app.start_sim.ButtonPushedFcn = createCallbackFcn(app, @start_simButtonPushed, true);
            app.start_sim.Text = '开始功率估计';
            app.Mean_Power_Display = uitextarea(app.GridLayout2);
            app.Panel_3 = uipanel(app.GridLayout);
            app.Panel_3.Title = '干扰源设置';
            app.GridLayout3 = uigridlayout(app.Panel_3);
            app.CheckBox = uicheckbox(app.GridLayout3);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '是否使用转发干扰';
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '非转发干扰';
            app.Button = uibutton(app.Tab, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '设置干扰源';
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'img', '干扰源3.png');
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '转发干扰';
            app.GridLayout4 = uigridlayout(app.Tab_2);
            app.Label_9 = uilabel(app.GridLayout4);
            app.Label_9.Text = '间歇采样时间';
            app.ISDFJ_SampleTime = uieditfield(app.GridLayout4, 'numeric');
            app.ISDFJ_SampleTime.Value = 1e-05;
            app.Label_10 = uilabel(app.GridLayout4);
            app.Label_10.Text = '转发次数';
            app.ISDFJ_ForwardingTimes = uieditfield(app.GridLayout4, 'numeric');
            app.ISDFJ_ForwardingTimes.Value = 2;
            app.Label_11 = uilabel(app.GridLayout4);
            app.Label_11.Text = '功率控制系数';
            app.ISDFJ_PowerCoefficient = uieditfield(app.GridLayout4, 'numeric');
            app.ISDFJ_PowerCoefficient.Value = 1;
            app.ISDFJ_RandomPhaseFlag = uicheckbox(app.GridLayout4);
            app.ISDFJ_RandomPhaseFlag.Text = '是否选用随机相位调制';
            app.Label_12 = uilabel(app.GridLayout4);
            app.Label_12.Text = '相位码元周期';
            app.ISDFJ_CodePeriod = uieditfield(app.GridLayout4, 'numeric');
            app.ISDFJ_CodePeriod.Editable = 'off';
            app.ISDFJ_CodePeriod.Value = 3.5e-06;
            app.TimeScope = uitimescope(app.GridLayout4);
            app.TimeScope.Title = '间歇干扰的功率估计';
            app.TimeScope.YLabel = 'Amplitude';
            app.TimeScope.XLimits = [0 0.15];
            app.TimeScope.XTimeSpan = 0.15;
            app.Panel_4 = uipanel(app.GridLayout);
            app.Panel_4.Title = '仿真与结果调试';
            app.GridLayout5 = uigridlayout(app.Panel_4);
            app.Power_Estimate_axis = uiaxes(app.GridLayout5);
            title(app.Power_Estimate_axis, '发射机功率估计')
            xlabel(app.Power_Estimate_axis, 'X')
            ylabel(app.Power_Estimate_axis, 'Y')
            zlabel(app.Power_Estimate_axis, 'Z')
            app.Button_3 = uibutton(app.GridLayout5, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '开始仿真';
            app.Button_2 = uibutton(app.GridLayout5, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '结果调试';
            app.Panel_5 = uipanel(app.GridLayout);
            app.Panel_5.Title = '其他功能';
            app.GridLayout6 = uigridlayout(app.Panel_5);
            app.Button_4 = uibutton(app.GridLayout6, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '参数分析';
            app.Button_5 = uibutton(app.GridLayout6, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '相似度分析';
            app.Button_6 = uibutton(app.GridLayout6, 'state');
            app.Button_6.ValueChangedFcn = createCallbackFcn(app, @Button_6ValueChanged, true);
            app.Button_6.Text = '干扰源优化';
            app.Button_7 = uibutton(app.GridLayout6, 'state');
            app.Button_7.ValueChangedFcn = createCallbackFcn(app, @Button_7ValueChanged, true);
            app.Button_7.Text = '干扰效能评估';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Link

            % Associate the Simulink Model
            app.Simulation = simulation('Simple_Model_Packaging_7');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end