classdef Noise_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        DropDown_3                     matlab.ui.control.DropDown
        Label_36                       matlab.ui.control.Label
        Panel_6                        matlab.ui.container.Panel
        GridLayout16_2                 matlab.ui.container.GridLayout
        NoiseGenButton_2               matlab.ui.control.Button
        quick_simulation               matlab.ui.control.Button
        NoiseGenButton                 matlab.ui.control.Button
        clear_cache                    matlab.ui.control.Button
        Noise_Bandwidth                matlab.ui.container.Panel
        GridLayout23                   matlab.ui.container.GridLayout
        Button                         matlab.ui.control.Button
        passband_bw                    matlab.ui.control.NumericEditField
        Label_35                       matlab.ui.control.Label
        passband_centerFreq            matlab.ui.control.EditField
        Label_34                       matlab.ui.control.Label
        transition_bw                  matlab.ui.control.NumericEditField
        EditField_14Label              matlab.ui.control.Label
        passband_num                   matlab.ui.control.NumericEditField
        Label_32                       matlab.ui.control.Label
        Panel_5                        matlab.ui.container.Panel
        GridLayout16                   matlab.ui.container.GridLayout
        power_controller_W             matlab.ui.control.NumericEditField
        Label_31                       matlab.ui.control.Label
        power_controller_coefficience  matlab.ui.control.NumericEditField
        Label_30                       matlab.ui.control.Label
        EditField_10                   matlab.ui.control.NumericEditField
        EditField_2Label               matlab.ui.control.Label
        EditField_9                    matlab.ui.control.NumericEditField
        EditFieldLabel                 matlab.ui.control.Label
        Panel_4                        matlab.ui.container.Panel
        GridLayout13_2                 matlab.ui.container.GridLayout
        TabGroup4_2                    matlab.ui.container.TabGroup
        NoneTab_3                      matlab.ui.container.Tab
        GridLayout20_2                 matlab.ui.container.GridLayout
        Label_27                       matlab.ui.control.Label
        MFTab                          matlab.ui.container.Tab
        GridLayout22                   matlab.ui.container.GridLayout
        MultiFreqF                     matlab.ui.control.EditField
        Label_29                       matlab.ui.control.Label
        MultiFreqA                     matlab.ui.control.EditField
        Label_28                       matlab.ui.control.Label
        Panel_3                        matlab.ui.container.Panel
        GridLayout13                   matlab.ui.container.GridLayout
        TabGroup4                      matlab.ui.container.TabGroup
        NoneTab_2                      matlab.ui.container.Tab
        GridLayout20                   matlab.ui.container.GridLayout
        Label_15                       matlab.ui.control.Label
        Tab_5                          matlab.ui.container.Tab
        GridLayout14                   matlab.ui.container.GridLayout
        StepSweepN                     matlab.ui.control.NumericEditField
        Label_22                       matlab.ui.control.Label
        StepSweepT                     matlab.ui.control.NumericEditField
        Label_21                       matlab.ui.control.Label
        StepSweepFmax                  matlab.ui.control.NumericEditField
        Label_20                       matlab.ui.control.Label
        StepSweepFmin                  matlab.ui.control.NumericEditField
        Label_19                       matlab.ui.control.Label
        Tab_6                          matlab.ui.container.Tab
        GridLayout21                   matlab.ui.container.GridLayout
        MultiFreqSkipTime              matlab.ui.control.NumericEditField
        Label_26                       matlab.ui.control.Label
        MultiFreqSkipDistance          matlab.ui.control.NumericEditField
        Label_25                       matlab.ui.control.Label
        MultiFreqSkipSequence          matlab.ui.control.EditField
        Label_24                       matlab.ui.control.Label
        MultiFreqSkipNum               matlab.ui.control.NumericEditField
        Label_23                       matlab.ui.control.Label
        Panel_2                        matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        TabGroup2                      matlab.ui.container.TabGroup
        NoneTab                        matlab.ui.container.Tab
        GridLayout18                   matlab.ui.container.GridLayout
        Label_3                        matlab.ui.control.Label
        Tab                            matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        pulseNoise_pass_bw             matlab.ui.control.NumericEditField
        EditField_12Label              matlab.ui.control.Label
        pulseNoise_centerFreq          matlab.ui.control.NumericEditField
        Label_6                        matlab.ui.control.Label
        GaussPower                     matlab.ui.control.NumericEditField
        Label_4                        matlab.ui.control.Label
        Tab_3                          matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        SinF                           matlab.ui.control.NumericEditField
        Label_11                       matlab.ui.control.Label
        SinAM                          matlab.ui.control.NumericEditField
        Label_10                       matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        DropDown_2                     matlab.ui.control.DropDown
        Label                          matlab.ui.control.Label
        DropDown1                      matlab.ui.control.DropDown
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = public)
        datalink_selector_num;
        Mean_Power;
        quick_simulation_flag;
        Ts; % Description
        T_Noise; % Description
    end
    
    methods (Access = private)
        
        function NoiseDisp(app, app_NoiseRD,t, noise)
             %画出噪声信号时域波形
             plot(app_NoiseRD.UIAxes,t,noise);
                
             %获得频域噪声数据
             noise_freq = fft(noise);
             %画出噪声的频域幅值图
             Fs = 1/app.Ts;
             freq = (-Fs/2) : Fs/length(noise_freq) : (Fs/2)-(Fs/length(noise_freq));
             plot(app_NoiseRD.UIAxes2,freq,abs(fftshift(noise_freq))/  max(max(abs(fftshift(noise_freq))),1e-10)  );
             %画出噪声的频域相位图
             plot(app_NoiseRD.UIAxes3,freq,unwrap(angle(noise_freq)));
        end

    end
    
    methods (Access = public)
        
         function b = designMutiBandpassFilter(app, polit_filter_num, polit_centerFreq_arr, pass_bw, transitionWidth)
            fs = 1/app.Ts;
            % 初始化滤波器频率结构体
            filter_freq = struct;
            
            % 使用循环为结构体创建属性
            for i = 1:polit_filter_num*2
                propName = sprintf('f%d', i);
                propValue = 0;
                filter_freq.(propName) = propValue;
            end
            
            % 设计滤波器的中心频率
            DC_0_flag = true;
            for freq_num = 1:polit_filter_num
                polit_centerFreq = polit_centerFreq_arr(freq_num);
                if polit_centerFreq <= 1.5e6
                    propName = sprintf('f%d', 2 * freq_num);
                    filter_freq.(propName) = polit_centerFreq;
                    filter_freq = rmfield(filter_freq, 'f1');
                    DC_0_flag = false;
                else
                    propName = sprintf('f%d', 2*freq_num-1);
                    filter_freq.(propName) = polit_centerFreq - pass_bw/2;
            
                    propName = sprintf('f%d', 2*freq_num);
                    filter_freq.(propName) = polit_centerFreq + pass_bw/2;
                end
            end
            
            % 设计滤波器的各通带、阻带的边界频率
            freq_group = [];
            filter_freq_name = fieldnames(filter_freq);
            for i = 1:round(length(filter_freq_name)/2)
                if isfield(filter_freq, 'f1')
                    freq_group = [freq_group filter_freq.(filter_freq_name{2*i-1})-transitionWidth filter_freq.(filter_freq_name{2*i-1}) ...
                        filter_freq.(filter_freq_name{2*i}) filter_freq.(filter_freq_name{2*i})+transitionWidth];
                else
                    if filter_freq.(filter_freq_name{2*i-1}) <= 1.5e6
                        freq_group = [freq_group filter_freq.(filter_freq_name{2*i-1}) filter_freq.(filter_freq_name{2*i-1})+transitionWidth];
                    else
                        freq_group = [freq_group filter_freq.(filter_freq_name{2*i-2})-transitionWidth filter_freq.(filter_freq_name{2*i-2}) ...
                            filter_freq.(filter_freq_name{2*i-1}) filter_freq.(filter_freq_name{2*i-1})+transitionWidth];
                    end
                end
            end
            
            % 设置滤波器的各带通幅度，和各通阻带的波动系数
            Band_amplitude = zeros(1, round((length(freq_group)+2)/2));
            deviation = ones(1, round((length(freq_group)+2)/2)) * 80;
            
            if ~DC_0_flag
                Band_amplitude(1:2:length(Band_amplitude)) = 1;
                deviation(1:2:length(Band_amplitude)) = 0.05;
            else
                Band_amplitude(2:2:length(Band_amplitude)) = 1;
                deviation(2:2:length(Band_amplitude)) = 0.05;
            end
            
            if ~all(diff(freq_group) > 0)
                str_disp = sprintf('频带设计不合理，目前的阻带和通带截止频率情况为：%s',num2str(freq_group));
            %     disp(str_disp);
                error(str_disp);
            
            end
            
            
            % 采用窗函数法设计fir滤波器，使用kaiser窗
            [n,Wn,beta,ftype] = kaiserord(freq_group, Band_amplitude, deviation, fs); % 计算阶数和归一化截止频率
            
            % 使用 fir1 函数设计滤波器
            b = fir1(n, Wn, ftype, kaiser(n+1,beta), 'noscale');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app_parent, datalink_sampleRate, datalink_selector_num)
            app.EditField_9.Value = app_parent.datalink_sampleRate;
            app.Ts = app.EditField_9.Value;
            app.datalink_selector_num = app_parent.datalink_selector_num;

            app.Mean_Power = 0;
            app.quick_simulation_flag = 0;
            assignin('base','quick_simulation_flag',app.quick_simulation_flag);
            
        end

        % Button pushed function: NoiseGenButton
        function NoiseGenButtonPushed(app, event)
            fbar = waitbar(0,'请等待..');
            
            %该按钮按下生成噪声，首先读取三种类型选择按钮的值
            dropValue1 = app.DropDown1.Value;
            dropValue2 = app.DropDown_2.Value;
            dropValue3 = app.DropDown_3.Value;
            app.power_controller_coefficience.Value = 1;%初始默认功率控制系数为1
            
            %若未进行仿真，则为模型调取默认参数,将所有参数先重置
            run("Scripts/defaultmodelstruct.m")

            % 设置噪声源的采样时间、干扰功率系数，以及根据各判断类型生成对应的噪声参数
            app.Ts = app.EditField_9.Value;
            model.Parameters.NoiseGen.NoiseTs = app.Ts;
            model.Parameters.jammer_power_controller = sqrt(app.power_controller_coefficience.Value);

            % 设置干扰源的带宽
            polit_filter_num = app.passband_num.Value;
            polit_centerFreq = str2num(app.passband_centerFreq.Value);
            pass_bw = app.passband_bw.Value;
            transitionWidth = app.transition_bw.Value;
            
            model.Parameters.NoiseGen.noise_filter_b = designMutiBandpassFilter(app, polit_filter_num, polit_centerFreq, pass_bw, transitionWidth);
            switch dropValue1
                case "None"
                    model.Parameters.NoiseGen.Type = "None";
                    model.Parameters.NoiseGen.Control.Source = 0;
                case "窄带噪声"
                    %生成高斯白噪声信号
                    model.Parameters.NoiseGen.Type = "高斯白噪声";
                    model.Parameters.NoiseGen.Control.Source = 1;
                    model.Parameters.NoiseGen.GaussPower = 10;       % 高斯白噪声的功率密度
                    model.Parameters.NoiseGen.GaussSeed = 67;      % 白噪声随机种子
                    model.Parameters.NoiseGen.pulse_noise_filter_b = designMutiBandpassFilter(app, 1, app.pulseNoise_centerFreq.Value, app.pulseNoise_pass_bw.Value, transitionWidth);

                case "单音噪声"
                    % 生成单音正弦噪声
                    model.Parameters.NoiseGen.Type = "单音正弦噪声";
                    model.Parameters.NoiseGen.Control.Source = 3;
                    model.Parameters.NoiseGen.SineNoiseA = app.SinAM.Value;      %单音正弦噪声的幅度
                    model.Parameters.NoiseGen.SineNoiseF = app.SinF.Value;       %单音正弦噪声的频率
                    model.Parameters.NoiseGen.SineNoisePhi0 = 0;                 %单音正弦噪声的初始相位

                    % model.Parameters.NoiseGen.SineNoiseMulti_selection = round(double(app.CheckBox_multione.Value)) ;
                    % model.Parameters.NoiseGen.SineNoiseFreqNum = app.SinFreqNum.Value; % 多音干扰的频点数参数
                    % model.Parameters.NoiseGen.SineNoiseOtherFreq = str2num(['[' app.SinOtherFreq.Value ']']);            % 多音干扰的频率参数
                    % model.Parameters.NoiseGen.SineNoiseOtherAmplitude = str2num(['[' app.SinOtherAmplitude.Value ']']);  % 多音干扰的幅度参数
              
                otherwise  % 不生成信号
                    model.Parameters.NoiseGen.Type = "None";
                    model.Parameters.NoiseGen.Control.Source = 0; 
                    
            end
            switch dropValue2
                case "None"
                    %干扰方式类型，默认是None
                    model.Parameters.NoiseGen.Jamming.Type = "None"; 
                    %噪声源的干扰方式控制位，默认为0. 0,干扰方式不工作
                    model.Parameters.NoiseGen.Control.Jamming = 0;   
                case "步进扫频"
                    %干扰方式类型
                    model.Parameters.NoiseGen.Jamming.Type = "步进调频"; 
                    %干扰方式控制位,2代表采用步进调频
                    model.Parameters.NoiseGen.Control.Jamming = 2;  
                    %步进扫频的最小频率
                    model.Parameters.NoiseGen.Jamming.StepSweepFmin = app.StepSweepFmin.Value;
                    %步进扫频的最大频率
                    model.Parameters.NoiseGen.Jamming.StepSweepFmax = app.StepSweepFmax.Value;
                    %步进扫频的周期
                    model.Parameters.NoiseGen.Jamming.StepSweepT = app.StepSweepT.Value;
                    %步进扫频的跳频个数
                    model.Parameters.NoiseGen.Jamming.StepSweepN = app.StepSweepN.Value;
                    %步进扫频的频率间隔
                    model.Parameters.NoiseGen.Jamming.StepSweepDeltaFstep = round(abs(app.StepSweepFmax.Value-app.StepSweepFmin.Value)/...
                        (app.StepSweepN.Value-1));
                    %步进扫频的时间间隔
                    model.Parameters.NoiseGen.Jamming.StepSweepTDwell = app.StepSweepT.Value / app.StepSweepN.Value;

                    %MF调制的跳频序列
                    model.Parameters.NoiseGen.MultiFreq.MFSequence = [0];
                    %MF调制的跳频个数
                    model.Parameters.NoiseGen.MultiFreq.MFNum =  32;
                    %MF调制的最小跳频间隔
                    model.Parameters.NoiseGen.MultiFreq.MFDistance =  1e6;
                    %MF调制的跳频驻留时间
                    model.Parameters.NoiseGen.MultiFreq.MFTime =  app.StepSweepT.Value ;

                case "随机扫频"
                    %先让步进扫频不工作
                    model.Parameters.NoiseGen.Jamming.Type = "None"; 
                    model.Parameters.NoiseGen.Control.Jamming = 0;  
                    %MF调制的跳频序列
                    model.Parameters.NoiseGen.MultiFreq.MFSequence = str2num(['[' app.MultiFreqSkipSequence.Value ']']);
                    %MF调制的跳频个数
                    model.Parameters.NoiseGen.MultiFreq.MFNum =  app.MultiFreqSkipNum.Value ;
                    %MF调制的最小跳频间隔
                    model.Parameters.NoiseGen.MultiFreq.MFDistance =  app.MultiFreqSkipDistance.Value ;
                    %MF调制的跳频驻留时间
                    model.Parameters.NoiseGen.MultiFreq.MFTime =  app.MultiFreqSkipTime.Value ;

            end
            switch dropValue3
                case "None"
                    if dropValue2 ~= "随机扫频"
                        %多频干扰类型，默认是None
                        model.Parameters.NoiseGen.MultiFreq.Type = "None"; 
                        %噪声源的多频干扰控制位，默认为0. 0,多频干扰不工作
                        model.Parameters.NoiseGen.Control.MultiFreq = 0;
                    else
                        %多频干扰类型
                        model.Parameters.NoiseGen.MultiFreq.Type = "多频干扰"; 
                        %噪声源的多频干扰控制位，1多频干扰工作
                        model.Parameters.NoiseGen.Control.MultiFreq = 1;
                        %MF调制的幅度
                        model.Parameters.NoiseGen.MultiFreq.MFA = [1];  
                        %MF调制的频率
                        model.Parameters.NoiseGen.MultiFreq.MFF = [0]; 
                    end
                case "多频干扰"
                    %多频干扰类型
                    model.Parameters.NoiseGen.MultiFreq.Type = "多频干扰"; 
                    %噪声源的多频干扰控制位，1多频干扰工作
                    model.Parameters.NoiseGen.Control.MultiFreq = 1;
                    %MF调制的幅度
                    model.Parameters.NoiseGen.MultiFreq.MFA = str2num(['[' app.MultiFreqA.Value ']']);  
                    %MF调制的频率
                    model.Parameters.NoiseGen.MultiFreq.MFF = str2num(['[' app.MultiFreqF.Value ']']);        
                    
               
            end
            assignin('base','model',model); % 此处，干扰源参数都隶属于model.Parameters.NoiseGen结构体，将其赋值到基本工作区中，用于仿真运行
            app.T_Noise = app.EditField_10.Value;

            
            %运行Jammer_Generator.slx模型并设定仿真时间
            waitbar(.25,fbar,'开始运行Simulink模型');
            simOut = sim("Jammer_Generator.slx", 'stoptime', num2str(app.T_Noise));
            assignin("base", 'simOut', simOut);

            model.Parameters.NoiseGen.NoiseData = squeeze(simOut.Noise.Data);

            %% 这部分为添加的二次仿真，目的是使仿真的功率控制可以一次实现
            extend_multiple_2 = ceil(length(simOut.tout) / length(simOut.NoisePowerEstimation.Data));
            Power_Estimate = reshape(repmat(reshape(simOut.NoisePowerEstimation.Data,1,[]),extend_multiple_2,1),1,[]);
            Power_Estimate_mean = mean(Power_Estimate(end-length(Power_Estimate)/2:end));

            if strcmp(dropValue1, "窄带噪声") || strcmp(dropValue1, "单音噪声")
                Power_error = 0.1;
            else
                Power_error = 0.4;
            end

            while (abs(Power_Estimate_mean-app.power_controller_W.Value) >= Power_error )
                if Power_Estimate_mean == 0
                    app.power_controller_coefficience.Value = 0;
                else
                    app.power_controller_coefficience.Value = app.power_controller_coefficience.Value * app.power_controller_W.Value / Power_Estimate_mean;
                end
                model.Parameters.jammer_power_controller = sqrt(app.power_controller_coefficience.Value);
                assignin('base','model',model);
                simOut = sim("Jammer_Generator.slx", 'stoptime', num2str(app.T_Noise));
                assignin("base", 'simOut', simOut);
                extend_multiple_2 = ceil(length(simOut.tout) / length(simOut.NoisePowerEstimation.Data));
                Power_Estimate = reshape(repmat(reshape(simOut.NoisePowerEstimation.Data,1,[]),extend_multiple_2,1),1,[]);
                Power_Estimate_mean = mean(Power_Estimate(end-length(Power_Estimate)/2:end));
            end

            %%
            app.Mean_Power = Power_Estimate_mean;
            model.Parameters.NoiseGen.NosiePowerEstimation = Power_Estimate_mean;
            % 保存建模产生的噪声信号
            assignin('base','model',model); % 此处，将噪声数据和功率估计结果赋值到基本工作区中，

            % 此处决定着干扰源功率控制的逻辑
            if app.Mean_Power ~=0
               app.power_controller_W.Editable = 'on';
               app.power_controller_coefficience.Editable = 'off';
            end

            disp('noise generation finished');

            % 进度条窗口显示处理结束
            waitbar(1,fbar,'处理结束');
            close(fbar);

        end

        % Button pushed function: clear_cache
        function clear_cacheButtonPushed(app, event)
             Simulink.sdi.clear;
             uiconfirm(app.UIFigure,'clear accomplishment','Process Prompt','Icon','success');
        end

        % Button pushed function: NoiseGenButton_2
        function NoiseGenButton_2Pushed(app, event)
            try
                app_NoiseRD = Jammer_Result_Display(app);
            catch
                app_NoiseRD = Jammer_Result_Display(app);
            end
            
            simOut = evalin('base','simOut'); 
            model = evalin('base','model'); 
            
            
            model.Parameters.NoiseGen.NoiseData = squeeze(simOut.Noise.Data);
            NoiseDisp(app, app_NoiseRD, simOut.Noise.Time, model.Parameters.NoiseGen.NoiseData);


            %% 这部分为添加的二次仿真，目的是使仿真的功率控制可以一次实现
            extend_multiple_2 = ceil(length(simOut.tout) / length(simOut.NoisePowerEstimation.Data));
            Power_Estimate = reshape(repmat(reshape(simOut.NoisePowerEstimation.Data,1,[]),extend_multiple_2,1),1,[]);
            Power_Estimate_mean = mean(Power_Estimate(end-length(Power_Estimate)/2:end));

            % 功率估计,画图
            plot(app_NoiseRD.UIAxes4,simOut.NoisePowerEstimation.Time, Power_Estimate,'Marker','none')
            xlabel(app_NoiseRD.UIAxes4,"t/s");
            ylabel(app_NoiseRD.UIAxes4,"Amplitude");

            % 功率估计，显示最终时间的平均功率值
            str_display = [ "平均功率估计为：", num2str(Power_Estimate_mean)];
            app_NoiseRD.Mean_Power_Display.Value = str_display;
            
        end

        % Value changed function: DropDown1
        function DropDown1ValueChanged(app, event)
            value = app.DropDown1.Value;
            switch value
                case "None"
                   app.TabGroup2.SelectedTab = app.NoneTab;

                case "窄带噪声"
                   app.TabGroup2.SelectedTab = app.Tab;

                case "单音噪声"
                   app.TabGroup2.SelectedTab = app.Tab_3;

            end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            switch value
                case "None"
                   app.TabGroup4.SelectedTab = app.NoneTab_2;

                case "步进扫频"
                   app.TabGroup4.SelectedTab = app.Tab_5;

                case "随机扫频"
                   app.TabGroup4.SelectedTab = app.Tab_6;  

            end
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            switch value
                case "None"
                    app.TabGroup4_2.SelectedTab = app.NoneTab_3;

                case "多频干扰"
                    app.TabGroup4_2.SelectedTab = app.MFTab;
            end
        end

        % Button pushed function: quick_simulation
        function quick_simulationButtonPushed(app, event)
             try
                app_QuickSimu = Quick_Simulation(app);
            catch
                app_QuickSimu = Quick_Simulation(app);
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
             % 设置干扰源的带宽
            polit_filter_num = app.passband_num.Value;
            polit_centerFreq = str2num(app.passband_centerFreq.Value);
            pass_bw = app.passband_bw.Value;
            transitionWidth = app.transition_bw.Value;
            
            sys_b = designMutiBandpassFilter(app, polit_filter_num, polit_centerFreq, pass_bw, transitionWidth);
            
            % 选择频率分辨率点数
            N = 1024;
            
            % 计算频率响应
            [H, w] = freqz(sys_b, 1, N);
            
            % 将弧度转换为 Hz（假设采样频率为 Fs）
            fs = 1/app.Ts;  % 采样频率，单位 Hz
            w_hz = w * (fs / (2 * pi));

            try
                app_Sys_Filter = Sys_Filter_Display(app);
            catch
                app_Sys_Filter = Sys_Filter_Display(app);
            end
            
            % 绘制幅频响应
            plot(app_Sys_Filter.UIAxes, w_hz, 20 * log10(abs(H)));
            xlabel(app_Sys_Filter.UIAxes, '频率 (Hz)');
            ylabel(app_Sys_Filter.UIAxes, '增益 (dB)');
            grid(app_Sys_Filter.UIAxes,"on");
            
            % 绘制相频响应
            % plot(app_Sys_Filter.UIAxes2, w_hz, angle(H));
            % xlabel(app_Sys_Filter.UIAxes2, '频率 (Hz)');
            % ylabel(app_Sys_Filter.UIAxes2, '相位 (rad)');
            % grid(app_Sys_Filter.UIAxes2,"on")

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '干扰源配置';
            app.GridLayout = uigridlayout(app.Panel);
            app.DropDown1 = uidropdown(app.GridLayout);
            app.DropDown1.Items = {'None', '窄带噪声', '单音噪声'};
            app.DropDown1.ValueChangedFcn = createCallbackFcn(app, @DropDown1ValueChanged, true);
            app.DropDown1.Value = 'None';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '干扰源';
            app.DropDown_2 = uidropdown(app.GridLayout);
            app.DropDown_2.Items = {'None', '步进扫频', '随机扫频'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'None';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '扫频方式';
            app.Panel_2 = uipanel(app.GridLayout);
            app.Panel_2.Title = '干扰源';
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.TabGroup2 = uitabgroup(app.GridLayout3);
            app.NoneTab = uitab(app.TabGroup2);
            app.NoneTab.Title = 'None';
            app.GridLayout18 = uigridlayout(app.NoneTab);
            app.Label_3 = uilabel(app.GridLayout18);
            app.Label_3.Text = '未选择干扰源';
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = '窄带干扰';
            app.GridLayout4 = uigridlayout(app.Tab);
            app.Label_4 = uilabel(app.GridLayout4);
            app.Label_4.Text = '噪声功率';
            app.GaussPower = uieditfield(app.GridLayout4, 'numeric');
            app.GaussPower.Value = 10;
            app.Label_6 = uilabel(app.GridLayout4);
            app.Label_6.Text = '中心频率';
            app.pulseNoise_centerFreq = uieditfield(app.GridLayout4, 'numeric');
            app.pulseNoise_centerFreq.Value = 28900000;
            app.EditField_12Label = uilabel(app.GridLayout4);
            app.EditField_12Label.Text = '带宽';
            app.pulseNoise_pass_bw = uieditfield(app.GridLayout4, 'numeric');
            app.pulseNoise_pass_bw.Value = 2500000;
            app.Tab_3 = uitab(app.TabGroup2);
            app.Tab_3.Title = '单音干扰';
            app.GridLayout7 = uigridlayout(app.Tab_3);
            app.Label_10 = uilabel(app.GridLayout7);
            app.Label_10.Text = '单音干扰幅值';
            app.SinAM = uieditfield(app.GridLayout7, 'numeric');
            app.SinAM.Value = 1;
            app.Label_11 = uilabel(app.GridLayout7);
            app.Label_11.Text = '单音干扰载频';
            app.SinF = uieditfield(app.GridLayout7, 'numeric');
            app.SinF.Tooltip = {'须为多音干扰频率中最低的一个频率'};
            app.SinF.Value = 29500000;
            app.Panel_3 = uipanel(app.GridLayout);
            app.Panel_3.Title = '扫频方式';
            app.GridLayout13 = uigridlayout(app.Panel_3);
            app.TabGroup4 = uitabgroup(app.GridLayout13);
            app.NoneTab_2 = uitab(app.TabGroup4);
            app.NoneTab_2.Title = 'None';
            app.GridLayout20 = uigridlayout(app.NoneTab_2);
            app.Label_15 = uilabel(app.GridLayout20);
            app.Label_15.Text = '未选择干扰方式';
            app.Tab_5 = uitab(app.TabGroup4);
            app.Tab_5.Title = '步进扫频';
            app.GridLayout14 = uigridlayout(app.Tab_5);
            app.Label_19 = uilabel(app.GridLayout14);
            app.Label_19.Text = '扫频下限频率';
            app.StepSweepFmin = uieditfield(app.GridLayout14, 'numeric');
            app.StepSweepFmin.Value = 3000000;
            app.Label_20 = uilabel(app.GridLayout14);
            app.Label_20.Text = '扫频上限频率';
            app.StepSweepFmax = uieditfield(app.GridLayout14, 'numeric');
            app.StepSweepFmax.Value = 5000000;
            app.Label_21 = uilabel(app.GridLayout14);
            app.Label_21.Text = '步进扫频周期';
            app.StepSweepT = uieditfield(app.GridLayout14, 'numeric');
            app.StepSweepT.Value = 1e-05;
            app.Label_22 = uilabel(app.GridLayout14);
            app.Label_22.Text = '跳频个数';
            app.StepSweepN = uieditfield(app.GridLayout14, 'numeric');
            app.StepSweepN.Value = 4;
            app.Tab_6 = uitab(app.TabGroup4);
            app.Tab_6.Title = '随机扫频';
            app.GridLayout21 = uigridlayout(app.Tab_6);
            app.Label_23 = uilabel(app.GridLayout21);
            app.Label_23.Text = '跳频点个数';
            app.MultiFreqSkipNum = uieditfield(app.GridLayout21, 'numeric');
            app.MultiFreqSkipNum.Value = 32;
            app.Label_24 = uilabel(app.GridLayout21);
            app.Label_24.Text = '跳频序列';
            app.MultiFreqSkipSequence = uieditfield(app.GridLayout21, 'text');
            app.MultiFreqSkipSequence.Value = '0,1,3,2';
            app.Label_25 = uilabel(app.GridLayout21);
            app.Label_25.Text = '跳频频率最小间隔';
            app.MultiFreqSkipDistance = uieditfield(app.GridLayout21, 'numeric');
            app.MultiFreqSkipDistance.Value = 3000000;
            app.Label_26 = uilabel(app.GridLayout21);
            app.Label_26.Text = '跳频驻留时间';
            app.MultiFreqSkipTime = uieditfield(app.GridLayout21, 'numeric');
            app.MultiFreqSkipTime.Value = 0.0001;
            app.Panel_4 = uipanel(app.GridLayout);
            app.Panel_4.Title = '多频频点选择';
            app.GridLayout13_2 = uigridlayout(app.Panel_4);
            app.TabGroup4_2 = uitabgroup(app.GridLayout13_2);
            app.NoneTab_3 = uitab(app.TabGroup4_2);
            app.NoneTab_3.Title = 'None';
            app.GridLayout20_2 = uigridlayout(app.NoneTab_3);
            app.Label_27 = uilabel(app.GridLayout20_2);
            app.Label_27.Text = '未选择多频干扰';
            app.MFTab = uitab(app.TabGroup4_2);
            app.MFTab.Title = '多频调制参数';
            app.GridLayout22 = uigridlayout(app.MFTab);
            app.Label_28 = uilabel(app.GridLayout22);
            app.Label_28.Text = '多频幅度';
            app.MultiFreqA = uieditfield(app.GridLayout22, 'text');
            app.MultiFreqA.Value = '1,1';
            app.Label_29 = uilabel(app.GridLayout22);
            app.Label_29.Text = '多频频率（0表示原频率）';
            app.MultiFreqF = uieditfield(app.GridLayout22, 'text');
            app.MultiFreqF.Value = '0,3e6';
            app.Panel_5 = uipanel(app.GridLayout);
            app.Panel_5.Title = '其他参数';
            app.GridLayout16 = uigridlayout(app.Panel_5);
            app.EditFieldLabel = uilabel(app.GridLayout16);
            app.EditFieldLabel.Text = '噪声采样时间';
            app.EditField_9 = uieditfield(app.GridLayout16, 'numeric');
            app.EditField_9.Editable = 'off';
            app.EditField_9.Value = 1e-07;
            app.EditField_2Label = uilabel(app.GridLayout16);
            app.EditField_2Label.Text = '噪声显示时间';
            app.EditField_10 = uieditfield(app.GridLayout16, 'numeric');
            app.EditField_10.Value = 0.001;
            app.Label_30 = uilabel(app.GridLayout16);
            app.Label_30.Visible = 'off';
            app.Label_30.Text = '功率控制系数';
            app.power_controller_coefficience = uieditfield(app.GridLayout16, 'numeric');
            app.power_controller_coefficience.Editable = 'off';
            app.power_controller_coefficience.Visible = 'off';
            app.power_controller_coefficience.Value = 1;
            app.Label_31 = uilabel(app.GridLayout16);
            app.Label_31.Text = '功率控制';
            app.power_controller_W = uieditfield(app.GridLayout16, 'numeric');
            app.Noise_Bandwidth = uipanel(app.GridLayout);
            app.Noise_Bandwidth.Title = '干扰源带宽设置';
            app.GridLayout23 = uigridlayout(app.Noise_Bandwidth);
            app.Label_32 = uilabel(app.GridLayout23);
            app.Label_32.Text = '通带个数';
            app.passband_num = uieditfield(app.GridLayout23, 'numeric');
            app.passband_num.Value = 4;
            app.EditField_14Label = uilabel(app.GridLayout23);
            app.EditField_14Label.Text = '过渡带带宽';
            app.transition_bw = uieditfield(app.GridLayout23, 'numeric');
            app.transition_bw.Value = 400000;
            app.Label_34 = uilabel(app.GridLayout23);
            app.Label_34.Text = '通带中心频率';
            app.passband_centerFreq = uieditfield(app.GridLayout23, 'text');
            app.passband_centerFreq.Value = '29.5e6,32.5e6,35.5e6,38.5e6';
            app.Label_35 = uilabel(app.GridLayout23);
            app.Label_35.Text = '通带带宽';
            app.passband_bw = uieditfield(app.GridLayout23, 'numeric');
            app.passband_bw.Value = 2000000;
            app.Button = uibutton(app.GridLayout23, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '滤波响应';
            app.Panel_6 = uipanel(app.GridLayout);
            app.Panel_6.Title = '仿真与调试';
            app.GridLayout16_2 = uigridlayout(app.Panel_6);
            app.clear_cache = uibutton(app.GridLayout16_2, 'push');
            app.clear_cache.ButtonPushedFcn = createCallbackFcn(app, @clear_cacheButtonPushed, true);
            app.clear_cache.Text = '清除缓存';
            app.NoiseGenButton = uibutton(app.GridLayout16_2, 'push');
            app.NoiseGenButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseGenButtonPushed, true);
            app.NoiseGenButton.Text = '生成干扰源';
            app.quick_simulation = uibutton(app.GridLayout16_2, 'push');
            app.quick_simulation.ButtonPushedFcn = createCallbackFcn(app, @quick_simulationButtonPushed, true);
            app.quick_simulation.Tooltip = {'使用前需要先仿真一次链路，把链路参数导入到工作区中，并且功率控制框中的功率要与序列值的第一个值相同'};
            app.quick_simulation.Text = '快速仿真';
            app.NoiseGenButton_2 = uibutton(app.GridLayout16_2, 'push');
            app.NoiseGenButton_2.ButtonPushedFcn = createCallbackFcn(app, @NoiseGenButton_2Pushed, true);
            app.NoiseGenButton_2.Text = '结果调试';
            app.Label_36 = uilabel(app.GridLayout);
            app.Label_36.Text = '多频选择';
            app.DropDown_3 = uidropdown(app.GridLayout);
            app.DropDown_3.Items = {'None', '多频干扰'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = 'None';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Noise_new(varargin)

            % Associate the Simulink Model
            app.Simulation = simulation('Jammer_Generator');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end