classdef Parameter_Analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        highpass_module                 matlab.ui.container.Panel
        GridLayout2_4                   matlab.ui.container.GridLayout
        highpass_filter_start           matlab.ui.control.Button
        highpass_cutoff_freq            matlab.ui.control.NumericEditField
        Label_9                         matlab.ui.control.Label
        Combination_timeFreq_Analysis_module  matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        resume_speed_fitting            matlab.ui.control.Button
        Combination_timeFreq_Analysis_start  matlab.ui.control.Button
        information_entropy_step_num    matlab.ui.control.NumericEditField
        Label_14                        matlab.ui.control.Label
        energy_denoise_threshold_search  matlab.ui.control.EditField
        Label_13                        matlab.ui.control.Label
        Combination_timeFreq_Analysis_winLength  matlab.ui.control.NumericEditField
        Label_12                        matlab.ui.control.Label
        Combination_timeFreq_Analysis_winType  matlab.ui.control.EditField
        Label_11                        matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        update                          matlab.ui.control.Button
        timeSlice_module                matlab.ui.container.Panel
        GridLayout2_3                   matlab.ui.container.GridLayout
        timeSlice_start                 matlab.ui.control.Button
        time_end                        matlab.ui.control.NumericEditField
        EditField_12Label               matlab.ui.control.Label
        time_start                      matlab.ui.control.NumericEditField
        Label_7                         matlab.ui.control.Label
        mixing_module                   matlab.ui.container.Panel
        GridLayout2_2                   matlab.ui.container.GridLayout
        filter_option                   matlab.ui.control.EditField
        Label_6                         matlab.ui.control.Label
        mix_start                       matlab.ui.control.Button
        fpass_higher                    matlab.ui.control.NumericEditField
        EditField_4Label                matlab.ui.control.Label
        fpass_lower                     matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        f_mix                           matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        data_read_module                matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        timeFreqAnalysis_fs             matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        data_reset                      matlab.ui.control.Button
        file_name                       matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        data_read_start                 matlab.ui.control.Button
        Tab_2                           matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        result_display                  matlab.ui.container.Panel
        GridLayout29                    matlab.ui.container.GridLayout
        recognition_result              matlab.ui.control.TextArea
        Label_25                        matlab.ui.control.Label
        modulation_KNN_comparasion      matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        signal_waiting_analysis_length  matlab.ui.control.NumericEditField
        Label_23                        matlab.ui.control.Label
        KNN_cluster_num                 matlab.ui.control.NumericEditField
        KNNEditFieldLabel               matlab.ui.control.Label
        modulation_sample_num_comparasion  matlab.ui.control.NumericEditField
        Label_22                        matlab.ui.control.Label
        modulation_KNN_comparasion_start  matlab.ui.control.Button
        dataFeature_design_and_dataset_make_module  matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        Multi_base_modulation_order     matlab.ui.control.NumericEditField
        EditField_2Label_2              matlab.ui.control.Label
        modulation_sample_num           matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        parallel_CPU_core_num           matlab.ui.control.NumericEditField
        CPUEditFieldLabel               matlab.ui.control.Label
        dataFeature_design_and_dataset_make_module_start  matlab.ui.control.Button
        Rs_and_Fc_estimate_module       matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        Rs_and_Fc_estimate_module_start  matlab.ui.control.Button
        Rs_and_Fc_estimate_module_initialize  matlab.ui.control.Button
        auto_correlation_win_length     matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        auto_coreletion_FFT_point       matlab.ui.control.NumericEditField
        FFTEditFieldLabel               matlab.ui.control.Label
        curricular_spectrum_length      matlab.ui.control.NumericEditField
        Label_18                        matlab.ui.control.Label
        dataReadEffectiveSection_module  matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        dataReadEffectiveSection_module_Resume  matlab.ui.control.Button
        dataReadEffectiveSection_module_Start  matlab.ui.control.Button
        filename                        matlab.ui.control.EditField
        Label_17                        matlab.ui.control.Label
        sampleBit                       matlab.ui.control.EditField
        Label_16                        matlab.ui.control.Label
        sampleRate                      matlab.ui.control.NumericEditField
        Label_15                        matlab.ui.control.Label
    end

    
    properties (Access = public)
        module_input;
        module_output;
        module_dataRead_output;
        module_timeFreqAnalysis_input;

        fc_list; 
        Rs_list; 
        dataReadEffectiveSection_module_I_branch_output; % Description
        cellArrayText_recognition_result; % Description
        cellArrayText_parameter_estimation; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app_parent)
            
        end

        % Button pushed function: data_read_start
        function data_read_startButtonPushed(app, event)
            Sampling_bit = 'int16';     % 待读取文件中存储信号的采样位数
            filename = app.file_name.Value; % 文件名，后续还要做出自动分析每一文件的功能

            [filepath,name,ext] = fileparts(filename);
            if strcmp(ext, ".txt")
                fid = fopen(filename, 'r');
                [data] = fread(fid,inf,Sampling_bit);
                fclose(fid);
                I = data(1: 2: end); % IQ交替采样得到data,要剥离得到I和Q
                Q = data(2: 2: end);
                app.module_dataRead_output = I + 1j*Q;

            elseif strcmp(ext, ".mat")
                val_struct = load(filename); % 目前所写的程序只能加载当前文件夹下的mat文件
                val_names = fieldnames(val_struct);
                app.module_dataRead_output = getfield(val_struct, val_names{1});
            else
                disp("文件名输入有误");
            end

            if isrow(app.module_dataRead_output)
                app.module_input = app.module_dataRead_output;
                app.module_output = app.module_dataRead_output;
            else
                app.module_input = app.module_dataRead_output';
                app.module_output = app.module_dataRead_output';
            end   

            uiconfirm(app.UIFigure,'reading accomplishment','Process Prompt','Icon','success');
        end

        % Callback function
        function ButtonPushed(app, event)
            app.module_timeFreqAnalysis_input = app.module_output;
            fs = app.timeFreqAnalysis_fs.Value;
            
            tt = 0:1/fs:(length(app.module_timeFreqAnalysis_input)-1)*1/fs;
            N = length(app.module_timeFreqAnalysis_input);
            
            try
                app_tfDisplay = Timefreq_Display(app);
            catch ME
                disp(ME.message);
                uiconfirm(app.UIFigure,'无法打开','Process Prompt','Icon','warning');
            end
            
            
            plot(app_tfDisplay.time_waveform_axis,tt,real(app.module_timeFreqAnalysis_input));
            xlabel(app_tfDisplay.time_waveform_axis,"t/s")
            ylabel(app_tfDisplay.time_waveform_axis,"amplitude/V") 
            title(app_tfDisplay.time_waveform_axis, "原始信号时域图")

            sig_fft = abs(fftshift(fft(app.module_timeFreqAnalysis_input))); 
            freq = -fs/2:fs/N:fs/2-1;

            plot(app_tfDisplay.freq_waveform_axis,freq,sig_fft)
            xlabel(app_tfDisplay.freq_waveform_axis,"f/Hz")
            ylabel(app_tfDisplay.freq_waveform_axis,"amplitude/V")
            title(app_tfDisplay.freq_waveform_axis, "原始信号频域图")

            uiconfirm(app.UIFigure,'timeFreqAnalysis accomplishment','Process Prompt','Icon','success');
        end

        % Button pushed function: data_reset
        function data_resetButtonPushed(app, event)
            if isrow(app.module_dataRead_output)
                app.module_input = app.module_dataRead_output;
                app.module_output = app.module_dataRead_output;
            else
                app.module_input = app.module_dataRead_output';
                app.module_output = app.module_dataRead_output';
            end   
        end

        % Button pushed function: mix_start
        function mix_startButtonPushed(app, event)
            f_mix = app.f_mix.Value;
            filter_option = app.filter_option.Value;
            fpass2 = app.fpass_lower.Value;
            fpass3 = app.fpass_higher.Value;
            fs = app.timeFreqAnalysis_fs.Value;
            
            tt = 0:1/fs:1/fs*(length(app.module_input)-1);
            y_mixer_carrier = cos(2*pi*f_mix*tt);  % xlim([0.02 0.02+10/fs]);
            y_mutiply = y_mixer_carrier.*app.module_input;
            
            if strcmp(filter_option,"lowpass")
                app.module_output = lowpass(y_mutiply,fpass2,fs,'ImpulseResponse','iir');
            elseif strcmp(filter_option,"bandpass")
                app.module_output = bandpass(y_mutiply,[fpass2 fpass3],fs,'ImpulseResponse','iir');
            else
                uiconfirm(app.UIFigure,'滤波器选项错误','Process Prompt','Icon','warning');
            end

            uiconfirm(app.UIFigure,'mixing accomplishment','Process Prompt','Icon','success');
            
        end

        % Button pushed function: update
        function updateButtonPushed(app, event)
             app.module_input = app.module_output;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            try         
                Ctf_export_struct = evalin('base', 'Ctf_export_struct');
                app_Ctf_Display = CombiTimeFreq_Display(app);
                
                %% 对信号做hilbert变换
                fs = app.timeFreqAnalysis_fs.Value;             % 采样频率
                x_input = app.module_input;  % 待分析信号,需要是一个行向量
                N_WinLen = app.Combination_timeFreq_Analysis_winLength.Value;           % 组合时频分析窗长

                x_via_hilbert = hilbert(x_input');
                N_sig_hilbert_length = length(x_via_hilbert);

    
                %% 原始信号时域图
                plot(app_Ctf_Display.original_sig_time_axis, Ctf_export_struct.t_initial, Ctf_export_struct.x_input); %接收信号的时域图
                    
                %% 原始信号频域图
                plot(app_Ctf_Display.original_sig_freq_axis, Ctf_export_struct.f_initial, abs(Ctf_export_struct.y_via_fft(1:N_sig_hilbert_length/2+1)) ) %画接收信号的单边谱
                xlabel(app_Ctf_Display.original_sig_freq_axis, '频率f/Hz');
                ylabel(app_Ctf_Display.original_sig_freq_axis, '幅度');
                grid(app_Ctf_Display.original_sig_freq_axis, "on");

    
                %% 去噪前的时频矩阵图                                            
                imagesc(app_Ctf_Display.before_denoise_STFT_axis, [Ctf_export_struct.TFR1_T(1) Ctf_export_struct.TFR1_T(end)]*1/fs, [Ctf_export_struct.TFR1_F(1) Ctf_export_struct.TFR1_F(N_sig_hilbert_length/2)]*fs, 10.*log10(abs(Ctf_export_struct.TFR1(1:N_sig_hilbert_length/2, 1:N_sig_hilbert_length))));   %imagesc(A) 
                % 将矩阵A中的元素数值按大小转化为不同颜色，并在坐标轴对应位置处以这种颜色染色，imagesc在画纵轴的时候正半轴为向下，而时频矩阵TFR1的正半轴为向上
                axis(app_Ctf_Display.before_denoise_STFT_axis, "xy"); % 可以解决imagesc画图函数在画纵轴时存在颠倒的问题
                xlabel(app_Ctf_Display.before_denoise_STFT_axis, '时间t/s');
                ylabel(app_Ctf_Display.before_denoise_STFT_axis, '频率f/Hz');
    
                %% 去噪前的时频脊线图
                plot(app_Ctf_Display.before_denoise_ridgeLine_axis, Ctf_export_struct.TFR1_T*1/fs, Ctf_export_struct.TFR1_F(Ctf_export_struct.ff_TFR1_timeFreq_max)*fs)
                xlabel(app_Ctf_Display.before_denoise_ridgeLine_axis, '时间t/s');
                ylabel(app_Ctf_Display.before_denoise_ridgeLine_axis, '频率f/Hz');

                %% STFT沿时间轴的归一化值
                plot(app_Ctf_Display.STFT_Relevance_axis, (0:length(Ctf_export_struct.stft_t)-1)*max(Ctf_export_struct.t_initial)/length(Ctf_export_struct.stft_t),Ctf_export_struct.stft_t./max(Ctf_export_struct.stft_t));
                xlabel(app_Ctf_Display.STFT_Relevance_axis, 'Time/s');
                ylabel(app_Ctf_Display.STFT_Relevance_axis, 'Normalized |STFT(t)|');

                %% STFT沿时间轴的归一化值的fft
                plot(app_Ctf_Display.peak_sequence_STFT_FFT_axis, Ctf_export_struct.f_Peak_sequence, Ctf_export_struct.y_stft_t);
                xlabel(app_Ctf_Display.peak_sequence_STFT_FFT_axis, 'Frequency/Hz');
                ylabel(app_Ctf_Display.peak_sequence_STFT_FFT_axis, 'Amplitude');

                %% 跳频信息显示
                str_disp = ['跳频频率为：' num2str(Ctf_export_struct.fhdd) 'Hz ' '跳周期为：' num2str(Ctf_export_struct.Thd) 's'];
                app_Ctf_Display.HF_information_text_display.Value = str_disp;
                
                %% 去噪后的STFT脊线
                plot(app_Ctf_Display.after_denoise_STFT_axis, Ctf_export_struct.TFR1_T/fs, Ctf_export_struct.TFR1_F(Ctf_export_struct.ff_TFR2_timeFreq_max)*fs)  % TFR1和TFR2的时间序列和频率序列相同
                xlabel(app_Ctf_Display.after_denoise_STFT_axis, '时间t/s');
                ylabel(app_Ctf_Display.after_denoise_STFT_axis, '频率f/Hz');
                title(app_Ctf_Display.after_denoise_STFT_axis, '去噪后的STFT变换时频脊线')
                
                %% 去噪前后的时域波形对比
                plot(app_Ctf_Display.comparasion_before_after_denoise_axis, Ctf_export_struct.t_via_denoise, Ctf_export_struct.x_input,'Color','r','Marker','+');
                hold(app_Ctf_Display.comparasion_before_after_denoise_axis, "on");
                plot(app_Ctf_Display.comparasion_before_after_denoise_axis, Ctf_export_struct.t_via_denoise, abs(Ctf_export_struct.x_denoise),'Color','g','Marker','o');
                hold(app_Ctf_Display.comparasion_before_after_denoise_axis, "on");
                legend(app_Ctf_Display.comparasion_before_after_denoise_axis, "原信号", "去噪信号",'Fontsize',4);
               
                %% 去噪前的时域波形
                plot(app_Ctf_Display.befre_denoise_time_axis, Ctf_export_struct.t_via_denoise, Ctf_export_struct.x_input);
                xlabel(app_Ctf_Display.befre_denoise_time_axis, '时间t/s');
                ylabel(app_Ctf_Display.befre_denoise_time_axis, '幅值');
    
                %% 组合时频变换后的时频矩阵
                imagesc(app_Ctf_Display.after_Combination_timeFreq_axis, Ctf_export_struct.TFR3_T*1/fs, Ctf_export_struct.TFR1_F(1:N_sig_hilbert_length/2)*fs, 10.*log10(abs(Ctf_export_struct.TFR4(1:N_sig_hilbert_length/2, 1:N_sig_hilbert_length))));  % 注意此处的时频坐标度量  %caxis([-6 -3]);
                axis(app_Ctf_Display.after_Combination_timeFreq_axis, "xy");
                xlabel(app_Ctf_Display.after_Combination_timeFreq_axis, '时间t/s');
                ylabel(app_Ctf_Display.after_Combination_timeFreq_axis, '频率f/Hz');

                %% 组合时频变换后的时频变换脊线
                plot(app_Ctf_Display.after_Combination_timeFreq_ridgeLine_axis, Ctf_export_struct.TFR3_T*1/fs, Ctf_export_struct.TFR1_F(Ctf_export_struct.ff_TFR4_timeFreq_max)*fs); % 同样需要注意此处的坐标度量
                xlabel(app_Ctf_Display.after_Combination_timeFreq_ridgeLine_axis, '时间t/s');
                ylabel(app_Ctf_Display.after_Combination_timeFreq_ridgeLine_axis, '频率f/Hz');
                title(app_Ctf_Display.after_Combination_timeFreq_ridgeLine_axis, strcat('组合时频变换后-', 'hamming窗-', num2str(N_WinLen), '窗长' ) );

                %% 信息熵显示
                str_disp = ['信息熵为：' num2str(Ctf_export_struct.Information_Entropy)];
                app_Ctf_Display.information_entropy.Value = str_disp;

                %% 跳频周期次数统计
                histogram(app_Ctf_Display.HF_period_statistic_axis, Ctf_export_struct.hop_time, 20)  % 做直方图用于统计
                xlabel(app_Ctf_Display.HF_period_statistic_axis, 't/ns');
                ylabel(app_Ctf_Display.HF_period_statistic_axis, '可能的跳频间隔统计/次');
                
                %% 时频脊线随点数的变化情况
                plot(app_Ctf_Display.timeFreq_point_axis, Ctf_export_struct.freq);
                xlabel(app_Ctf_Display.timeFreq_point_axis, '点数');
                ylabel(app_Ctf_Display.timeFreq_point_axis, '频率f/Hz');
    
                %% 线性扫频速率拟合
                plot(app_Ctf_Display.Linear_sweep_frequency_fitting_axis, Ctf_export_struct.time_2, Ctf_export_struct.freq_2,'Marker','o')
                hold(app_Ctf_Display.Linear_sweep_frequency_fitting_axis, "on");
    
                plot(app_Ctf_Display.Linear_sweep_frequency_fitting_axis, Ctf_export_struct.x1, Ctf_export_struct.y1,'Marker','x');
                hold(app_Ctf_Display.Linear_sweep_frequency_fitting_axis, "off");
                xlabel(app_Ctf_Display.Linear_sweep_frequency_fitting_axis, '时间t/s');
                ylabel(app_Ctf_Display.Linear_sweep_frequency_fitting_axis, '频率f/Hz');
                legend(app_Ctf_Display.Linear_sweep_frequency_fitting_axis, "实际跳频频率","拟合出的线性扫频频率",'Fontsize',4);
                            
                %% 去噪后的时域信号
                plot(app_Ctf_Display.after_denoiise_time_axis, Ctf_export_struct.t_via_denoise, abs(Ctf_export_struct.x_denoise));
                xlabel(app_Ctf_Display.after_denoiise_time_axis, '时间t/s');
                ylabel(app_Ctf_Display.after_denoiise_time_axis, '幅值');

                %% 平均带宽显示
                str_disp = ['该段信号的平均带宽为：' num2str(Ctf_export_struct.bandwidth_mean) 'Hz'];
                app_Ctf_Display.mean_BW_display.Value = str_disp;

                %% 瞬时带宽变化图
                plot(app_Ctf_Display.Instantaneous_BW_axis, Ctf_export_struct.t_BW_measure, Ctf_export_struct.bandwidth_instantaneous)
                xlabel(app_Ctf_Display.Instantaneous_BW_axis, '时间t/s');
                ylabel(app_Ctf_Display.Instantaneous_BW_axis, '带宽频率f/Hz');
                title(app_Ctf_Display.Instantaneous_BW_axis, '该段信号内带宽随时间变化关系图', 'Fontsize', 14, 'Fontweight', 'bold')

            catch ME
                disp(ME.message);
                delete(app_Ctf_Display);
                uiconfirm(app.UIFigure,'无法打开','Process Prompt','Icon','warning');
            end


        end

        % Button pushed function: timeSlice_start
        function timeSlice_startButtonPushed(app, event)
            tw1 = app.time_start.Value;
            tw2 = app.time_end.Value;
            fs = app.timeFreqAnalysis_fs.Value;
            tt = 0:1/fs:(length(app.module_input)-1)*1/fs;
            Nw1 = max(1,round((tw1-tt(1))*fs));
            Nw2 = round((tw2-tw1)*fs);
            tt_via_timeSclice = tw1:1/fs:tw2;
            
            y_via_timeSlice = app.module_input(Nw1:Nw1+Nw2);

            try
                app_tfDisplay = Timefreq_Display(app);
            catch ME
                disp(ME.message);
                uiconfirm(app.UIFigure,'无法打开','Process Prompt','Icon','warning');
            end
            
            plot(app_tfDisplay.time_waveform_axis,tt_via_timeSclice, real(y_via_timeSlice));
            xlabel(app_tfDisplay.time_waveform_axis,"t/s")
            ylabel(app_tfDisplay.time_waveform_axis,"amplitude/V") 
            
            sig_fft = abs(fftshift(fft(y_via_timeSlice))); 
            N = Nw2+1;
            freq = -fs/2:fs/N:fs/2-1;

            plot(app_tfDisplay.freq_waveform_axis,freq,sig_fft)
            xlabel(app_tfDisplay.freq_waveform_axis,"f/Hz")
            ylabel(app_tfDisplay.freq_waveform_axis,"amplitude/V")       % xlim([-0.1e9 0.1e9]);

            uiconfirm(app.UIFigure,'timeSlice accomplishment','Process Prompt','Icon','success');

        end

        % Callback function
        function timeSlice_endButtonPushed(app, event)
             app.module_input = app.module_output;
        end

        % Button pushed function: highpass_filter_start
        function highpass_filter_startButtonPushed(app, event)
            fpass = app.highpass_cutoff_freq.Value;
            fs = app.timeFreqAnalysis_fs.Value;
            app.module_output = highpass(app.module_input,fpass,fs,'ImpulseResponse','iir'); %高通滤波

            uiconfirm(app.UIFigure,'highpassFilter accomplishment','Process Prompt','Icon','success');
        end

        % Button pushed function: Combination_timeFreq_Analysis_start
        function Combination_timeFreq_Analysis_startButtonPushed(app, event)
            %% 对信号做hilbert变换
            fbar = waitbar(0,'请等待..');
            fs = app.timeFreqAnalysis_fs.Value;             % 采样频率
            x_input = app.module_input;  % 待分析信号,需要是一个行向量
            N_WinLen = app.Combination_timeFreq_Analysis_winLength.Value;           % 组合时频分析窗长
            Win_Type = eval(strcat("@",app.Combination_timeFreq_Analysis_winType.Value));     % 组合时频分析窗类型
            ww = eval(app.energy_denoise_threshold_search.Value);            % 能量去噪的阈值寻优范围
            NN_Interval_Number = app.information_entropy_step_num.Value; % 衡量信息熵的步长数目
            Ctf_export_struct = struct();
            
            Windows_Type_Choose = window(Win_Type, N_WinLen);      %加窗
            x_via_hilbert = hilbert(x_input');
            N_sig_hilbert_length = length(x_via_hilbert);
            t_initial = 0:1/fs:(N_sig_hilbert_length-1)*1/fs;
            f_initial = 0 :fs/N_sig_hilbert_length:(fs/2);
            y_via_fft = fft(x_via_hilbert);

            Ctf_export_struct.t_initial = t_initial;
            Ctf_export_struct.x_input = x_input;
            Ctf_export_struct.f_initial = f_initial;
            Ctf_export_struct.y_via_fft = y_via_fft;

            waitbar(.1,fbar,'完成hilbert变换');

            %% 对信号做STFT
            [TFR1, TFR1_T, TFR1_F] = tfrstft(x_input',1:length(x_input'),N_sig_hilbert_length,Windows_Type_Choose);  %短时傅里叶变换函数tfrstft
                                                    %输入为：信号x
                                                    %        信号长度
                                                    %        所加窗函数
                                                    %输出为：短时傅里叶变换得到的时频矩阵
                                                    %        时频矩阵对应的时间序列
                                                    %        时频矩阵对应的频率序列
                                                   
             %% 去噪前的时频矩阵图 
             Ctf_export_struct.TFR1_T = TFR1_T;
             Ctf_export_struct.TFR1_F = TFR1_F;
             Ctf_export_struct.TFR1 = TFR1;
            [~,ff_TFR1_timeFreq_max]=max(abs(TFR1(1:N_sig_hilbert_length/2,1:N_sig_hilbert_length)));%%%%%%%%%%%%     时频脊线

            Ctf_export_struct.ff_TFR1_timeFreq_max = ff_TFR1_timeFreq_max;
            waitbar(.2,fbar,'完成STFT');
            
            %% 提取时间/频率平面(基于峰值序列估计跳速),对时频脊线进行的处理
            stft_t = max(abs(TFR1),[],1);       %每个时间点所有频率对应的最大频谱幅值
            stft_t = stft_t./max(stft_t);       %幅度归一化
            stft_t = stft_t-mean(abs(stft_t));  %去直流（mean取平均值）

            Ctf_export_struct.stft_t = stft_t;
            
            y_stft_t = fftshift(abs(fft(stft_t)));
            f_Peak_sequence = (0:(length(y_stft_t)-1)) * fs/length(y_stft_t) - fs/2;
            
            index = find(y_stft_t==max(y_stft_t));
            fhd = (index-1)*fs/length(y_stft_t) - fs/2; %频率是从0开始的，数组下标从1开始
            fhdd = fhd(find(fhd>0)); 
            Thd = 1/fhdd;

            Ctf_export_struct.f_Peak_sequence = f_Peak_sequence;
            Ctf_export_struct.y_stft_t = y_stft_t;
            Ctf_export_struct.fhdd = fhdd;
            Ctf_export_struct.Thd = Thd;
            waitbar(.3,fbar,'完成时频脊线提取及跳周期估计');
            
            %% 能量门限去噪
            [TFR1_row, TFR1_col] = size(TFR1);
            TFR1_power = abs(TFR1);
            threshold = ww./TFR1_row./TFR1_col.*sum(sum(TFR1_power)); 
            threshold_length = length(threshold);
            c_t = zeros(1,threshold_length);
            for i = 1:threshold_length
                 c_t(i) = sum(sum(TFR1_power > threshold(i)));
            end
            
            c_t_1 = diff(c_t);
            c_t_2 = diff(c_t_1);
            [~, suitable_threshold_index] = min(abs(c_t_2));
            suitable_threshold = threshold(suitable_threshold_index);
            
            TFR2 = TFR1;  % TFR2为去除小信号噪声后的时频矩阵
            TFR2(TFR1_power < suitable_threshold) = 0;
            
            [~, ff_TFR2_timeFreq_max]=max(abs(TFR2(1:TFR1_col/2,1:TFR1_col))); % 时频脊线
            [x_denoise, ~] = tfristft(TFR2, 1:N_sig_hilbert_length, Windows_Type_Choose, 0);
            t_via_denoise = 0:1/fs:(N_sig_hilbert_length-1)*1/fs;  %去噪前后的时域波形对比

            Ctf_export_struct.ff_TFR2_timeFreq_max = ff_TFR2_timeFreq_max;
            Ctf_export_struct.x_denoise = x_denoise;
            Ctf_export_struct.t_via_denoise = t_via_denoise;

            waitbar(.4,fbar,'完成能量门限去噪');
            
            %% 组合时频分析
            [TFR3,TFR3_T,TFR3_FF]=tfrspwv(x_via_hilbert,1:N_sig_hilbert_length,N_sig_hilbert_length,Windows_Type_Choose,Windows_Type_Choose); % 伪平滑魏格纳-维利变换
            
            TFR4 = TFR2(1:N_sig_hilbert_length/2,:).* TFR3(1:2:N_sig_hilbert_length,:); 
                                % STFT 与 SPWV 组合时频相乘，由于两种变换的机理不同，
                                % 因此相乘时需采取交叉相乘，以保证同一频率上的时频分量进行了相乘

            [~,ff_TFR4_timeFreq_max] = max(abs(TFR4(1:N_sig_hilbert_length/2,1:N_sig_hilbert_length))); % 时频脊线
            
            Ctf_export_struct.TFR3_T = TFR3_T;
            Ctf_export_struct.TFR1_F = TFR1_F;
            Ctf_export_struct.TFR4 = TFR4;
            Ctf_export_struct.ff_TFR4_timeFreq_max = ff_TFR4_timeFreq_max;

%             PlotSTFT(TFR3_T*1/fs,TFR1_F(1:N_sig_hilbert_length/2)*fs,abs(TFR4(1:N_sig_hilbert_length/2,1:N_sig_hilbert_length)));  
            waitbar(.6,fbar,'完成组合时频分析');
            
            %% 基于信息熵衡量时频聚焦性
            TFR4_abs = abs(TFR4);
            TFR4_abs_max = max(max(TFR4_abs));
            TFR4_abs_min = max(min(TFR4_abs));
            
            search_step = (TFR4_abs_max - TFR4_abs_min)/NN_Interval_Number;
            Section_Group = zeros(1, NN_Interval_Number);
            [row, col] = size(TFR4_abs);
            
            for i = 1:row
                for j = 1:col
                    if TFR4_abs(i, j) == TFR4_abs_max
                        Section_Group(end) = Section_Group(end) + 1;
                    else
                        Section_Num = fix( (TFR4_abs(i, j) - TFR4_abs_min)/search_step) + 1;
                        Section_Group(Section_Num) = Section_Group(Section_Num) + 1;
                    end     
                end
            end
            
            Section_Group_Chance = Section_Group./length(TFR4_abs(:));
            Information_Entropy = - sum(Section_Group_Chance .* log( Section_Group_Chance) );

            Ctf_export_struct.Information_Entropy = Information_Entropy;
            waitbar(.7,fbar,'完成信息熵评估');
            
            %% 通过时频脊线提取跳频时刻，统计跳频周期
            time = TFR3_T*1/fs;                     % 各时频脊线对应的时刻点
            freq = TFR1_F(ff_TFR1_timeFreq_max)*fs; % 各时频脊线对应的频率点
            N_freq = length(freq);
            time_pre = [];
            for i = 2:N_freq-1
                if (freq(i+1)-freq(i) ~= 0) & (freq(i-1)-freq(i)==0 )
                    time_pre = [time_pre time(i)];  % 各跳时刻的终点
                end
            end
            
            time_pre_2 = [];
            for i = 2:N_freq-1
                if (freq(i-1)-freq(i) ~= 0) & (freq(i+1)-freq(i)==0)
                    time_pre_2 = [time_pre_2 time(i)]; % 各跳时刻的起点
                end
            end
            
            time_pre(1) = [];     % 最开始多记录一个终点
            time_pre_2(end) = []; % 最末尾多记录一个起点
            hop_time = (time_pre-time_pre_2)*1e9; % 跳频驻留时间计算

            Ctf_export_struct.hop_time = hop_time;
            waitbar(.8,fbar,'完成跳频周期统计');
            
            %% 曲线拟合
            time = TFR3_T*1/fs;
            freq = TFR1_F(ff_TFR1_timeFreq_max)*fs;
            
            Ctf_export_struct.freq = freq;

            figure;
            plot(freq);
            xlabel('点数');
            ylabel('频率f/Hz');
            title('时频脊线随点数的变化情况');

            uiconfirm(app.UIFigure,'注意观察波形时频脊线，记录待分析的信号片段','Process Prompt','Icon','warning');
            uiwait(app.UIFigure);  % 利用pause和resume来给用户足够的时间选取待分析波形

            
            prompt = {'待拟合信号的起始点:', '待拟合信号的终止点:'};
            dlgtitle = '曲线拟合参数输入';
            dims = [1 40; 1 40];
            definput = {'0', '0.001'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            timeFreq_start_point = str2num( answer{1,1} );
            timeFreq_end_point = str2num( answer{2,1} );
            
%             timeFreq_start_point = input("待拟合信号的起始点:");
%             timeFreq_end_point = input("待拟合信号的终止点:");
            
            time = time(timeFreq_start_point:timeFreq_end_point);
            freq = freq(timeFreq_start_point:timeFreq_end_point); %302 693 1706
            
            p = polyfit(time,freq,1);
            
            x1 = linspace(time(1),time(end));
            y1 = polyval(p,x1);

            Ctf_export_struct.time_2 = time;
            Ctf_export_struct.freq_2 = freq;
            Ctf_export_struct.x1 = x1;
            Ctf_export_struct.y1 = y1;
            waitbar(.9,fbar,'完成扫频速率拟合');
            
            %% 存p
            end_or_not_str = questdlg('是否终止拟合，并保存拟合系数?','保存线性拟合系数','yes','no','no');
            if end_or_not_str == "yes"
                save('fitting_coefficient.mat','p');
            end
            % p_save(1,:) = p;
            
            %% 对TFR4矩阵进行分析,以每一时刻点的3dB频率阈值为基准求带宽随时间的变化，以及平均带宽等参数
            figure;
            plot(t_via_denoise, abs(x_denoise));
            xlabel('时间t/s');
            ylabel('幅值');
            title('去噪后的时域信号')

            uiconfirm(app.UIFigure,'注意观察去噪后的时域信号，记录待分析的信号片段时间点','Process Prompt','Icon','warning');
            uiwait(app.UIFigure); 
            
            prompt = {'待分析带宽信号的时间起点:', '待分析带宽信号的时间终点:'};
            dlgtitle = '带宽计算参数输入';
            dims = [1 40; 1 40];
            definput = {'0', '0.000001'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            BW_time_start_point = str2double( answer{1,1} );
            BW_time_end_point = str2double( answer{2,1} );

%             BW_time_start_point = input("待分析带宽信号的时间起点:");  % 周期单元4为0.00011204，和0.00014012
%             BW_time_end_point = input("待分析带宽信号的时间终点:");
            
            BW_sig_start_point = max(1, round(BW_time_start_point * fs));
            BW_sig_end_point = round(BW_time_end_point * fs);
            
            TFR5 = TFR4(:, BW_sig_start_point:BW_sig_end_point); % 时频矩阵中行数为频率，列数为时刻
            TFR5_abs = abs(TFR5);
            
            TFR5_abs(all(TFR5_abs==0, 2),:) = []; %去除矩阵中的全0行
            
            TFR5_abs = TFR5_abs + eps;
            TFR5_abs_log = 10.*log10(TFR5_abs);  % 将时频矩阵转化为对数形式
            [row, col] = size(TFR5_abs_log);
            upper_LowerUpper_limit_matrix = zeros(2, col);
            
            for j = 1:col
                [TFR5_abs_log_max, TFR5_abs_log_max_index] = max(TFR5_abs_log(:,j));
                threshold = TFR5_abs_log_max-3; % 设定3dB带宽阈值点
                for i = TFR5_abs_log_max_index:-1:1 % 在最大值点向前搜索，记录3dB截止频率下限
                    if  TFR5_abs_log(i, j)-threshold <1e-10
                        TFR5_abs_log_lower_limit = i;
                        break;
                    end
                end
                
                for i = TFR5_abs_log_max_index:row % 在最大值点向后搜索，记录3dB截止频率上限
                    if  TFR5_abs_log(i, j)-threshold <1e-10
                        TFR5_abs_log_upper_limit = i;
                        break;
                    end
                end
                upper_LowerUpper_limit_matrix(1, j) = TFR5_abs_log_lower_limit; % 将每一时刻对应的3dB上下限截止频点都找到，求瞬时带宽
                upper_LowerUpper_limit_matrix(2, j) = TFR5_abs_log_upper_limit;
                
            end
            
            conversion_coefficient = mean( diff( TFR1_F(1:N_sig_hilbert_length/2) )) *fs ;
            bandwidth_instantaneous = (upper_LowerUpper_limit_matrix(2,:) - upper_LowerUpper_limit_matrix(1,:)) * conversion_coefficient;
            bandwidth_mean = mean(bandwidth_instantaneous);
            t_BW_measure = (1:col).*1/fs;

            Ctf_export_struct.bandwidth_mean = bandwidth_mean;
            Ctf_export_struct.t_BW_measure = t_BW_measure;
            Ctf_export_struct.bandwidth_instantaneous = bandwidth_instantaneous;
            
            waitbar(1,fbar,'完成带宽计算,处理结束');
            close(fbar);

            assignin("base", 'Ctf_export_struct', Ctf_export_struct);
            uiconfirm(app.UIFigure,'reading accomplishment','Process Prompt','Icon','success');

        end

        % Button pushed function: resume_speed_fitting
        function resume_speed_fittingButtonPushed(app, event)
            uiresume(app.UIFigure);
        end

        % Button pushed function: dataReadEffectiveSection_module_Start
        function dataReadEffectiveSection_module_StartButtonPushed(app, event)
            fs = app.sampleRate.Value;               % 待读取文件中存储信号的采样频率
            Sampling_bit = app.sampleBit.Value;     % 待读取文件中存储信号的采样位数
            filename = app.filename.Value; % 文件名，后续还要做出自动分析每一文件的功能

            fid = fopen(filename, 'r');
            [data] = fread(fid,inf,Sampling_bit);
            fclose(fid);
            I = data(1: 2: end); % IQ交替采样得到data,要剥离得到I和Q
            Q = data(2: 2: end);
            z = I+1j*Q; % 生成复信号,计算角频率(相位差),就能解调了

            figure;
            subplot(211);
            plot(I);
            xlabel("采样点数")
            ylabel("amplitude/V")
            title("I路时域信号")

            subplot(212);
            plot(Q);
            xlabel("采样点数")
            ylabel("amplitude/V")
            title("Q路时域信号")

            uiconfirm(app.UIFigure,'注意观察IQ路的时域信号，记录待分析的信号片段起始点','Process Prompt','Icon','warning');
            uiwait(app.UIFigure); 

            prompt = {'待分析调制信号的时间起点:', '待分析调制信号的时间终点:'};
            dlgtitle = '调制方式识别参数输入';
            dims = [1 40; 1 40];
            definput = {'4208', '10000'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            start_point = str2double( answer{1,1} );
            end_point = str2double( answer{2,1} );

%             start_point = input("待分析信号起始点:");  % 4208
%             end_point   = input("待分析信号终止点:");  % 10000
            
            % 分段观察信号
            if end_point > length(I)
                end_point = length(I);
                uiconfirm(app.UIFigure,'待分析的终止点过大','Process Prompt','Icon','warning');
            end
        
            I_for_observe = I(start_point:end_point);  %已分析的第一个片段，属于采集信号的第二块
            Q_for_observe = Q(start_point:end_point);
            
            try
                app_IQDisplay1 = Modulation_Display();
            catch ME
                disp(ME.message);
                uiconfirm(app.UIFigure,'无法打开','Process Prompt','Icon','warning');
            end

        
            plot(app_IQDisplay1.I_branch_time_axis, I);
            xlabel(app_IQDisplay1.I_branch_time_axis, "采样点数")
            ylabel(app_IQDisplay1.I_branch_time_axis, "amplitude/V")
            title(app_IQDisplay1.I_branch_time_axis, "I路时域信号")
        
            plot(app_IQDisplay1.Q_branch_time_axis, Q);
            xlabel(app_IQDisplay1.Q_branch_time_axis, "采样点数")
            ylabel(app_IQDisplay1.Q_branch_time_axis, "amplitude/V")
            title(app_IQDisplay1.Q_branch_time_axis, "Q路时域信号")
            
            % 针对I路做时频分析
            N = length(I_for_observe);
            tt = 0:1/fs:1/fs*(N-1);

            try
                app_IQDisplay2 = Modulation_Display();
            catch ME
                disp(ME.message);
                uiconfirm(app.UIFigure,'无法打开','Process Prompt','Icon','warning');
            end
        
            plot(app_IQDisplay2.I_branch_time_axis, tt ,I_for_observe);
            xlabel(app_IQDisplay2.I_branch_time_axis, "t/s")
            ylabel(app_IQDisplay2.I_branch_time_axis, "amplitude/V")
            title(app_IQDisplay2.I_branch_time_axis, "截取后I路时域信号")
        
            sig_fft = abs(fftshift(fft(I_for_observe)));
            freq = -fs/2:fs/N:fs/2-1;

            plot(app_IQDisplay2.Q_branch_time_axis, freq,sig_fft)
            xlabel(app_IQDisplay2.Q_branch_time_axis, "f/Hz")
            ylabel(app_IQDisplay2.Q_branch_time_axis, "amplitude/V")
            title(app_IQDisplay2.Q_branch_time_axis, "截取后I路频域信号")

            app.dataReadEffectiveSection_module_I_branch_output = I_for_observe / max(I_for_observe);

            String_Display = strcat("截取后信号的长度为：", num2str(length(I_for_observe)) );
            app.cellArrayText_recognition_result = horzcat(app.cellArrayText_recognition_result, String_Display);
            app.recognition_result.Value = app.cellArrayText_recognition_result;

            uiconfirm(app.UIFigure,'initial time sclice accomplishment','Process Prompt','Icon','success');
        end

        % Button pushed function: dataReadEffectiveSection_module_Resume
        function dataReadEffectiveSection_module_ResumeButtonPushed(app, event)
            uiresume(app.UIFigure);
        end

        % Button pushed function: Rs_and_Fc_estimate_module_initialize
        function Rs_and_Fc_estimate_module_initializeButtonPushed(app, event)
            app.fc_list = [];
            app.Rs_list = [];
            uiconfirm(app.UIFigure,'initialization accomplishment','Process Prompt','Icon','success');
        end

        % Button pushed function: Rs_and_Fc_estimate_module_start
        function Rs_and_Fc_estimate_module_startButtonPushed(app, event)
            CSF_Method = "FreqDomainCalculateDirectly";  % 循环谱分析方法选择
            MapN = app.curricular_spectrum_length.Value;    %分析窗长
            nfft = app.auto_coreletion_FFT_point.Value;    %自相关估计
            MapN_2 = app.auto_correlation_win_length.Value;  %窗长
            fs = app.sampleRate.Value;
        
            s = app.dataReadEffectiveSection_module_I_branch_output;  %  待分析信号
            [f_cyclic_spectrum,alpha_cyclic_spectrum,CS1, CS_alpha_0, CS_Carrier] = Cyclic_Spectrum_Function(CSF_Method, fs, s, MapN); %估载频非常准，码元速率仍是老问题
        %     [peaks, locations] = findpeaks(CS_alpha_0);
            
            try
                app_ParaEstiDisplay = Modulation_ParaEstimate_Display();
            catch ME
                disp(ME.message);
                uiconfirm(app.UIFigure,'无法打开','Process Prompt','Icon','warning');
            end
        
            mesh(app_ParaEstiDisplay.cyclic_spectrum_3_dmension_axis, f_cyclic_spectrum,alpha_cyclic_spectrum,abs(CS1))
            xlabel(app_ParaEstiDisplay.cyclic_spectrum_3_dmension_axis, 'f/Hz')
            ylabel(app_ParaEstiDisplay.cyclic_spectrum_3_dmension_axis, '\alpha/Hz')
            title(app_ParaEstiDisplay.cyclic_spectrum_3_dmension_axis, "循环谱三维结果图");
        
%             alpha_line = -fs/2:fs/MapN:0;
            alpha_line = 0:fs/MapN:fs/2;
            z_fc_estimate = fliplr(CS1(:,end-1));
            plot(app_ParaEstiDisplay.cyclic_spectrum_CF_estimation_axis, alpha_line, abs(z_fc_estimate));
            xlabel(app_ParaEstiDisplay.cyclic_spectrum_CF_estimation_axis, '\alpha Hz')
            grid(app_ParaEstiDisplay.cyclic_spectrum_CF_estimation_axis, "on")
            title(app_ParaEstiDisplay.cyclic_spectrum_CF_estimation_axis, "循环谱载频估计\alpha平面图");
        
            [~, b] = max(abs(z_fc_estimate));
            z_Rs_estimate = fliplr(CS1(:, round(MapN-1-(MapN/2-b)/2*2)));
            plot(app_ParaEstiDisplay.cyclic_spectrum_CR_estimation_axis, alpha_line,abs(z_Rs_estimate));
            xlabel(app_ParaEstiDisplay.cyclic_spectrum_CR_estimation_axis, '\alpha Hz')
            title(app_ParaEstiDisplay.cyclic_spectrum_CR_estimation_axis, "循环谱码速估计\alpha平面图");
            grid(app_ParaEstiDisplay.cyclic_spectrum_CR_estimation_axis, "on")

            [~,cf_index] = max(fliplr(CS_alpha_0'));
            carrier = cf_index * fs/MapN;
            str_display = sprintf('循环谱估计所得载频为：%e Hz\n', carrier/2);
            app.cellArrayText_parameter_estimation = horzcat(app.cellArrayText_parameter_estimation, str_display);
            app_ParaEstiDisplay.perameter_estimation_display.Value = app.cellArrayText_parameter_estimation;
        
            CS_Carrier(end) = 0;
            [~,cr_index] = max(fliplr(CS_Carrier'));
            code_rate = (cr_index-1) * fs/MapN;
            str_display = sprintf('循环谱估计所得码速为：%e bit/s\n', code_rate/2);
            app.cellArrayText_parameter_estimation = horzcat(app.cellArrayText_parameter_estimation, str_display);
            app_ParaEstiDisplay.perameter_estimation_display.Value = app.cellArrayText_parameter_estimation;
        
            % 快速估计载频和码速
            overlap = nfft/4;
            [Pxy, f_initial, t_cpsd] = cpsd(s, s, MapN_2, overlap, nfft, fs);
            [~,cf_index_2] = max(Pxy);
            f_max = f_initial(cf_index_2);
            str_display = sprintf('自相关估计所得载频为：%e Hz\n', f_max);
            app.cellArrayText_parameter_estimation = horzcat(app.cellArrayText_parameter_estimation, str_display);
            app_ParaEstiDisplay.perameter_estimation_display.Value = app.cellArrayText_parameter_estimation;
        
            plot(app_ParaEstiDisplay.auto_correlation_result_axis, f_initial, abs(Pxy));
            title(app_ParaEstiDisplay.auto_correlation_result_axis, 'Cyclic Spectral Density');
            xlabel(app_ParaEstiDisplay.auto_correlation_result_axis, 'Frequency (Hz)');
            ylabel(app_ParaEstiDisplay.auto_correlation_result_axis, "power/W")
            title(app_ParaEstiDisplay.auto_correlation_result_axis, "自相关功率估计结果")
        
            app.fc_list = [app.fc_list carrier/2 f_max];
            app.Rs_list = [app.Rs_list code_rate/2];
            
            variable_waiting_save = app.dataReadEffectiveSection_module_I_branch_output;
            save("section1",'variable_waiting_save');

            uiconfirm(app.UIFigure,'signal estimate accomplishment','Process Prompt','Icon','success');
        end

        % Button pushed function: 
        % dataFeature_design_and_dataset_make_module_start
        function dataFeature_design_and_dataset_make_module_startButtonPushed(app, event)
            set(0,'defaultfigurecolor','w')

            CoreNum = 8;                  %设定机器CPU核心数量,最大为80
            feature_samples_num = 5;       %特征库中每一类调制的样本数，标准是10000
            ScaleNumber = 2;
            fs = app.sampleRate.Value;
        
            modulation_test = ["BASK" "MASK" "QAM" "MQAM" "BPSK" "MPSK" "QPSK" "OQPSK" "BFSK" "MFSK" "MSK" "OFDM"]; %["BASK" "MASK" "QAM" "MQAM" "BPSK" "MPSK" "QPSK" "OQPSK" "BFSK" "MFSK" "MSK" "OFDM"]
            color_list = ["#0072BD" "blue" "#D95319" "#EDB120" "#7E2F8E" "red" "green" "cyan" "magenta" "yellow" "black" "#77AC30"];
            mark_list = ["<" "o" "+" "*" "." "x" ">" "|" "square" "diamond" "^" "v"];

            try
                app_FeatureDisplay = Modulation_Display();
            catch ME
                disp(ME.message);
                uiconfirm(app.UIFigure,'无法打开','Process Prompt','Icon','warning');
            end
            
            class_num = length(modulation_test);
            M = 2^ScaleNumber;
            if isempty(gcp('nocreate'))
                parpool(CoreNum);
            end
        
            Gama_m_middle = zeros(length(modulation_test),feature_samples_num);
            Sigma_aa_middle = zeros(length(modulation_test),feature_samples_num);
            Sigma_dp_middle = zeros(length(modulation_test),feature_samples_num);
            Sigma_ap_middle = zeros(length(modulation_test),feature_samples_num);
            Sigma_af_middle = zeros(length(modulation_test),feature_samples_num);
            c42_middle = zeros(length(modulation_test),feature_samples_num);
            c80_middle = zeros(length(modulation_test),feature_samples_num);
            c80Ratioc42_middle = zeros(length(modulation_test),feature_samples_num);
        
            Gama_m_middle_2 = zeros(length(modulation_test),feature_samples_num*length(app.Rs_list));
            Sigma_aa_middle_2 = zeros(length(modulation_test),feature_samples_num*length(app.Rs_list));
            Sigma_dp_middle_2 = zeros(length(modulation_test),feature_samples_num*length(app.Rs_list));
            Sigma_ap_middle_2 = zeros(length(modulation_test),feature_samples_num*length(app.Rs_list));
            Sigma_af_middle_2 = zeros(length(modulation_test),feature_samples_num*length(app.Rs_list));
            c42_middle_2 = zeros(length(modulation_test),feature_samples_num*length(app.Rs_list));
            c80Ratioc42_middle_2 = zeros(length(modulation_test),feature_samples_num*length(app.Rs_list));
        
            Gama_m = zeros(length(modulation_test),feature_samples_num*length(app.Rs_list)*length(app.fc_list));
            Sigma_aa = zeros(length(modulation_test),feature_samples_num*length(app.Rs_list)*length(app.fc_list));
            Sigma_dp = zeros(length(modulation_test),feature_samples_num*length(app.Rs_list)*length(app.fc_list));
            Sigma_ap = zeros(length(modulation_test),feature_samples_num*length(app.Rs_list)*length(app.fc_list));
            Sigma_af = zeros(length(modulation_test),feature_samples_num*length(app.Rs_list)*length(app.fc_list));
            c42 = zeros(length(modulation_test),feature_samples_num*length(app.Rs_list)*length(app.fc_list));
            c80Ratioc42 = zeros(length(modulation_test),feature_samples_num*length(app.Rs_list)*length(app.fc_list));
        
            
            Gama_m_feature = zeros(1,feature_samples_num*length(app.Rs_list)*length(app.fc_list), class_num);
            Sigma_aa_feature = zeros(1,feature_samples_num*length(app.Rs_list)*length(app.fc_list), class_num);
            Sigma_dp_feature = zeros(1,feature_samples_num*length(app.Rs_list)*length(app.fc_list), class_num);
            Sigma_ap_feature = zeros(1,feature_samples_num*length(app.Rs_list)*length(app.fc_list), class_num);
            Sigma_af_feature = zeros(1,feature_samples_num*length(app.Rs_list)*length(app.fc_list), class_num);
            c42_feature = zeros(1,feature_samples_num*length(app.Rs_list)*length(app.fc_list), class_num);
            c80Ratioc42_feature = zeros(1,feature_samples_num*length(app.Rs_list)*length(app.fc_list), class_num);
        
            s = cell(1,feature_samples_num);
            s_analytic = cell(1,feature_samples_num);
            m80 = cell(1,feature_samples_num);
            m60 = cell(1,feature_samples_num);
            m40 = cell(1,feature_samples_num);

            fbar_2 = waitbar(0,'请等待..');
            for j = 1:length(modulation_test)
                modulatedType = modulation_test(j);
                for k1 = 1:length(app.fc_list)
                    fc_test = app.fc_list(k1);
                    disp(strcat("fc为",num2str(fc_test)));
                    for k2 = 1:length(app.Rs_list)
                        Rs_test = app.Rs_list(k2);
                        disp(strcat("Rs为",num2str(Rs_test)));
                        if Rs_test >= fc_test
                            break;
                        end
        
                        t_end = 1/Rs_test*1000;
                        % tic;
                        parfor i = 1:feature_samples_num
        %                 for i = 1:num_samples
                            [s{i}] = Modulated_Sig_Generate(modulatedType, fs, Rs_test, fc_test, M, t_end);
                            [Gama_m_middle(j,i),Sigma_aa_middle(j,i),Sigma_dp_middle(j,i),Sigma_ap_middle(j,i),Sigma_af_middle(j,i)] = Feature(s{i},Rs_test,fc_test,fs);
                            s_analytic{i} = hilbert(s{i});
                            c42_middle(j,i) = abs(mean(s_analytic{i}.*s_analytic{i}.*conj(s_analytic{i}).*conj(s_analytic{i}))-mean(s_analytic{i}.*s_analytic{i}).^2-2*mean(s_analytic{i}.*conj(s_analytic{i})).^2);
                            m80{i} = mean(s_analytic{i}.*s_analytic{i}.*s_analytic{i}.*s_analytic{i}.*s_analytic{i}.*s_analytic{i}.*s_analytic{i}.*s_analytic{i});
                            m60{i} = mean(s_analytic{i}.*s_analytic{i}.*s_analytic{i}.*s_analytic{i}.*s_analytic{i}.*s_analytic{i});
                            m40{i} = mean(s_analytic{i}.*s_analytic{i}.*s_analytic{i}.*s_analytic{i});
                            c80_middle(j,i) = abs(m80{i} - 28*(mean(s_analytic{i}.*s_analytic{i})).*m60{i} - 35*m40{i}.^2 + 420*(mean(s_analytic{i}.*s_analytic{i})).^2.*m40{i} - 630*(mean(s_analytic{i}.*s_analytic{i})).^4);
                            c80Ratioc42_middle(j,i) = c80_middle(j,i) / c42_middle(j,i);
                        end
                        % toc;
        
                        Gama_m_middle_2(j,(k2-1)*feature_samples_num+1:k2*feature_samples_num) = Gama_m_middle(j,:);  %take picture use
                        Sigma_aa_middle_2(j,(k2-1)*feature_samples_num+1:k2*feature_samples_num) = Sigma_aa_middle(j,:);
                        Sigma_dp_middle_2(j,(k2-1)*feature_samples_num+1:k2*feature_samples_num) = Sigma_dp_middle(j,:);
                        Sigma_ap_middle_2(j,(k2-1)*feature_samples_num+1:k2*feature_samples_num) = Sigma_ap_middle(j,:);
                        Sigma_af_middle_2(j,(k2-1)*feature_samples_num+1:k2*feature_samples_num) = Sigma_af_middle(j,:);
                        c42_middle_2(j,(k2-1)*feature_samples_num+1:k2*feature_samples_num) = c42_middle(j,:);
                        c80Ratioc42_middle_2(j,(k2-1)*feature_samples_num+1:k2*feature_samples_num) = c80Ratioc42_middle(j,:);
                    end
                    Gama_m(j,(k1-1)*feature_samples_num*length(app.Rs_list)+1:k1*feature_samples_num*length(app.Rs_list)) = Gama_m_middle_2(j,:);
                    Sigma_aa(j,(k1-1)*feature_samples_num*length(app.Rs_list)+1:k1*feature_samples_num*length(app.Rs_list)) = Sigma_aa_middle_2(j,:);
                    Sigma_dp(j,(k1-1)*feature_samples_num*length(app.Rs_list)+1:k1*feature_samples_num*length(app.Rs_list)) = Sigma_dp_middle_2(j,:);
                    Sigma_ap(j,(k1-1)*feature_samples_num*length(app.Rs_list)+1:k1*feature_samples_num*length(app.Rs_list)) = Sigma_ap_middle_2(j,:);
                    Sigma_af(j,(k1-1)*feature_samples_num*length(app.Rs_list)+1:k1*feature_samples_num*length(app.Rs_list)) = Sigma_af_middle_2(j,:);
                    c42(j,(k1-1)*feature_samples_num*length(app.Rs_list)+1:k1*feature_samples_num*length(app.Rs_list)) = c42_middle_2(j,:);
                    c80Ratioc42(j,(k1-1)*feature_samples_num*length(app.Rs_list)+1:k1*feature_samples_num*length(app.Rs_list)) = c80Ratioc42_middle_2(j,:);
        
                end
%                 disp( strcat(modulatedType, "调制方式测试完毕，(特征一)") );
                process_interval = 0.95/length(modulation_test);
                waitbar(process_interval*j, fbar_2, strcat(modulatedType, "调制方式测试完毕"));
        
                curve = scatter3(app_FeatureDisplay.I_branch_time_axis, Gama_m(j,:), Sigma_aa(j,:), Sigma_dp(j,:), 'Color', color_list(j),Marker = mark_list(j));
                legend(app_FeatureDisplay.I_branch_time_axis, curve, modulatedType,'Location','Best');
                legend(app_FeatureDisplay.I_branch_time_axis, 'off'); 
                legend(app_FeatureDisplay.I_branch_time_axis, 'show');
                hold(app_FeatureDisplay.I_branch_time_axis, 'on');
                
                curve = scatter3(app_FeatureDisplay.Q_branch_time_axis, Sigma_dp(j,:), Sigma_ap(j,:), Sigma_af(j,:), 'Color', color_list(j),Marker = mark_list(j));
                legend(app_FeatureDisplay.Q_branch_time_axis, curve, modulatedType,'Location','Best');
                legend(app_FeatureDisplay.Q_branch_time_axis, 'off'); 
                legend(app_FeatureDisplay.Q_branch_time_axis, 'show');
                hold(app_FeatureDisplay.Q_branch_time_axis, 'on');
        
                Gama_m_feature(:,:,j) = Gama_m(j,:);
                Sigma_aa_feature(:,:,j) = Sigma_aa(j,:);
                Sigma_dp_feature(:,:,j) = Sigma_dp(j,:);
                Sigma_ap_feature(:,:,j) = Sigma_ap(j,:);
                Sigma_af_feature(:,:,j) = Sigma_af(j,:);
                c42_feature(:,:,j) = c42(j,:);
                c80Ratioc42_feature(:,:,j) = c80Ratioc42(j,:);
            end
            xlabel(app_FeatureDisplay.I_branch_time_axis, "瞬时幅度绝对值变化");
            ylabel(app_FeatureDisplay.I_branch_time_axis, "瞬时幅度方差变化");
            zlabel(app_FeatureDisplay.I_branch_time_axis, "瞬时相位绝对值方差变化");

            xlabel(app_FeatureDisplay.Q_branch_time_axis, "瞬时相位绝对值方差变化");
            ylabel(app_FeatureDisplay.Q_branch_time_axis, "瞬时相位方差变化");
            zlabel(app_FeatureDisplay.Q_branch_time_axis, "瞬时频率方差变化");
        
            c42_feature_2         = c42_feature        ;
            c80Ratioc42_feature_2 = c80Ratioc42_feature;
            Gama_m_feature_2      = Gama_m_feature     ;
            Sigma_aa_feature_2   = Sigma_aa_feature   ;
            Sigma_af_feature_2   = Sigma_af_feature   ;
            Sigma_ap_feature_2   = Sigma_ap_feature   ;
            Sigma_dp_feature_2   = Sigma_dp_feature   ;
            
            c42_feature_2(:,:,12) = 0;
            c80Ratioc42_feature_2(:,:,12) = 0;
            Gama_m_feature_2(:,:,12) = 0;
            Sigma_aa_feature_2(:,:,12) = 0;
            Sigma_af_feature_2(:,:,12) = 0;
            Sigma_ap_feature_2(:,:,12) = 0;
            Sigma_dp_feature_2(:,:,12) = 0;
            
            save("Feature","Gama_m_feature","Sigma_aa_feature","Sigma_dp_feature","Sigma_ap_feature","Sigma_af_feature","c42_feature","c80Ratioc42_feature");
            save("Feature_2","Gama_m_feature_2","Sigma_aa_feature_2","Sigma_dp_feature_2","Sigma_ap_feature_2","Sigma_af_feature_2","c42_feature_2","c80Ratioc42_feature_2");
            
            waitbar(1,fbar_2,'完成特征数据采集,处理结束');
            close(fbar_2);
            uiconfirm(app.UIFigure,'feature set collection accomplishment','Process Prompt','Icon','success');
        end

        % Button pushed function: modulation_KNN_comparasion_start
        function modulation_KNN_comparasion_startButtonPushed(app, event)
            options = uiconfirm(app.UIFigure,'是否包含OFDM体制检测','Process Prompt','Options',{'是','否'},'DefaultOption',2);

            if strcmp(options, '是')
                load Feature.mat       % 此处需要替换为包含OFDM的特征数据集
            elseif strcmp(options, '否')
                load Feature_2.mat     % 此处需要替换为不包含OFDM的特征数据集
            else
                uiconfirm(app.UIFigure,'选择错误','Process Prompt','Icon','warning');
            end
   
            feature_num = app.modulation_sample_num_comparasion.Value;   % 特征数据的种类数
            K_list = app.KNN_cluster_num.Value;                          % KNN聚类分析法的聚类数
            Length_Waiting_Analysis = app.signal_waiting_analysis_length.Value;      % 待分析信号的长度
            fs = app.sampleRate.Value;
%             SNR = 20;           % 信噪比
            
            if exist('Gama_m_feature_2','var') ~= 0
                Gama_m_feature = Gama_m_feature_2;
                Sigma_aa_feature = Sigma_aa_feature_2;
                Sigma_af_feature = Sigma_af_feature_2;
                Sigma_ap_feature = Sigma_ap_feature_2;
                Sigma_dp_feature = Sigma_dp_feature_2;
                c42_feature = c42_feature_2;
                c80Ratioc42_feature = c80Ratioc42_feature_2;
            end

            sample_dims = size(c42_feature);
            sample_num = sample_dims(2);
            class_num = sample_dims(3);
        
            trainData_subset = zeros(feature_num, sample_num, class_num);
            trainData_subset(1,:,:) = Gama_m_feature/max(Gama_m_feature,[],"all");      %对每一种调制方式都进行归一化
            trainData_subset(2,:,:) = Sigma_aa_feature/max(Sigma_aa_feature,[],"all");
            trainData_subset(3,:,:) = Sigma_af_feature/max(Sigma_af_feature,[],"all");
            trainData_subset(4,:,:) = Sigma_ap_feature/max(Sigma_ap_feature,[],"all");
            trainData_subset(5,:,:) = Sigma_dp_feature/max(Sigma_dp_feature,[],"all");
            trainData_subset(6,:,:) = c42_feature/max(c42_feature,[],"all");
            trainData_subset(7,:,:) = c80Ratioc42_feature/max(c80Ratioc42_feature,[],"all");
        
            % trainData_subset(1,:,:) = (Gama_m_feature - mean(Gama_m_feature,"all"))/std(Gama_m_feature,1,"all");   % 数据归一化的方式不一样
            % trainData_subset(2,:,:) = (Sigma_aa_feature - mean(Sigma_aa_feature,"all"))/std(Sigma_aa_feature,1,"all");
            % trainData_subset(3,:,:) = (Sigma_af_feature - mean(Sigma_af_feature,"all"))/std(Sigma_af_feature,1,"all");
            % trainData_subset(4,:,:) = (Sigma_ap_feature - mean(Sigma_ap_feature,"all"))/std(Sigma_ap_feature,1,"all");
            % trainData_subset(5,:,:) = (Sigma_dp_feature - mean(Sigma_dp_feature,"all"))/std(Sigma_dp_feature,1,"all");
        
            trainData = permute(trainData_subset, [2 1 3]);
            trainDim = size(trainData); %获取训练集的维数
        
            % 测试数据瞬时特征提取    
            s = app.dataReadEffectiveSection_module_I_branch_output;
            s = s(1:Length_Waiting_Analysis)';
            s= s/max(s);  % 信号归一化
        
            modulation_test_list = ["BASK" "MASK" "QAM" "MQAM" "BPSK" "MPSK" "QPSK" "OQPSK" "BFSK" "MFSK" "MSK" "OFDM"];   %["BASK" "MASK" "QAM" "MQAM" "BPSK" "MPSK" "QPSK" "OQPSK" "BFSK" "MFSK" "MSK" ]
%             correct_rate_array = zeros(length(modulation_test_list), length(Rs_list)*length(fc_list));
        
            for K_index = 1:length(K_list)
                K = K_list(K_index);
                disp(strcat("K为",num2str(K)));
                for fc_index = 1:length(app.fc_list)
                    fc = app.fc_list(fc_index);
                    % if fs < fc*2
                    %     disp('当前采样频率过低');
                    % end
                    disp(strcat("fc为",num2str(fc)));
                    for Rs_index = 1:length(app.Rs_list)
                        Rs = app.Rs_list(Rs_index);
                        if Rs >= fc
                            break;
                        end
                        disp(strcat("Rs为",num2str(Rs)));
        
                        [Gama_m,Sigma_aa,Sigma_dp,Sigma_ap,Sigma_af] = Feature(s,Rs,fc,fs);
                        s_analytic = hilbert(s);
                        c42 = abs(mean(s_analytic.*s_analytic.*conj(s_analytic).*conj(s_analytic))-mean(s_analytic.*s_analytic).^2-2*mean(s_analytic.*conj(s_analytic)).^2);
        
                        m20 = mean(s_analytic.*s_analytic);
                        m80 = mean(s_analytic.*s_analytic.*s_analytic.*s_analytic.*s_analytic.*s_analytic.*s_analytic.*s_analytic);
                        m60 = mean(s_analytic.*s_analytic.*s_analytic.*s_analytic.*s_analytic.*s_analytic);
                        m40 = mean(s_analytic.*s_analytic.*s_analytic.*s_analytic);
                        c80 = abs(m80 - 28*m20.*m60 - 35*m40.^2 + 420*m20.^2.*m40 - 630*m20.^4);
                        c80Ratioc42 = c80/c42;
        
                        testData = [Gama_m/max(Gama_m_feature,[],"all") Sigma_aa/max(Sigma_aa_feature,[],"all") ...
                            Sigma_af/max(Sigma_af_feature,[],"all") Sigma_ap/max(Sigma_ap_feature,[],"all") Sigma_dp/max(Sigma_dp_feature,[],"all") ...
                            c42/max(c42_feature,[],"all") c80Ratioc42/max(c80Ratioc42_feature,[],"all")];  %设置1个测试点
        
                        %                 testData = [(Gama_m - mean(Gama_m_feature,"all"))/std(Gama_m_feature,1,"all") (Sigma_aa - mean(Sigma_aa_feature,"all"))/std(Sigma_aa_feature,1,"all") ...
                        %                     (Sigma_af - mean(Sigma_af_feature,"all"))/std(Sigma_af_feature,1,"all") ...
                        %                     (Sigma_ap- mean(Sigma_ap_feature,"all"))/std(Sigma_ap_feature,1,"all") ...
                        %                     (Sigma_dp- mean(Sigma_dp_feature,"all"))/std(Sigma_dp_feature,1,"all")];
        
                        %%分别计算测试集中各个点与每个训练集中的点的欧氏距离
                        %把测试点扩展成矩阵
                        testData_rep = repmat(testData,sample_num,1);
                        %设置三个二维矩阵存放测试集与测试点的扩展矩阵的差值平方
                        for diff_index = 1:length(modulation_test_list)
                            eval([strcat('diff',num2str(diff_index),'= (trainData(:,:,',num2str(diff_index),')-testData_rep).^2'),';'] );
                        end
        
                        %设置三个一维数组存放欧式距离
                        for distance_index = 1:length(modulation_test_list)
                            eval([strcat('distance', num2str(distance_index), '=(diff', num2str(distance_index), '(:,1)+diff', num2str(distance_index),...
                                '(:,2)+diff',num2str(distance_index), '(:,3)+diff', num2str(distance_index), '(:,4)+diff', num2str(distance_index), ...
                                '(:,5)+diff', num2str(distance_index), '(:,6)+diff', num2str(distance_index),'(:,7) ).^0.5'), ';']);
                        end
        
                        %将三个一维数组合成一个二维矩阵
                        temp=[distance1 distance2 distance3 distance4 distance5 distance6 distance7 distance8 distance9 distance10 distance11 distance12];
                        %将这个二维矩阵转换为一维数组
                        distance=reshape(temp,1,trainDim(1)*trainDim(3));
                        %对距离进行排序
                        distance_sort=sort(distance);
                        %用一个循环寻找最小的K个距离里面那个类里出现的频率最高，并返回该类
                        for num_index = 1:length(modulation_test_list)
                            eval([strcat('num',num2str(num_index),'=0'), ';']); %num为类出现的次数
                        end
        
%                         sum=0;%sum1,sum2,sum3的和
                        for i=1:K
                            for j=1:trainDim(1)
                                if distance1(j)==distance_sort(i)
                                    num1=num1+1;
                                end
                                if distance2(j)==distance_sort(i)
                                    num2=num2+1;
                                end
                                if distance3(j)==distance_sort(i)
                                    num3=num3+1;
                                end
                                if distance4(j)==distance_sort(i)
                                    num4=num4+1;
                                end
                                if distance5(j)==distance_sort(i)
                                    num5=num5+1;
                                end
                                if distance6(j)==distance_sort(i)
                                    num6=num6+1;
                                end
                                if distance7(j)==distance_sort(i)
                                    num7=num7+1;
                                end
                                if distance8(j)==distance_sort(i)
                                    num8=num8+1;
                                end
                                if distance9(j)==distance_sort(i)
                                    num9=num9+1;
                                end
                                if distance10(j)==distance_sort(i)
                                    num10=num10+1;
                                end
                                if distance11(j)==distance_sort(i)
                                    num11=num11+1;
                                end
                                if distance12(j)==distance_sort(i)
                                    num12=num12+1;
                                end
                            end
                            sum=num1+num2+num3+num4+num5+num6+num7+num8+num9+num10+num11+num12;
                            if sum>=K
                                break;
                            end
                        end
        
                        class=[num1 num2 num3 num4 num5 num6 num7 num8 num9 num10 num11 num12];
        
                        classindex = find(class(1,:)==max(class));
                        classnamelist = ["BASK" "MASK" "QAM" "MQAM" "BPSK" "MPSK" "QPSK" "OQPSK" "BFSK" "MFSK" "MSK" "OFDM"];
                        classname = classnamelist(classindex);
        
                        String_Display = strcat("码速Rs=", num2str(Rs), ",载频fc=", num2str(fc),",此时调制方式识别为：", classname);
                        app.cellArrayText_recognition_result = horzcat(app.cellArrayText_recognition_result, String_Display);
                        app.recognition_result.Value = app.cellArrayText_recognition_result;

%                         disp(String_Display);
                    end
                end
            end
            String_Display = sprintf('-----------本次识别结束------------\n\n');
            app.cellArrayText_recognition_result = horzcat(app.cellArrayText_recognition_result, String_Display);
            app.recognition_result.Value = app.cellArrayText_recognition_result;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '参数分析';
            app.GridLayout = uigridlayout(app.Panel);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '射频信号预处理';
            app.GridLayout2 = uigridlayout(app.Tab);
            app.data_read_module = uipanel(app.GridLayout2);
            app.data_read_module.Title = '数据读入';
            app.GridLayout12 = uigridlayout(app.data_read_module);
            app.data_read_start = uibutton(app.GridLayout12, 'push');
            app.data_read_start.ButtonPushedFcn = createCallbackFcn(app, @data_read_startButtonPushed, true);
            app.data_read_start.Text = '开始读取';
            app.Label = uilabel(app.GridLayout12);
            app.Label.Text = '文件名';
            app.file_name = uieditfield(app.GridLayout12, 'text');
            app.file_name.Value = 'Envelope.mat';
            app.data_reset = uibutton(app.GridLayout12, 'push');
            app.data_reset.ButtonPushedFcn = createCallbackFcn(app, @data_resetButtonPushed, true);
            app.data_reset.Text = '数据重置';
            app.Label_2 = uilabel(app.GridLayout12);
            app.Label_2.Text = '采样率';
            app.timeFreqAnalysis_fs = uieditfield(app.GridLayout12, 'numeric');
            app.timeFreqAnalysis_fs.Value = 50000000;
            app.mixing_module = uipanel(app.GridLayout2);
            app.mixing_module.Title = '下混频';
            app.GridLayout2_2 = uigridlayout(app.mixing_module);
            app.Label_3 = uilabel(app.GridLayout2_2);
            app.Label_3.Text = '混频载波频率';
            app.f_mix = uieditfield(app.GridLayout2_2, 'numeric');
            app.f_mix.Value = 24000000;
            app.Label_4 = uilabel(app.GridLayout2_2);
            app.Label_4.Text = '低频截止频率';
            app.fpass_lower = uieditfield(app.GridLayout2_2, 'numeric');
            app.fpass_lower.Value = 900000;
            app.EditField_4Label = uilabel(app.GridLayout2_2);
            app.EditField_4Label.Text = '高频截止频率';
            app.fpass_higher = uieditfield(app.GridLayout2_2, 'numeric');
            app.fpass_higher.Value = 950000;
            app.mix_start = uibutton(app.GridLayout2_2, 'push');
            app.mix_start.ButtonPushedFcn = createCallbackFcn(app, @mix_startButtonPushed, true);
            app.mix_start.Text = '开始';
            app.Label_6 = uilabel(app.GridLayout2_2);
            app.Label_6.Text = '滤波器选项';
            app.filter_option = uieditfield(app.GridLayout2_2, 'text');
            app.filter_option.Value = 'lowpass';
            app.timeSlice_module = uipanel(app.GridLayout2);
            app.timeSlice_module.Title = '时间切片';
            app.GridLayout2_3 = uigridlayout(app.timeSlice_module);
            app.Label_7 = uilabel(app.GridLayout2_3);
            app.Label_7.Text = '截取的时间起点';
            app.time_start = uieditfield(app.GridLayout2_3, 'numeric');
            app.EditField_12Label = uilabel(app.GridLayout2_3);
            app.EditField_12Label.Text = '截取的时间终点';
            app.time_end = uieditfield(app.GridLayout2_3, 'numeric');
            app.time_end.Value = 5e-05;
            app.timeSlice_start = uibutton(app.GridLayout2_3, 'push');
            app.timeSlice_start.ButtonPushedFcn = createCallbackFcn(app, @timeSlice_startButtonPushed, true);
            app.timeSlice_start.Text = '开始';
            app.update = uibutton(app.GridLayout2, 'push');
            app.update.ButtonPushedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.update.Text = '更新';
            app.Button_2 = uibutton(app.GridLayout2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '显示数据';
            app.Combination_timeFreq_Analysis_module = uipanel(app.GridLayout2);
            app.Combination_timeFreq_Analysis_module.Title = '组合时频分析';
            app.GridLayout6 = uigridlayout(app.Combination_timeFreq_Analysis_module);
            app.Label_11 = uilabel(app.GridLayout6);
            app.Label_11.Text = '窗类型';
            app.Combination_timeFreq_Analysis_winType = uieditfield(app.GridLayout6, 'text');
            app.Combination_timeFreq_Analysis_winType.Value = 'hamming';
            app.Label_12 = uilabel(app.GridLayout6);
            app.Label_12.Text = '窗长';
            app.Combination_timeFreq_Analysis_winLength = uieditfield(app.GridLayout6, 'numeric');
            app.Combination_timeFreq_Analysis_winLength.Value = 801;
            app.Label_13 = uilabel(app.GridLayout6);
            app.Label_13.Text = '能量去噪阈值';
            app.energy_denoise_threshold_search = uieditfield(app.GridLayout6, 'text');
            app.energy_denoise_threshold_search.Value = '1:0.1:10';
            app.Label_14 = uilabel(app.GridLayout6);
            app.Label_14.Text = '信息熵步长数目';
            app.information_entropy_step_num = uieditfield(app.GridLayout6, 'numeric');
            app.information_entropy_step_num.Value = 100;
            app.Combination_timeFreq_Analysis_start = uibutton(app.GridLayout6, 'push');
            app.Combination_timeFreq_Analysis_start.ButtonPushedFcn = createCallbackFcn(app, @Combination_timeFreq_Analysis_startButtonPushed, true);
            app.Combination_timeFreq_Analysis_start.Text = '开始';
            app.resume_speed_fitting = uibutton(app.GridLayout6, 'push');
            app.resume_speed_fitting.ButtonPushedFcn = createCallbackFcn(app, @resume_speed_fittingButtonPushed, true);
            app.resume_speed_fitting.Text = '继续';
            app.highpass_module = uipanel(app.GridLayout2);
            app.highpass_module.Title = '高通滤波';
            app.GridLayout2_4 = uigridlayout(app.highpass_module);
            app.Label_9 = uilabel(app.GridLayout2_4);
            app.Label_9.Text = '截止频率';
            app.highpass_cutoff_freq = uieditfield(app.GridLayout2_4, 'numeric');
            app.highpass_cutoff_freq.Value = 10000;
            app.highpass_filter_start = uibutton(app.GridLayout2_4, 'push');
            app.highpass_filter_start.ButtonPushedFcn = createCallbackFcn(app, @highpass_filter_startButtonPushed, true);
            app.highpass_filter_start.Text = '开始';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '调制识别';
            app.GridLayout13 = uigridlayout(app.Tab_2);
            app.dataReadEffectiveSection_module = uipanel(app.GridLayout13);
            app.dataReadEffectiveSection_module.Title = '读取信号有效片段';
            app.GridLayout8 = uigridlayout(app.dataReadEffectiveSection_module);
            app.Label_15 = uilabel(app.GridLayout8);
            app.Label_15.Text = '采样频率';
            app.sampleRate = uieditfield(app.GridLayout8, 'numeric');
            app.sampleRate.Value = 15360000;
            app.Label_16 = uilabel(app.GridLayout8);
            app.Label_16.Text = '采样位数';
            app.sampleBit = uieditfield(app.GridLayout8, 'text');
            app.sampleBit.Value = 'int16';
            app.Label_17 = uilabel(app.GridLayout8);
            app.Label_17.Text = '待分析文件名';
            app.filename = uieditfield(app.GridLayout8, 'text');
            app.filename.Value = '1711665356.4783814_iq.txt';
            app.dataReadEffectiveSection_module_Start = uibutton(app.GridLayout8, 'push');
            app.dataReadEffectiveSection_module_Start.ButtonPushedFcn = createCallbackFcn(app, @dataReadEffectiveSection_module_StartButtonPushed, true);
            app.dataReadEffectiveSection_module_Start.Text = '开始';
            app.dataReadEffectiveSection_module_Resume = uibutton(app.GridLayout8, 'push');
            app.dataReadEffectiveSection_module_Resume.ButtonPushedFcn = createCallbackFcn(app, @dataReadEffectiveSection_module_ResumeButtonPushed, true);
            app.dataReadEffectiveSection_module_Resume.Text = '继续';
            app.Rs_and_Fc_estimate_module = uipanel(app.GridLayout13);
            app.Rs_and_Fc_estimate_module.Title = '估计有效信号片段的码速和载频';
            app.GridLayout9 = uigridlayout(app.Rs_and_Fc_estimate_module);
            app.Label_18 = uilabel(app.GridLayout9);
            app.Label_18.Text = '循环谱窗长';
            app.curricular_spectrum_length = uieditfield(app.GridLayout9, 'numeric');
            app.curricular_spectrum_length.Value = 5000;
            app.FFTEditFieldLabel = uilabel(app.GridLayout9);
            app.FFTEditFieldLabel.Text = '自相关估计FFT点数';
            app.auto_coreletion_FFT_point = uieditfield(app.GridLayout9, 'numeric');
            app.auto_coreletion_FFT_point.Value = 1024;
            app.EditField_2Label = uilabel(app.GridLayout9);
            app.EditField_2Label.Text = '自相关估计窗长';
            app.auto_correlation_win_length = uieditfield(app.GridLayout9, 'numeric');
            app.auto_correlation_win_length.Value = 3000;
            app.Rs_and_Fc_estimate_module_initialize = uibutton(app.GridLayout9, 'push');
            app.Rs_and_Fc_estimate_module_initialize.ButtonPushedFcn = createCallbackFcn(app, @Rs_and_Fc_estimate_module_initializeButtonPushed, true);
            app.Rs_and_Fc_estimate_module_initialize.Tooltip = {'可以兼带复位的作用'};
            app.Rs_and_Fc_estimate_module_initialize.Text = '初始化';
            app.Rs_and_Fc_estimate_module_start = uibutton(app.GridLayout9, 'push');
            app.Rs_and_Fc_estimate_module_start.ButtonPushedFcn = createCallbackFcn(app, @Rs_and_Fc_estimate_module_startButtonPushed, true);
            app.Rs_and_Fc_estimate_module_start.Text = '开始估计';
            app.dataFeature_design_and_dataset_make_module = uipanel(app.GridLayout13);
            app.dataFeature_design_and_dataset_make_module.Title = '设计数据特征和制作特征数据集';
            app.GridLayout10 = uigridlayout(app.dataFeature_design_and_dataset_make_module);
            app.dataFeature_design_and_dataset_make_module_start = uibutton(app.GridLayout10, 'push');
            app.dataFeature_design_and_dataset_make_module_start.ButtonPushedFcn = createCallbackFcn(app, @dataFeature_design_and_dataset_make_module_startButtonPushed, true);
            app.dataFeature_design_and_dataset_make_module_start.Text = '开始';
            app.CPUEditFieldLabel = uilabel(app.GridLayout10);
            app.CPUEditFieldLabel.Text = '并行CPU核数';
            app.parallel_CPU_core_num = uieditfield(app.GridLayout10, 'numeric');
            app.parallel_CPU_core_num.Value = 8;
            app.EditFieldLabel = uilabel(app.GridLayout10);
            app.EditFieldLabel.Text = '每类调制样本数';
            app.modulation_sample_num = uieditfield(app.GridLayout10, 'numeric');
            app.modulation_sample_num.Value = 5;
            app.EditField_2Label_2 = uilabel(app.GridLayout10);
            app.EditField_2Label_2.Text = '多进制调制的阶数';
            app.Multi_base_modulation_order = uieditfield(app.GridLayout10, 'numeric');
            app.Multi_base_modulation_order.Value = 2;
            app.modulation_KNN_comparasion = uipanel(app.GridLayout13);
            app.modulation_KNN_comparasion.Title = '提取待测信号特征和输出调制类别';
            app.GridLayout11 = uigridlayout(app.modulation_KNN_comparasion);
            app.modulation_KNN_comparasion_start = uibutton(app.GridLayout11, 'push');
            app.modulation_KNN_comparasion_start.ButtonPushedFcn = createCallbackFcn(app, @modulation_KNN_comparasion_startButtonPushed, true);
            app.modulation_KNN_comparasion_start.Text = '开始';
            app.Label_22 = uilabel(app.GridLayout11);
            app.Label_22.Text = '每类调制样本数';
            app.modulation_sample_num_comparasion = uieditfield(app.GridLayout11, 'numeric');
            app.modulation_sample_num_comparasion.Value = 5;
            app.KNNEditFieldLabel = uilabel(app.GridLayout11);
            app.KNNEditFieldLabel.Text = 'KNN聚类数';
            app.KNN_cluster_num = uieditfield(app.GridLayout11, 'numeric');
            app.KNN_cluster_num.Value = 10;
            app.Label_23 = uilabel(app.GridLayout11);
            app.Label_23.Text = '待分析信号长度';
            app.signal_waiting_analysis_length = uieditfield(app.GridLayout11, 'numeric');
            app.signal_waiting_analysis_length.Value = 5000;
            app.result_display = uipanel(app.GridLayout13);
            app.result_display.Title = '结果显示';
            app.GridLayout29 = uigridlayout(app.result_display);
            app.Label_25 = uilabel(app.GridLayout29);
            app.Label_25.Text = '识别结果';
            app.recognition_result = uitextarea(app.GridLayout29);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Parameter_Analysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end