classdef Quick_Simulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Panel              matlab.ui.container.Panel
        GridLayout         matlab.ui.container.GridLayout
        error_rate_result  matlab.ui.control.TextArea
        Label_6            matlab.ui.control.Label
        quick_simulation   matlab.ui.control.Button
        Label_5            matlab.ui.control.Label
        sequence_value_4   matlab.ui.control.EditField
        sequence_value_3   matlab.ui.control.EditField
        Label_4            matlab.ui.control.Label
        Label_3            matlab.ui.control.Label
        sequence_value_2   matlab.ui.control.EditField
        Label_2            matlab.ui.control.Label
        sequence_value1    matlab.ui.control.EditField
        Label              matlab.ui.control.Label
        DropDown_5         matlab.ui.control.DropDown
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = public)
        main; % Description
    end
    
    properties (Access = private)
        quick_simulation_flag; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app_parent)
            app.main = app_parent;
        end

        % Button pushed function: quick_simulation
        function quick_simulationButtonPushed(app, event)
             try
                dropValue_sequence_type = app.DropDown_5.Value;

                % 读取三大类型值，决定干扰源、调制方式与干扰方式
                dropValue1 = app.main.DropDown1.Value;
                dropValue2 = app.main.DropDown_2.Value;
                dropValue3 = app.main.DropDown_3.Value;

                if dropValue1 == "None"
                    uiconfirm(app.UIFigure,'No selection source','Process Prompt','Icon','warning');
                    return ;
                end

                % 选择快速仿真类型
                switch dropValue_sequence_type
                    case "None"
                        uiconfirm(app.UIFigure,'No selection sequence','Process Prompt','Icon','warning');
                        return ;

                    case "功率序列"
                        power_sequence_value = str2num(['[' app.sequence_value1.Value ']']);

                    case "步进扫频下限频率序列"
                        StepSweepFreq_downLimit_sequence_value = str2num(['[' app.sequence_value1.Value ']']);

                    case "步进扫频上限频率序列"
                        StepSweepFreq_upLimit_sequence_value = str2num(['[' app.sequence_value1.Value ']']);

                    case "步进扫频跳频点个数序列"
                        StepSweepN_sequence_value = str2num(['[' app.sequence_value1.Value ']']);

                    case "单音干扰载频序列"
                        Single_tone_sequence_value = str2num(['[' app.sequence_value1.Value ']']);

                    otherwise
                        uiconfirm(app.noise,'Sequence undefined','Process Prompt','Icon','warning');
                end

                error_rate_array = zeros(1, length(str2num(['[' app.sequence_value1.Value ']']))); %% 用于存放误码率序列
                fbar = waitbar(0,'请等待..');
                process_percent = 0.9 / length(str2num(['[' app.sequence_value1.Value ']'])); %% 做一个进度百分比提示，每次的进度值

                % 根据所输入的序列对快速仿真进行遍历
                for i = 1:length(str2num(['[' app.sequence_value1.Value ']']))
                    
                    % 为模型调取工作区原有参数
                    model = evalin('base', 'model');
                   
                    % 设置噪声源的采样时间、干扰功率系数，以及根据各判断类型生成对应的噪声参数
                    app.main.Ts = app.main.EditField_9.Value;
                    model.Parameters.NoiseGen.NoiseTs = app.main.Ts;

                    % 设置干扰源的带宽
                    polit_filter_num = app.main.passband_num.Value;
                    polit_centerFreq = str2num(app.main.passband_centerFreq.Value);
                    pass_bw = app.main.passband_bw.Value;
                    transitionWidth = app.main.transition_bw.Value;

                    model.Parameters.NoiseGen.noise_filter_b = designMutiBandpassFilter(app.main, polit_filter_num, polit_centerFreq, pass_bw, transitionWidth);
                    
                    % 根据不同的快速仿真的类型，来决定相对应的参数的修改，以进行快速仿真
                    switch dropValue_sequence_type
                        case "功率序列"
                        %根据输入的快速仿真序列来决定功率序列的快速仿真
                        power_value = power_sequence_value(i);
                        app.main.power_controller_W.Value = power_value;
                        if app.main.Mean_Power == 0
                            app.main.power_controller_coefficience.Value = 0;
                        else
                            app.main.power_controller_coefficience.Value = app.main.power_controller_coefficience.Value * power_value / app.main.Mean_Power;
                        end
                        model.Parameters.jammer_power_controller = sqrt(app.main.power_controller_coefficience.Value); %这个参数需要补充定义，否则后面Jammer_Generator.slx模型报错

                        
                        case "步进扫频上限频率序列"
                        StepSweepFreq_upLimit_value = StepSweepFreq_upLimit_sequence_value(i);
                        app.main.StepSweepFmax.Value = StepSweepFreq_upLimit_value;
                        if app.main.Mean_Power == 0
                            app.main.power_controller_coefficience.Value = 0;
                        else
                            app.main.power_controller_coefficience.Value = app.main.power_controller_coefficience.Value * app.main.power_controller_W.Value / app.main.Mean_Power;
                            model.Parameters.jammer_power_controller = sqrt(app.main.power_controller_coefficience.Value);
                        end

                        case "步进扫频下限频率序列"
                        StepSweepFreq_downLimit_value = StepSweepFreq_downLimit_sequence_value(i);
                        app.main.StepSweepFmin.Value = StepSweepFreq_downLimit_value;
                        if app.main.Mean_Power == 0
                            app.main.power_controller_coefficience.Value = 0;
                        else
                            app.main.power_controller_coefficience.Value = app.main.power_controller_coefficience.Value * app.main.power_controller_W.Value / app.main.Mean_Power;
                            model.Parameters.jammer_power_controller = sqrt(app.main.power_controller_coefficience.Value);
                        end

                        case "步进扫频跳频点个数序列"
                        StepSweepN_value = StepSweepN_sequence_value(i);
                        app.main.StepSweepN.Value = StepSweepN_value;
                        if app.main.Mean_Power == 0
                            app.main.power_controller_coefficience.Value = 0;
                        else
                            app.main.power_controller_coefficience.Value = app.main.power_controller_coefficience.Value * app.main.power_controller_W.Value / app.main.Mean_Power;
                            model.Parameters.jammer_power_controller = sqrt(app.main.power_controller_coefficience.Value);
                        end

                        case"单音干扰载频序列"
                        Single_tone_value = Single_tone_sequence_value(i);
                        dropValue1 = "单音噪声"; %由于是单音干扰载频序列，故需要修改干扰源为单音正弦噪声
                        app.main.SinF.Value = Single_tone_value;
                        if app.main.Mean_Power == 0
                            app.main.power_controller_coefficience.Value = 0;
                        else
                            app.main.power_controller_coefficience.Value = app.main.power_controller_coefficience.Value * app.main.power_controller_W.Value / app.main.Mean_Power;
                            model.Parameters.jammer_power_controller = sqrt(app.main.power_controller_coefficience.Value);
                        end

                        otherwise
                        
                    end

                    switch dropValue1
                        case "None"
                            model.Parameters.NoiseGen.Type = "None";
                            model.Parameters.NoiseGen.Control.Source = 0;
                        case "窄带噪声"
                            %生成高斯白噪声信号
                            model.Parameters.NoiseGen.Type = "高斯白噪声";
                            model.Parameters.NoiseGen.Control.Source = 1;
                            model.Parameters.NoiseGen.GaussPower = 10;       % 高斯白噪声的功率密度
                            model.Parameters.NoiseGen.GaussSeed = 67;      % 白噪声随机种子
                            model.Parameters.NoiseGen.pulse_noise_filter_b = designMutiBandpassFilter(app.main, 1, app.main.pulseNoise_centerFreq.Value, app.main.pulseNoise_pass_bw.Value, transitionWidth);

                        case "单音噪声"
                            % 生成单音正弦噪声
                            model.Parameters.NoiseGen.Type = "单音正弦噪声";
                            model.Parameters.NoiseGen.Control.Source = 3;
                            model.Parameters.NoiseGen.SineNoiseA = app.main.SinAM.Value;      %单音正弦噪声的幅度
                            model.Parameters.NoiseGen.SineNoiseF = app.main.SinF.Value;       %单音正弦噪声的频率
                            model.Parameters.NoiseGen.SineNoisePhi0 = 0;                 %单音正弦噪声的初始相位

                        otherwise
                            model.Parameters.NoiseGen.Type = "None";
                            model.Parameters.NoiseGen.Control.Source = 0; 
                    end
                    
                    switch dropValue2
                        case "None"
                            %干扰方式类型，默认是None
                            model.Parameters.NoiseGen.Jamming.Type = "None"; 
                            %噪声源的干扰方式控制位，默认为0. 0,干扰方式不工作
                            model.Parameters.NoiseGen.Control.Jamming = 0;   
                        case "步进扫频"
                            %干扰方式类型
                            model.Parameters.NoiseGen.Jamming.Type = "步进调频"; 
                            %干扰方式控制位,2代表采用步进调频
                            model.Parameters.NoiseGen.Control.Jamming = 2;  
                            %步进扫频的最小频率
                            model.Parameters.NoiseGen.Jamming.StepSweepFmin = app.main.StepSweepFmin.Value;
                            %步进扫频的最大频率
                            model.Parameters.NoiseGen.Jamming.StepSweepFmax = app.main.StepSweepFmax.Value;
                            %步进扫频的周期
                            model.Parameters.NoiseGen.Jamming.StepSweepT = app.main.StepSweepT.Value;
                            %步进扫频的跳频个数
                            model.Parameters.NoiseGen.Jamming.StepSweepN = app.main.StepSweepN.Value;
                            %步进扫频的频率间隔
                            model.Parameters.NoiseGen.Jamming.StepSweepDeltaFstep = round(abs(app.main.StepSweepFmax.Value-app.main.StepSweepFmin.Value)/...
                                (app.main.StepSweepN.Value-1));
                            %步进扫频的时间间隔
                            model.Parameters.NoiseGen.Jamming.StepSweepTDwell = app.main.StepSweepT.Value / app.main.StepSweepN.Value;
        
                            %MF调制的跳频序列
                            model.Parameters.NoiseGen.MultiFreq.MFSequence = [0];
                            %MF调制的跳频个数
                            model.Parameters.NoiseGen.MultiFreq.MFNum =  32;
                            %MF调制的最小跳频间隔
                            model.Parameters.NoiseGen.MultiFreq.MFDistance =  1e6;
                            %MF调制的跳频驻留时间
                            model.Parameters.NoiseGen.MultiFreq.MFTime =  app.main.StepSweepT.Value ;
        
                        case "随机扫频"
                            %先让步进扫频不工作
                            model.Parameters.NoiseGen.Jamming.Type = "None"; 
                            model.Parameters.NoiseGen.Control.Jamming = 0;  
                            %MF调制的跳频序列
                            model.Parameters.NoiseGen.MultiFreq.MFSequence = str2num(['[' app.main.MultiFreqSkipSequence.Value ']']);
                            %MF调制的跳频个数
                            model.Parameters.NoiseGen.MultiFreq.MFNum =  app.main.MultiFreqSkipNum.Value ;
                            %MF调制的最小跳频间隔
                            model.Parameters.NoiseGen.MultiFreq.MFDistance =  app.main.MultiFreqSkipDistance.Value ;
                            %MF调制的跳频驻留时间
                            model.Parameters.NoiseGen.MultiFreq.MFTime =  app.main.MultiFreqSkipTime.Value ;
        
                    end
                    switch dropValue3
                        case "None"
                            if dropValue2 ~= "随机扫频"
                                %多频干扰类型，默认是None
                                model.Parameters.NoiseGen.MultiFreq.Type = "None"; 
                                %噪声源的多频干扰控制位，默认为0. 0,多频干扰不工作
                                model.Parameters.NoiseGen.Control.MultiFreq = 0;
                            else
                                %多频干扰类型
                                model.Parameters.NoiseGen.MultiFreq.Type = "多频干扰"; 
                                %噪声源的多频干扰控制位，1多频干扰工作
                                model.Parameters.NoiseGen.Control.MultiFreq = 1;
                                %MF调制的幅度
                                model.Parameters.NoiseGen.MultiFreq.MFA = [1];  
                                %MF调制的频率
                                model.Parameters.NoiseGen.MultiFreq.MFF = [0]; 
                            end
                        case "多频干扰"
                            %多频干扰类型
                            model.Parameters.NoiseGen.MultiFreq.Type = "多频干扰"; 
                            %噪声源的多频干扰控制位，1多频干扰工作
                            model.Parameters.NoiseGen.Control.MultiFreq = 1;
                            %MF调制的幅度
                            model.Parameters.NoiseGen.MultiFreq.MFA = str2num(['[' app.main.MultiFreqA.Value ']']);  
                            %MF调制的频率
                            model.Parameters.NoiseGen.MultiFreq.MFF = str2num(['[' app.main.MultiFreqF.Value ']']);        
                    end

                    % 根据不同的链路，选择对应的链路输入参数名称读入
                    try
                        if app.main.datalink_selector_num == 1
                            UAV_Datalink_temporary_storage = evalin('base','model.UserData');  %% 此处出了问题
                            model.UserData = UAV_Datalink_temporary_storage;
                        elseif app.main.datalink_selector_num == 2
                            UAV_Datalink_temporary_storage = evalin('base','model.user_defined');
                            model.user_defined = UAV_Datalink_temporary_storage;
                        elseif app.main.datalink_selector_num == 3
                            UAV_Datalink_temporary_storage = evalin('base','model.user_defined');
                            model.user_defined = UAV_Datalink_temporary_storage;
                        else % 第四条链路
                            UAV_Datalink_temporary_storage = evalin('base','model.user_defined');
                            model.user_defined = UAV_Datalink_temporary_storage;        
                        end
                    catch ME
                        disp(ME.message);
                        uiconfirm(app.UIFigure,'model.user_defined不存在，请运行一遍链路','Process Prompt','Icon','warning');
                        close(fbar);
                        return ;
                    end        

                    assignin('base','model',model); 

                    %运行Jammer_Generator.slx模型并设定仿真时间
                    app.main.T_Noise = app.main.EditField_10.Value;
                    simOut = sim("Jammer_Generator.slx", 'stoptime', num2str(app.main.T_Noise));

                    % 功率估计,画图
                    extend_multiple_2 = ceil(length(simOut.tout) / length(simOut.NoisePowerEstimation.Data));
                    Power_Estimate = reshape(repmat(reshape(simOut.NoisePowerEstimation.Data,1,[]),extend_multiple_2,1),1,[]);

                    % 功率估计，显示最终时间的平均功率值
                    Power_Estimate_mean = mean(Power_Estimate(end-length(Power_Estimate)/2:end));

                    %% 这部分为添加的二次仿真，目的是使仿真的功率控制可以一次实现
                    if strcmp(dropValue1, "窄带噪声") || strcmp(dropValue1, "单音噪声")
                        Power_error = 0.1;
                    else
                        Power_error = 0.4;
                    end

                    while (abs(Power_Estimate_mean-app.main.power_controller_W.Value) >= Power_error )
                        app.main.power_controller_coefficience.Value = app.main.power_controller_coefficience.Value * app.main.power_controller_W.Value / max(Power_Estimate_mean, 1e-10);
                        model.Parameters.jammer_power_controller = sqrt(app.main.power_controller_coefficience.Value);
                        assignin('base','model',model);
                        simOut = sim("Jammer_Generator.slx", 'stoptime', num2str(app.main.T_Noise));
                        assignin("base", 'simOut', simOut);
                        extend_multiple_2 = ceil(length(simOut.tout) / length(simOut.NoisePowerEstimation.Data));
                        Power_Estimate = reshape(repmat(reshape(simOut.NoisePowerEstimation.Data,1,[]),extend_multiple_2,1),1,[]);
                        Power_Estimate_mean = mean(Power_Estimate(end-length(Power_Estimate)/2:end));
                    end

                    %%
                    model.Parameters.NoiseGen.NosiePowerEstimation = Power_Estimate_mean;
                    % 保存建模产生的噪声信号
                    assignin('base','model',model); % 此处，将噪声数据和功率估计结果赋值到基本工作区中

                    % 现在遇到的问题是，如何在noise中调用datalink的参数，或如何在datalink中调用noise的参数，实现一键进行两者的联合仿真！！！ 已解决
                    % 输入信号传参
                    UAV_datalink_simulation_time = evalin('base','UAV_datalink_simulation_time');

                    % 调用仿真模型
                    app.quick_simulation_flag = 1;
                    assignin('base','quick_simulation_flag',app.quick_simulation_flag);

                    try
                        if app.main.datalink_selector_num == 1
                            simOut = sim("Simple_Model_Packaging.slx",'stoptime',num2str(UAV_datalink_simulation_time));%运行Sine.slx模型并设定仿真时间为UAV_datalink_simulation_time
                        elseif app.main.datalink_selector_num == 2
                            simOut = sim("Simple_Model_Packaging_2.slx",'stoptime',num2str(UAV_datalink_simulation_time));
                        elseif app.main.datalink_selector_num == 3
%                             simOut = sim("Simple_Model_Packaging_3.slx",'stoptime',num2str(UAV_datalink_simulation_time));
                            simOut = sim("Simple_Model_Packaging_7.slx",'stoptime',num2str(UAV_datalink_simulation_time));
                        elseif app.main.datalink_selector_num == 4
                            simOut = sim("Simple_Model_Packaging_4.slx",'stoptime',num2str(UAV_datalink_simulation_time));
                        else % 第五条链路
                            simOut = sim("Simple_Model_Packaging_5.slx",'stoptime',num2str(UAV_datalink_simulation_time));
                        end
                        
                        % 误码率曲线
                        error_bit_output = simOut.CodeBER.Data;
                        error_rate_array(i) = error_bit_output(end,1);
                    end

                    waitbar(process_percent*i, fbar, '序列点仿真进行中');
                end
                
                % 结束对输入序列的值遍历后，展示快速仿真的序列对应的误码率
                str_display = [ "快速仿真的误码率结果为：", num2str(round(error_rate_array, 4))];
                app.error_rate_result.Value = str_display;
                app.quick_simulation_flag = 0;
                assignin('base','quick_simulation_flag',app.quick_simulation_flag);
                waitbar(1,fbar,'完成一键快速仿真');
                close(fbar);

                uiconfirm(app.UIFigure,'quick simulation accomplishment','Process Prompt','Icon','success');

             catch ME
                disp(ME.message);
                uiconfirm(app.UIFigure,ME.message,'Process Prompt','Icon','warning');
                close(fbar);
                return ;

            end


        end

        % Value changed function: DropDown_5
        function DropDown_5ValueChanged(app, event)
            value = app.DropDown_5.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '快速仿真';
            app.GridLayout = uigridlayout(app.Panel);
            app.DropDown_5 = uidropdown(app.GridLayout);
            app.DropDown_5.Items = {'None', '功率序列', '单音干扰载频序列', '步进扫频上限频率序列', '步进扫频下限频率序列', '步进扫频跳频点个数序列', '其他（还有很多种）'};
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_5ValueChanged, true);
            app.DropDown_5.Value = 'None';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '序列类型';
            app.sequence_value1 = uieditfield(app.GridLayout, 'text');
            app.sequence_value1.Value = '5,10,20';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '序列参数1的值';
            app.sequence_value_2 = uieditfield(app.GridLayout, 'text');
            app.sequence_value_2.Editable = 'off';
            app.sequence_value_2.Value = '20e6,28e6,35e6,40e6';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = '序列参数2的值';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = '序列参数3的值';
            app.sequence_value_3 = uieditfield(app.GridLayout, 'text');
            app.sequence_value_3.Editable = 'off';
            app.sequence_value_3.Value = '4e6,5e6,6e6';
            app.sequence_value_4 = uieditfield(app.GridLayout, 'text');
            app.sequence_value_4.Editable = 'off';
            app.sequence_value_4.Value = '2,3,4';
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_5.Text = '序列参数4的值';
            app.quick_simulation = uibutton(app.GridLayout, 'push');
            app.quick_simulation.ButtonPushedFcn = createCallbackFcn(app, @quick_simulationButtonPushed, true);
            app.quick_simulation.Tooltip = {'使用前需要先仿真一次链路，把链路参数导入到工作区中，并且功率控制框中的功率要与序列值的第一个值相同'};
            app.quick_simulation.Text = '开启快速仿真';
            app.Label_6 = uilabel(app.GridLayout);
            app.Label_6.Text = '快速仿真数据结果';
            app.error_rate_result = uitextarea(app.GridLayout);
            app.error_rate_result.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Quick_Simulation(varargin)

            % Associate the Simulink Model
            app.Simulation = simulation('Simple_Model_Packaging_7');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end