classdef Similarity_Display < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        Tab                   matlab.ui.container.Tab
        GridLayout            matlab.ui.container.GridLayout
        Button_6              matlab.ui.control.Button
        Path_import1          matlab.ui.control.Button
        Path_import2          matlab.ui.control.Button
        EWDTWTextArea         matlab.ui.control.TextArea
        FLEDTWTextArea        matlab.ui.control.TextArea
        SFDTWTextArea         matlab.ui.control.TextArea
        SFDTWLabel            matlab.ui.control.Label
        FLEDTWLabel           matlab.ui.control.Label
        EWDTWTextAreaLabel    matlab.ui.control.Label
        Sequence_1            matlab.ui.control.EditField
        Sequence_2            matlab.ui.control.EditField
        EditField_2Label      matlab.ui.control.Label
        Label_2               matlab.ui.control.Label
        Tab_3                 matlab.ui.container.Tab
        GridLayout_2          matlab.ui.container.GridLayout
        Sequence_2_2          matlab.ui.control.EditField
        Label_5               matlab.ui.control.Label
        Sequence_1_2          matlab.ui.control.EditField
        Label_4               matlab.ui.control.Label
        SFDTW_weight          matlab.ui.control.NumericEditField
        SFDTWEditFieldLabel   matlab.ui.control.Label
        FLEDTW_weight         matlab.ui.control.NumericEditField
        FLEDTWEditFieldLabel  matlab.ui.control.Label
        EWDTW_weight          matlab.ui.control.NumericEditField
        FLEDTWLabel_3         matlab.ui.control.Label
        Button_7              matlab.ui.control.Button
        Path_import1_2        matlab.ui.control.Button
        Path_import2_2        matlab.ui.control.Button
        Weighted_result       matlab.ui.control.EditField
        Label_3               matlab.ui.control.Label
        Label                 matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function Button_5Pushed(app, event)
            % 打开文件选择对话框，仅显示.mat文件
            [filename, pathname] = uigetfile('*.mat', '选择MAT文件');
            
            % 如果用户没有取消选择
            if ~isequal(filename, 0)
                % 构建完整文件路径
                fullpath = fullfile(pathname, filename);
                
                try
                    % 加载.mat文件内容
                    fileContents = load(fullpath);
                    
                    % 获取文件中的所有变量名
                    varNames = fieldnames(fileContents);
                    
                    % 检查是否至少有一个变量
                    if isempty(varNames)
                        errordlg('选中的.mat文件不包含任何变量', '导入错误');
                        return;
                    end
                    
                    % 获取第一个变量的内容
                    firstVarName = varNames{1};
                    % 使用 sprintf 和 %g 格式
                    str = sprintf('%g ', fileContents.(firstVarName));
                    app.Sequence_1.Value = strtrim(str); % 去除末尾空格
                    % app.Sequence_1.Value = num2str(fileContents.(firstVarName));
                    
                    % 检查是否是矩阵
                    if ~ismatrix(fileContents.(firstVarName))
                        errordlg('文件中的第一个变量不是矩阵', '导入错误');
                        return;
                    end
                    
                    
                    % 显示成功消息和变量信息
                    msgbox(sprintf('成功导入矩阵数据\n变量名: %s\n大小: %s', ...
                        firstVarName, mat2str(size(fileContents.(firstVarName)))), '导入完成');
                    
                    % 可选: 在命令行显示信息
                    disp(['已导入变量: ' firstVarName]);
                    disp(['矩阵大小: ' mat2str(size(fileContents.(firstVarName)))]);
                    
                catch ME
                    % 显示错误信息
                    errordlg(sprintf('导入文件时出错:\n%s', ME.message), '导入错误');
                end
            end

        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            
            X = str2num(app.Sequence_1.Value) ; % 时间序列 X
            Y = str2num(app.Sequence_2.Value) ; % 时间序列 Y

            Xle = EWDTW_1(X) ;
            Yle = EWDTW_1(Y) ;
            L=length(X) ;
            % 调用 EWDTW_2 函数
            distance_EWDTW = EWDTW_2(Xle, Yle, 8, 0.2, L);
            % 调用 FLEDTW_1 函数
            distance_FLEDTW = FLEDTW_1(X,Y) ;
            % 调用 SFDTW_1 函数
            distance_SFDTW = SFDTW_1(X,Y,2,1,1) ;
            
            app.EWDTWTextArea.Value = num2str(distance_EWDTW) ;
            app.FLEDTWTextArea.Value = num2str(distance_FLEDTW) ;
            app.SFDTWTextArea.Value = num2str(distance_SFDTW) ;

            %% EWDTW相似度算法函数
            function result = EWDTW_1(time_series)
                % EWDTW算法。本函数用于提取原本序列的极大值和极小值，初始点和终止点
                % 输入矩阵为一个行向量
                % 生成的矩阵为2维矩阵，第一列为记录的点的所在位置，第二列为记录的点的值，第三列为类型，极大值记为1，极小值记为-1，初始值和终止值记为0
                % 获取时间序列的长度
                n = length(time_series);
                
                % 初始化结果二维数组
                result = zeros(n, 3);  
                
                % 初始点和终止点类型是0
                result(1, 1) = 1;            % 初始点位置
                result(1, 2) = time_series(1); % 初始点值
                result(1, 3) = 0;            % 初始点类型
                
                result(n, 1) = n;            % 终止点位置
                result(n, 2) = time_series(n); % 终止点值
                result(n, 3) = 0;            % 终止点类型
                
                % 查找极值点
                for i = 2:n-1
                    if time_series(i) > time_series(i-1) && time_series(i) > time_series(i+1)
                        % 极大值点
                        result(i, 1) = i;            % 极大值点位置
                        result(i, 2) = time_series(i); % 极大值点值
                        result(i, 3) = 1;            % 极大值类型
                    elseif time_series(i) < time_series(i-1) && time_series(i) < time_series(i+1)
                        % 极小值点
                        result(i, 1) = i;            % 极小值点位置
                        result(i, 2) = time_series(i); % 极小值点值
                        result(i, 3) = -1;           % 极小值类型
                    end
                end
                
                % 清除不记录的点，将其位置填充为0
                result(result(:, 1) == 0, :) = [];
            end

            function distance = EWDTW_2(Xle, Yle, Alpha, Beta, L) % 其中 L=length(X); 即为Xle的原始序列长度.Alpha默认值为8 ,Beta默认值为 0.2
                % EWDTW算法。本函数用于计算两个序列的相似性度量距离
                % 输入为极值序列Xle和Yle ；加权参数Alpha和Beta. 原始序列的时间长度L
                % 获取输入矩阵的行数
                n = size(Xle, 1); % Xle 的行数
                m = size(Yle, 1); % Yle 的行数
            
                % 初始化 DTW 距离矩阵
                position_distance = zeros(n, m);
                value_distance = zeros(n, m);
                type_distance = zeros(n, m);
                W = zeros(n, m); % W是距离矩阵
                Wmax = 2 ; % Wmax是函数的期望上界,不影响权重函数曲线,值固定为 2
                mc = round(L/2) ; % mc 是原始序列中点.对于这个"中点"的理解，也有的理解为是对齐的那个点的原始序列位置值
            
                DEW = zeros(n+1, m+1);
                % 设置边界条件
                DEW(:, 1) = Inf;
                DEW(1, :) = Inf;
                DEW(1, 1) = 0;  % 起点为 0
            
                % 计算加权距离
                for i = 1:n
                    for j = 1:m
                        % 计算位置、值和类型的距离
                        position_distance(i,j) = abs(Xle(i,1)-Yle(j, 1));
                        value_distance(i,j) = abs(Xle(i,2)-Yle(j, 2));
                        type_distance(i,j) = 1-Beta*Xle(i,3)*Yle(j, 3);
                        
                        W(i,j) = Wmax/(1+exp(-Alpha*(position_distance(i,j)*type_distance(i,j)-mc)/L)) ;
                        DEW(i+1,j+1) = W(i,j)*value_distance(i,j)+min([DEW(i,j+1),DEW(i+1,j),DEW(i,j)]) ;
            
                    end
                end
            
                % 最终的相似性度量就是DTW矩阵的右下角元素
                distance = DEW(n+1,m+1);  
            end

            %% FLEDTW相似度算法函数
            function dist_FLEDTW = FLEDTW_1(time_series_X ,time_series_Y )
                % FLEDTW算法。本函数用于直接生成俩序列的相似度量距离。子函数为提取时间序列的极小值特征矩阵和极大值特征矩阵
                % 输入矩阵为两个行向量X,Y
                % 输出为相似度量距离
                % 生成的矩阵为3维矩阵，第一列为记录的点的所在位置，第二列为记录的点的值，第三列为类型，极大值记为1，极小值记为-1，初始值和终止值记为0
                [Xem, XeM] = max_min_matrix (time_series_X) ;
                [Yem, YeM] = max_min_matrix (time_series_Y) ;
            
                n1 = size(Xem, 1); % 极小值特征矩阵 Xem 的行数
                n2 = size(XeM, 1); % 极大值特征矩阵 XeM 的行数
            
                m1 = size(Yem, 1); % 极小值特征矩阵 Yem 的行数
                m2 = size(YeM, 1); % 极大值特征矩阵 YeM 的行数
            
                Dem = zeros(n1+1, m1+1); % 极小值特征矩阵的相似性度量距离
                DeM = zeros(n2+1, m2+1); % 极大值特征矩阵的相似性度量距离
            
                d_em= zeros(n1, m1);
                d_eM= zeros(n2, m2);
            
                % 设置边界条件
                Dem(:, 1) = Inf;
                Dem(1, :) = Inf;
                Dem(1, 1) = 0;  % 起点为 0
            
                DeM(:, 1) = Inf;
                DeM(1, :) = Inf;
                DeM(1, 1) = 0;  % 起点为 0
            
                % 计算加权距离
                for i = 1:n1
                    for j = 1:m1
                        % 计算位置、值和类型的距离
                        d_em(i,j) = sum(abs(Xem(i,:)-Yem(j,:)));
                        Dem(i+1,j+1) = d_em(i,j)+min([Dem(i,j+1),Dem(i+1,j),Dem(i,j)]) ;
                    end
                end
            
                for i = 1:n2
                    for j = 1:m2
                        % 计算位置、值和类型的距离
                        d_eM(i,j) = sum(abs(XeM(i,:)-YeM(j,:)));
                        DeM(i+1,j+1) = d_eM(i,j)+min([DeM(i,j+1),DeM(i+1,j),DeM(i,j)]) ;
                    end
                end
                dist_em = Dem(n1+1, m1+1);
                dist_eM = DeM(n2+1, m2+1);
                dist_FLEDTW = dist_em + dist_eM ; %FLEDTW 的相似性度量距离
            end

            function [Xem ,XeM] = max_min_matrix(time_series)
                % FLEDTW子函数 max_min_matrix ， 用于提取极小值特征矩阵 Xem 和极大值特征矩阵 XeM 
                % 输入为一个行向量，即原始时间序列 X ,即time_series
                % 输出为2个矩阵，分别为极小值特征矩阵 Xem 和极大值特征矩阵 XeM 
                % 选取极值点大小、极值点左拟合线段斜率、 极值点右拟合线段斜率、 极值点位置这四个特征作为极值特征量
                Xle = local_extreme(time_series) ;
                % extremum(i)对应极值点大小， place(i)对应极值点位置
                extremum = Xle(:,2) ;
                place = Xle(:,1) ;
                % 将极值序列 Xle 转换为极值特征矩阵 Xe
                % 极值特征矩阵 Xe 的第一列表示极值点大小，第二列表示极值点左拟合线段斜率，第三列表示极值点右拟合线段斜率，第四列表示极值点在原始时间序列的位置。每一行表示每一个极值点
                n = length(time_series); % n是时间序列的长度
                n_prime = length(Xle) ; % n_prime是极值序列的长度
            
                % 初始化矩阵
                k_left = zeros(n_prime, 1);
                k_right = zeros(n_prime, 1);
                Alpha_left = zeros(n_prime, 1);
                Alpha_right = zeros(n_prime, 1); 
                place_one = zeros(n_prime, 1); 
            
                Alpha_right(1) = atan((extremum(2)-extremum(1))/(place(2)-place(1)));
                Alpha_left(n_prime) = atan((extremum(n_prime-1)-extremum(n_prime))/(place(n_prime)-place(n_prime-1)));
            
                Xem(1,:) = [time_series(1),0,Alpha_right(1),1/n] ; % 极小值特征矩阵起始点赋值 
                XeM(1,:) = [time_series(1),0,Alpha_right(1),1/n] ; % 极大值特征矩阵起始点赋值
            
                for i = 1:n_prime
            
                    if i == 1
                        k_left(i) = 0; % 起始点的极值点左拟合线段斜率为0
                    else 
                        k_left(i) = (extremum(i)-extremum(i-1))/(place(i)-place(i-1)) ; % 极值点左拟合线段斜率
                    end
            
                    if i == n_prime
                        k_right(i) = 0 ; % 起始点的极值点右拟合线段斜率为0
                    else
                        k_right(i) =  (extremum(i+1)-extremum(i))/(place(i+1)-place(i)) ; % 极值点右拟合线段斜率
                    end
            
                    Alpha_left(i) = atan(k_left(i)) ; % 对极值点左拟合线段斜率归一化
                    Alpha_right(i) = atan(k_right(i)) ; % 对极值点右拟合线段斜率归一化
                    place_one(i) = place(i)/n ; % 对位置距离归一化
            
                    if Xle(i,3) == 1 % 如果是极大值类型
                        XeM = [XeM ; extremum(i),Alpha_left(i),Alpha_right(i),place_one(i)];
                    end
                    if Xle(i,3) == -1 % 如果是极小值类型
                        Xem = [Xem ; extremum(i),Alpha_left(i),Alpha_right(i),place_one(i)];
                    end
                end
            
                Xem = [Xem ; time_series(n),Alpha_left(n_prime), 0 ,1]; % 极小值特征矩阵终止点赋值 
                XeM = [XeM ; time_series(n),Alpha_left(n_prime), 0 ,1]; % 极大值特征矩阵终止点赋值
            end

            function result = local_extreme(time_series)
                % FLEDTW子函数 local_extreme , 用于提取极值序列，与 EWDTW_1 函数相同
                % 输入矩阵为一个行向量
                % 生成的矩阵为2维矩阵，第一列为记录的点的所在位置，第二列为记录的点的值，第三列为类型，极大值记为1，极小值记为-1，初始值和终止值记为0
                % 获取时间序列的长度
                n = length(time_series);
                
                % 初始化结果二维数组
                result = zeros(n, 3);  
                
                % 初始点和终止点类型是0
                result(1, 1) = 1;            % 初始点位置
                result(1, 2) = time_series(1); % 初始点值
                result(1, 3) = 0;            % 初始点类型
                
                result(n, 1) = n;            % 终止点位置
                result(n, 2) = time_series(n); % 终止点值
                result(n, 3) = 0;            % 终止点类型
                
                % 查找极值点
                for i = 2:n-1
                    if time_series(i) > time_series(i-1) && time_series(i) > time_series(i+1)
                        % 极大值点
                        result(i, 1) = i;            % 极大值点位置
                        result(i, 2) = time_series(i); % 极大值点值
                        result(i, 3) = 1;            % 极大值类型
                    elseif time_series(i) < time_series(i-1) && time_series(i) < time_series(i+1)
                        % 极小值点
                        result(i, 1) = i;            % 极小值点位置
                        result(i, 2) = time_series(i); % 极小值点值
                        result(i, 3) = -1;           % 极小值类型
                    end
                end
                
                % 清除不记录的点，将其位置填充为0
                result(result(:, 1) == 0, :) = [];
            end

            %% SFDTW相似度算法函数
            function distance = SFDTW_1(X, Y, r, eta, mu)
                % SFDTW算法。将时间序列分解为滤波信号，斜率信号和波动信号从而得到相似度量结果
                % 第一步将原始时间序列分解为滤波信号、 斜率信号和波动信号
                % 第二步将分解后的信号分别构建距离矩阵， 调整权重参数构建累积距离矩阵的加权结构
                % 第三步根据累积距离矩阵确定最佳弯曲路径， 将对齐路径应用于原始时间序列，得到相似度量结果
                % 滤波使用移动平均滤波，对时间序列用长度为(2*r+1)的滑动窗口对时间序列进行移动平均
            
                % 输入：原始序列 X 和 Y，滤波窗口参数 r，权重参数 η 和 μ ;一般 r 取2，η 取1，μ 取1
                % 输出：相似性度量距离 distance
            
                % 分解时间序列 X 和 Y
                [Fx, Kx, Cx] = separate(X, r); % 分别为时间序列 X 的滤波信号，斜率信号，波动信号
                [Fy, Ky, Cy] = separate(Y, r);
            
                n = length(X);
                m = length(Y);
            
                % 初始化累积距离矩阵
                Dsf = zeros(n+1, m+1); 
                % 设置边界条件
                Dsf(:, 1) = Inf;
                Dsf(1, :) = Inf;
                Dsf(1, 1) = 0;
            
                % 初始化分解信号距离矩阵
                F = zeros(n, m); % 滤波信号两点距离
                K = zeros(n, m); % 斜率信号两点距离
                C = zeros(n, m); % 波动信号两点距离
            
                % 计算分解信号两点距离
                for i = 1:n
                    for j = 1:m
                        F(i, j) = (Fx(i) - Fy(j))^2;
                        K(i, j) = (Kx(i) - Ky(j))^2;
                        C(i, j) = (Cx(i) - Cy(j))^2;
                    end
                end
            
                % 计算累积距离矩阵
                for i = 1:n
                    for j = 1:m
                        Dsf(i+1, j+1) = F(i, j) * eta * K(i, j) + mu * C(i, j) + min([Dsf(i+1, j), Dsf(i, j+1), Dsf(i, j)]);
                    end
                end
                distance = Dsf(n+1, m+1);
            end
            
            function [F, K, C] = separate(X, r)
                % SFDTW子函数，输入为原始时间序列X和滤波窗口参数r
                % 输出为滤波信号，斜率信号，波动信号

                % 输入：原始序列 X = {x1, ..., xn}，滤波窗口参数 r。
                % 输出：滤波信号 F = {f1, ..., fn},
                %       斜率信号 K = {k1, ..., kn}，
                %       波动信号 C = {c1, ..., cn}。
            
                n = length(X);
            %    windowSize = 2*r + 1;
                
                % 初始化滤波信号、波动信号和斜率信号
                F = zeros(1, n); % 初始化滤波信号
                C = zeros(1, n); % 初始化波动信号
                K = zeros(1, n); % 初始化斜率信号
            
                % 计算移动平均滤波信号
                for i = 1:n
                    if (i <= r) || (i > n-r)
                        F(i) = X(i);  % 前r个和后r个不滤波
                    else
                        F(i) = mean(X(i-r:i+r));  % 中间部分，包含r个邻近点
                    end
                end
            
                % 计算波动信号
                C = X - F;
            
                % 寻找滤波信号的局部极值点
                f_place = [];
                f_value = [];
                for i = 2:n-1
                    if (F(i) > F(i-1)) && (F(i) > F(i+1))  % 极大值
                        f_place = [f_place, i]; % 记录滤波信号的局部极值点在原时间序列的位置
                        f_value = [f_value, F(i)]; % 记录滤波信号的局部极值点大小
                    elseif (F(i) < F(i-1)) && (F(i) < F(i+1))  % 极小值
                        f_place = [f_place, i];
                        f_value = [f_value, F(i)];
                    end
                end
            
                % 计算斜率信号
                % 斜率K的计算是基于极值点之间的变化
                for i = 1:length(f_place)-1 % i 遍历滤波信号的局部极值点
                    for j = f_place(i):f_place(i+1)-1 % j 遍历第i个局部极值点和第i+1个局部极值点之间的原时间序列
                        K(j) = atan((f_value(i+1) - f_value(i)) / (f_place(i+1) - f_place(i)));  % 斜率计算，未计算的（如最后一个极值点以后的原时间序列的区域）斜率均为默认值0
                    end
                end
            end
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
           
            X = str2num(app.Sequence_1_2.Value) ; % 时间序列 X
            Y = str2num(app.Sequence_2_2.Value) ; % 时间序列 Y

            Xle = EWDTW_1(X) ;
            Yle = EWDTW_1(Y) ;
            L=length(X) ;
            % 调用 EWDTW_2 函数
            distance_EWDTW = EWDTW_2(Xle, Yle, 8, 0.2, L);
            % 调用 FLEDTW_1 函数
            distance_FLEDTW = FLEDTW_1(X,Y) ;
            % 调用 SFDTW_1 函数
            distance_SFDTW = SFDTW_1(X,Y,2,1,1) ;
            
            distance = app.EWDTW_weight.Value*distance_EWDTW + app.FLEDTW_weight.Value*distance_FLEDTW + app.SFDTW_weight.Value*distance_SFDTW ;
            app.Weighted_result.Value = num2str(distance) ;

            %% EWDTW相似度算法函数
            function result = EWDTW_1(time_series)
                % EWDTW算法。本函数用于提取原本序列的极大值和极小值，初始点和终止点
                % 输入矩阵为一个行向量
                % 生成的矩阵为2维矩阵，第一列为记录的点的所在位置，第二列为记录的点的值，第三列为类型，极大值记为1，极小值记为-1，初始值和终止值记为0
                % 获取时间序列的长度
                n = length(time_series);
                
                % 初始化结果二维数组
                result = zeros(n, 3);  
                
                % 初始点和终止点类型是0
                result(1, 1) = 1;            % 初始点位置
                result(1, 2) = time_series(1); % 初始点值
                result(1, 3) = 0;            % 初始点类型
                
                result(n, 1) = n;            % 终止点位置
                result(n, 2) = time_series(n); % 终止点值
                result(n, 3) = 0;            % 终止点类型
                
                % 查找极值点
                for i = 2:n-1
                    if time_series(i) > time_series(i-1) && time_series(i) > time_series(i+1)
                        % 极大值点
                        result(i, 1) = i;            % 极大值点位置
                        result(i, 2) = time_series(i); % 极大值点值
                        result(i, 3) = 1;            % 极大值类型
                    elseif time_series(i) < time_series(i-1) && time_series(i) < time_series(i+1)
                        % 极小值点
                        result(i, 1) = i;            % 极小值点位置
                        result(i, 2) = time_series(i); % 极小值点值
                        result(i, 3) = -1;           % 极小值类型
                    end
                end
                
                % 清除不记录的点，将其位置填充为0
                result(result(:, 1) == 0, :) = [];
            end

            function distance = EWDTW_2(Xle, Yle, Alpha, Beta, L) % 其中 L=length(X); 即为Xle的原始序列长度.Alpha默认值为8 ,Beta默认值为 0.2
                % EWDTW算法。本函数用于计算两个序列的相似性度量距离
                % 输入为极值序列Xle和Yle ；加权参数Alpha和Beta. 原始序列的时间长度L
                % 获取输入矩阵的行数
                n = size(Xle, 1); % Xle 的行数
                m = size(Yle, 1); % Yle 的行数
            
                % 初始化 DTW 距离矩阵
                position_distance = zeros(n, m);
                value_distance = zeros(n, m);
                type_distance = zeros(n, m);
                W = zeros(n, m); % W是距离矩阵
                Wmax = 2 ; % Wmax是函数的期望上界,不影响权重函数曲线,值固定为 2
                mc = round(L/2) ; % mc 是原始序列中点.对于这个"中点"的理解，也有的理解为是对齐的那个点的原始序列位置值
            
                DEW = zeros(n+1, m+1);
                % 设置边界条件
                DEW(:, 1) = Inf;
                DEW(1, :) = Inf;
                DEW(1, 1) = 0;  % 起点为 0
            
                % 计算加权距离
                for i = 1:n
                    for j = 1:m
                        % 计算位置、值和类型的距离
                        position_distance(i,j) = abs(Xle(i,1)-Yle(j, 1));
                        value_distance(i,j) = abs(Xle(i,2)-Yle(j, 2));
                        type_distance(i,j) = 1-Beta*Xle(i,3)*Yle(j, 3);
                        
                        W(i,j) = Wmax/(1+exp(-Alpha*(position_distance(i,j)*type_distance(i,j)-mc)/L)) ;
                        DEW(i+1,j+1) = W(i,j)*value_distance(i,j)+min([DEW(i,j+1),DEW(i+1,j),DEW(i,j)]) ;
            
                    end
                end
            
                % 最终的相似性度量就是DTW矩阵的右下角元素
                distance = DEW(n+1,m+1);  
            end

            %% FLEDTW相似度算法函数
            function dist_FLEDTW = FLEDTW_1(time_series_X ,time_series_Y )
                % FLEDTW算法。本函数用于直接生成俩序列的相似度量距离。子函数为提取时间序列的极小值特征矩阵和极大值特征矩阵
                % 输入矩阵为两个行向量X,Y
                % 输出为相似度量距离
                % 生成的矩阵为3维矩阵，第一列为记录的点的所在位置，第二列为记录的点的值，第三列为类型，极大值记为1，极小值记为-1，初始值和终止值记为0
                [Xem, XeM] = max_min_matrix (time_series_X) ;
                [Yem, YeM] = max_min_matrix (time_series_Y) ;
            
                n1 = size(Xem, 1); % 极小值特征矩阵 Xem 的行数
                n2 = size(XeM, 1); % 极大值特征矩阵 XeM 的行数
            
                m1 = size(Yem, 1); % 极小值特征矩阵 Yem 的行数
                m2 = size(YeM, 1); % 极大值特征矩阵 YeM 的行数
            
                Dem = zeros(n1+1, m1+1); % 极小值特征矩阵的相似性度量距离
                DeM = zeros(n2+1, m2+1); % 极大值特征矩阵的相似性度量距离
            
                d_em= zeros(n1, m1);
                d_eM= zeros(n2, m2);
            
                % 设置边界条件
                Dem(:, 1) = Inf;
                Dem(1, :) = Inf;
                Dem(1, 1) = 0;  % 起点为 0
            
                DeM(:, 1) = Inf;
                DeM(1, :) = Inf;
                DeM(1, 1) = 0;  % 起点为 0
            
                % 计算加权距离
                for i = 1:n1
                    for j = 1:m1
                        % 计算位置、值和类型的距离
                        d_em(i,j) = sum(abs(Xem(i,:)-Yem(j,:)));
                        Dem(i+1,j+1) = d_em(i,j)+min([Dem(i,j+1),Dem(i+1,j),Dem(i,j)]) ;
                    end
                end
            
                for i = 1:n2
                    for j = 1:m2
                        % 计算位置、值和类型的距离
                        d_eM(i,j) = sum(abs(XeM(i,:)-YeM(j,:)));
                        DeM(i+1,j+1) = d_eM(i,j)+min([DeM(i,j+1),DeM(i+1,j),DeM(i,j)]) ;
                    end
                end
                dist_em = Dem(n1+1, m1+1);
                dist_eM = DeM(n2+1, m2+1);
                dist_FLEDTW = dist_em + dist_eM ; %FLEDTW 的相似性度量距离
            end

            function [Xem ,XeM] = max_min_matrix(time_series)
                % FLEDTW子函数 max_min_matrix ， 用于提取极小值特征矩阵 Xem 和极大值特征矩阵 XeM 
                % 输入为一个行向量，即原始时间序列 X ,即time_series
                % 输出为2个矩阵，分别为极小值特征矩阵 Xem 和极大值特征矩阵 XeM 
                % 选取极值点大小、极值点左拟合线段斜率、 极值点右拟合线段斜率、 极值点位置这四个特征作为极值特征量
                Xle = local_extreme(time_series) ;
                % extremum(i)对应极值点大小， place(i)对应极值点位置
                extremum = Xle(:,2) ;
                place = Xle(:,1) ;
                % 将极值序列 Xle 转换为极值特征矩阵 Xe
                % 极值特征矩阵 Xe 的第一列表示极值点大小，第二列表示极值点左拟合线段斜率，第三列表示极值点右拟合线段斜率，第四列表示极值点在原始时间序列的位置。每一行表示每一个极值点
                n = length(time_series); % n是时间序列的长度
                n_prime = length(Xle) ; % n_prime是极值序列的长度
            
                % 初始化矩阵
                k_left = zeros(n_prime, 1);
                k_right = zeros(n_prime, 1);
                Alpha_left = zeros(n_prime, 1);
                Alpha_right = zeros(n_prime, 1); 
                place_one = zeros(n_prime, 1); 
            
                Alpha_right(1) = atan((extremum(2)-extremum(1))/(place(2)-place(1)));
                Alpha_left(n_prime) = atan((extremum(n_prime-1)-extremum(n_prime))/(place(n_prime)-place(n_prime-1)));
            
                Xem(1,:) = [time_series(1),0,Alpha_right(1),1/n] ; % 极小值特征矩阵起始点赋值 
                XeM(1,:) = [time_series(1),0,Alpha_right(1),1/n] ; % 极大值特征矩阵起始点赋值
            
                for i = 1:n_prime
            
                    if i == 1
                        k_left(i) = 0; % 起始点的极值点左拟合线段斜率为0
                    else 
                        k_left(i) = (extremum(i)-extremum(i-1))/(place(i)-place(i-1)) ; % 极值点左拟合线段斜率
                    end
            
                    if i == n_prime
                        k_right(i) = 0 ; % 起始点的极值点右拟合线段斜率为0
                    else
                        k_right(i) =  (extremum(i+1)-extremum(i))/(place(i+1)-place(i)) ; % 极值点右拟合线段斜率
                    end
            
                    Alpha_left(i) = atan(k_left(i)) ; % 对极值点左拟合线段斜率归一化
                    Alpha_right(i) = atan(k_right(i)) ; % 对极值点右拟合线段斜率归一化
                    place_one(i) = place(i)/n ; % 对位置距离归一化
            
                    if Xle(i,3) == 1 % 如果是极大值类型
                        XeM = [XeM ; extremum(i),Alpha_left(i),Alpha_right(i),place_one(i)];
                    end
                    if Xle(i,3) == -1 % 如果是极小值类型
                        Xem = [Xem ; extremum(i),Alpha_left(i),Alpha_right(i),place_one(i)];
                    end
                end
            
                Xem = [Xem ; time_series(n),Alpha_left(n_prime), 0 ,1]; % 极小值特征矩阵终止点赋值 
                XeM = [XeM ; time_series(n),Alpha_left(n_prime), 0 ,1]; % 极大值特征矩阵终止点赋值
            end

            function result = local_extreme(time_series)
                % FLEDTW子函数 local_extreme , 用于提取极值序列，与 EWDTW_1 函数相同
                % 输入矩阵为一个行向量
                % 生成的矩阵为2维矩阵，第一列为记录的点的所在位置，第二列为记录的点的值，第三列为类型，极大值记为1，极小值记为-1，初始值和终止值记为0
                % 获取时间序列的长度
                n = length(time_series);
                
                % 初始化结果二维数组
                result = zeros(n, 3);  
                
                % 初始点和终止点类型是0
                result(1, 1) = 1;            % 初始点位置
                result(1, 2) = time_series(1); % 初始点值
                result(1, 3) = 0;            % 初始点类型
                
                result(n, 1) = n;            % 终止点位置
                result(n, 2) = time_series(n); % 终止点值
                result(n, 3) = 0;            % 终止点类型
                
                % 查找极值点
                for i = 2:n-1
                    if time_series(i) > time_series(i-1) && time_series(i) > time_series(i+1)
                        % 极大值点
                        result(i, 1) = i;            % 极大值点位置
                        result(i, 2) = time_series(i); % 极大值点值
                        result(i, 3) = 1;            % 极大值类型
                    elseif time_series(i) < time_series(i-1) && time_series(i) < time_series(i+1)
                        % 极小值点
                        result(i, 1) = i;            % 极小值点位置
                        result(i, 2) = time_series(i); % 极小值点值
                        result(i, 3) = -1;           % 极小值类型
                    end
                end
                
                % 清除不记录的点，将其位置填充为0
                result(result(:, 1) == 0, :) = [];
            end

            %% SFDTW相似度算法函数
            function distance = SFDTW_1(X, Y, r, eta, mu)
                % SFDTW算法。将时间序列分解为滤波信号，斜率信号和波动信号从而得到相似度量结果
                % 第一步将原始时间序列分解为滤波信号、 斜率信号和波动信号
                % 第二步将分解后的信号分别构建距离矩阵， 调整权重参数构建累积距离矩阵的加权结构
                % 第三步根据累积距离矩阵确定最佳弯曲路径， 将对齐路径应用于原始时间序列，得到相似度量结果
                % 滤波使用移动平均滤波，对时间序列用长度为(2*r+1)的滑动窗口对时间序列进行移动平均
            
                % 输入：原始序列 X 和 Y，滤波窗口参数 r，权重参数 η 和 μ ;一般 r 取2，η 取1，μ 取1
                % 输出：相似性度量距离 distance
            
                % 分解时间序列 X 和 Y
                [Fx, Kx, Cx] = separate(X, r); % 分别为时间序列 X 的滤波信号，斜率信号，波动信号
                [Fy, Ky, Cy] = separate(Y, r);
            
                n = length(X);
                m = length(Y);
            
                % 初始化累积距离矩阵
                Dsf = zeros(n+1, m+1); 
                % 设置边界条件
                Dsf(:, 1) = Inf;
                Dsf(1, :) = Inf;
                Dsf(1, 1) = 0;
            
                % 初始化分解信号距离矩阵
                F = zeros(n, m); % 滤波信号两点距离
                K = zeros(n, m); % 斜率信号两点距离
                C = zeros(n, m); % 波动信号两点距离
            
                % 计算分解信号两点距离
                for i = 1:n
                    for j = 1:m
                        F(i, j) = (Fx(i) - Fy(j))^2;
                        K(i, j) = (Kx(i) - Ky(j))^2;
                        C(i, j) = (Cx(i) - Cy(j))^2;
                    end
                end
            
                % 计算累积距离矩阵
                for i = 1:n
                    for j = 1:m
                        Dsf(i+1, j+1) = F(i, j) * eta * K(i, j) + mu * C(i, j) + min([Dsf(i+1, j), Dsf(i, j+1), Dsf(i, j)]);
                    end
                end
                distance = Dsf(n+1, m+1);
            end
            
            function [F, K, C] = separate(X, r)
                % SFDTW子函数，输入为原始时间序列X和滤波窗口参数r
                % 输出为滤波信号，斜率信号，波动信号

                % 输入：原始序列 X = {x1, ..., xn}，滤波窗口参数 r。
                % 输出：滤波信号 F = {f1, ..., fn},
                %       斜率信号 K = {k1, ..., kn}，
                %       波动信号 C = {c1, ..., cn}。
            
                n = length(X);
            %    windowSize = 2*r + 1;
                
                % 初始化滤波信号、波动信号和斜率信号
                F = zeros(1, n); % 初始化滤波信号
                C = zeros(1, n); % 初始化波动信号
                K = zeros(1, n); % 初始化斜率信号
            
                % 计算移动平均滤波信号
                for i = 1:n
                    if (i <= r) || (i > n-r)
                        F(i) = X(i);  % 前r个和后r个不滤波
                    else
                        F(i) = mean(X(i-r:i+r));  % 中间部分，包含r个邻近点
                    end
                end
            
                % 计算波动信号
                C = X - F;
            
                % 寻找滤波信号的局部极值点
                f_place = [];
                f_value = [];
                for i = 2:n-1
                    if (F(i) > F(i-1)) && (F(i) > F(i+1))  % 极大值
                        f_place = [f_place, i]; % 记录滤波信号的局部极值点在原时间序列的位置
                        f_value = [f_value, F(i)]; % 记录滤波信号的局部极值点大小
                    elseif (F(i) < F(i-1)) && (F(i) < F(i+1))  % 极小值
                        f_place = [f_place, i];
                        f_value = [f_value, F(i)];
                    end
                end
            
                % 计算斜率信号
                % 斜率K的计算是基于极值点之间的变化
                for i = 1:length(f_place)-1 % i 遍历滤波信号的局部极值点
                    for j = f_place(i):f_place(i+1)-1 % j 遍历第i个局部极值点和第i+1个局部极值点之间的原时间序列
                        K(j) = atan((f_value(i+1) - f_value(i)) / (f_place(i+1) - f_place(i)));  % 斜率计算，未计算的（如最后一个极值点以后的原时间序列的区域）斜率均为默认值0
                    end
                end
            end
        end

        % Button pushed function: Path_import1
        function Path_import1ButtonPushed(app, event)
            % 打开文件选择对话框，仅显示.mat文件
            [filename, pathname] = uigetfile('*.mat', '选择MAT文件');
            
            % 如果没有取消选择
            if ~isequal(filename, 0)
                % 构建完整文件路径
                fullpath = fullfile(pathname, filename);
                
                try
                    % 加载.mat文件内容
                    fileContents = load(fullpath);
                    
                    % 获取文件中的所有变量名
                    varNames = fieldnames(fileContents);
                    
                    % 检查是否至少有一个变量
                    if isempty(varNames)
                        errordlg('选中的.mat文件不包含任何变量', '导入错误');
                        return;
                    end
                    
                    % 如果有多个变量，让用户选择要导入哪个
                    if length(varNames) > 1
                        [selection, ok] = listdlg(...
                            'PromptString', '选择要导入的变量:', ...
                            'SelectionMode', 'single', ...
                            'ListString', varNames);
                        
                        if ~ok
                            return;  % 用户取消了选择
                        end
                        VarName = varNames{selection};
                    else
                        VarName = varNames{1};
                    end

                    % 使用 sprintf 和 %g 格式
                    str = sprintf('%g ', fileContents.(VarName));
                    app.Sequence_1.Value = strtrim(str); % 去除末尾空格
                    
                    % 检查是否是矩阵
                    if ~ismatrix(fileContents.(VarName))
                        errordlg('导入变量不是矩阵', '导入错误');
                        return;
                    end
                    
                    
                    % 显示成功消息和变量信息
                    msgbox(sprintf('成功导入矩阵数据\n变量名: %s\n大小: %s', ...
                        VarName, mat2str(size(fileContents.(VarName)))), '导入完成');    

                catch ME
                    % 显示错误信息
                    errordlg(sprintf('导入文件时出错:\n%s', ME.message), '导入错误');
                end
            end

        end

        % Button pushed function: Path_import2
        function Path_import2ButtonPushed(app, event)
           % 打开文件选择对话框，仅显示.mat文件
            [filename, pathname] = uigetfile('*.mat', '选择MAT文件');
            
            % 如果没有取消选择
            if ~isequal(filename, 0)
                % 构建完整文件路径
                fullpath = fullfile(pathname, filename);
                
                try
                    % 加载.mat文件内容
                    fileContents = load(fullpath);
                    
                    % 获取文件中的所有变量名
                    varNames = fieldnames(fileContents);
                    
                    % 检查是否至少有一个变量
                    if isempty(varNames)
                        errordlg('选中的.mat文件不包含任何变量', '导入错误');
                        return;
                    end
                    
                    % 如果有多个变量，让用户选择要导入哪个
                    if length(varNames) > 1
                        [selection, ok] = listdlg(...
                            'PromptString', '选择要导入的变量:', ...
                            'SelectionMode', 'single', ...
                            'ListString', varNames);
                        
                        if ~ok
                            return;  % 用户取消了选择
                        end
                        VarName = varNames{selection};
                    else
                        VarName = varNames{1};
                    end

                    % 使用 sprintf 和 %g 格式
                    str = sprintf('%g ', fileContents.(VarName));
                    app.Sequence_2.Value = strtrim(str); % 去除末尾空格
                    
                    % 检查是否是矩阵
                    if ~ismatrix(fileContents.(VarName))
                        errordlg('导入变量不是矩阵', '导入错误');
                        return;
                    end
                    
                    
                    % 显示成功消息和变量信息
                    msgbox(sprintf('成功导入矩阵数据\n变量名: %s\n大小: %s', ...
                        VarName, mat2str(size(fileContents.(VarName)))), '导入完成');    

                catch ME
                    % 显示错误信息
                    errordlg(sprintf('导入文件时出错:\n%s', ME.message), '导入错误');
                end
            end


        end

        % Button pushed function: Path_import1_2
        function Path_import1_2ButtonPushed(app, event)
            % 打开文件选择对话框，仅显示.mat文件
            [filename, pathname] = uigetfile('*.mat', '选择MAT文件');
            
            % 如果没有取消选择
            if ~isequal(filename, 0)
                % 构建完整文件路径
                fullpath = fullfile(pathname, filename);
                
                try
                    % 加载.mat文件内容
                    fileContents = load(fullpath);
                    
                    % 获取文件中的所有变量名
                    varNames = fieldnames(fileContents);
                    
                    % 检查是否至少有一个变量
                    if isempty(varNames)
                        errordlg('选中的.mat文件不包含任何变量', '导入错误');
                        return;
                    end
                    
                    % 如果有多个变量，让用户选择要导入哪个
                    if length(varNames) > 1
                        [selection, ok] = listdlg(...
                            'PromptString', '选择要导入的变量:', ...
                            'SelectionMode', 'single', ...
                            'ListString', varNames);
                        
                        if ~ok
                            return;  % 用户取消了选择
                        end
                        VarName = varNames{selection};
                    else
                        VarName = varNames{1};
                    end

                    % 使用 sprintf 和 %g 格式
                    str = sprintf('%g ', fileContents.(VarName));
                    app.Sequence_1_2.Value = strtrim(str); % 去除末尾空格
                    
                    % 检查是否是矩阵
                    if ~ismatrix(fileContents.(VarName))
                        errordlg('导入变量不是矩阵', '导入错误');
                        return;
                    end
                    
                    
                    % 显示成功消息和变量信息
                    msgbox(sprintf('成功导入矩阵数据\n变量名: %s\n大小: %s', ...
                        VarName, mat2str(size(fileContents.(VarName)))), '导入完成');    

                catch ME
                    % 显示错误信息
                    errordlg(sprintf('导入文件时出错:\n%s', ME.message), '导入错误');
                end
            end

        end

        % Button pushed function: Path_import2_2
        function Path_import2_2ButtonPushed(app, event)
            % 打开文件选择对话框，仅显示.mat文件
            [filename, pathname] = uigetfile('*.mat', '选择MAT文件');
            
            % 如果没有取消选择
            if ~isequal(filename, 0)
                % 构建完整文件路径
                fullpath = fullfile(pathname, filename);
                
                try
                    % 加载.mat文件内容
                    fileContents = load(fullpath);
                    
                    % 获取文件中的所有变量名
                    varNames = fieldnames(fileContents);
                    
                    % 检查是否至少有一个变量
                    if isempty(varNames)
                        errordlg('选中的.mat文件不包含任何变量', '导入错误');
                        return;
                    end
                    
                    % 如果有多个变量，让用户选择要导入哪个
                    if length(varNames) > 1
                        [selection, ok] = listdlg(...
                            'PromptString', '选择要导入的变量:', ...
                            'SelectionMode', 'single', ...
                            'ListString', varNames);
                        
                        if ~ok
                            return;  % 用户取消了选择
                        end
                        VarName = varNames{selection};
                    else
                        VarName = varNames{1};
                    end

                    % 使用 sprintf 和 %g 格式
                    str = sprintf('%g ', fileContents.(VarName));
                    app.Sequence_2_2.Value = strtrim(str); % 去除末尾空格
                    
                    % 检查是否是矩阵
                    if ~ismatrix(fileContents.(VarName))
                        errordlg('导入变量不是矩阵', '导入错误');
                        return;
                    end
                    
                    
                    % 显示成功消息和变量信息
                    msgbox(sprintf('成功导入矩阵数据\n变量名: %s\n大小: %s', ...
                        VarName, mat2str(size(fileContents.(VarName)))), '导入完成');    

                catch ME
                    % 显示错误信息
                    errordlg(sprintf('导入文件时出错:\n%s', ME.message), '导入错误');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '相似度算法';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '相似度算法结果';
            app.GridLayout = uigridlayout(app.Tab);
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '时间序列1';
            app.EditField_2Label = uilabel(app.GridLayout);
            app.EditField_2Label.Text = '时间序列2';
            app.Sequence_2 = uieditfield(app.GridLayout, 'text');
            app.Sequence_2.Value = '2, 4, 3, 6, 5, 7, 4, 3, 6, 5, 6, 8';
            app.Sequence_1 = uieditfield(app.GridLayout, 'text');
            app.Sequence_1.Value = '1, 3, 2, 5, 4, 6, 7';
            app.EWDTWTextAreaLabel = uilabel(app.GridLayout);
            app.EWDTWTextAreaLabel.Text = 'EWDTW算法相似度结果';
            app.FLEDTWLabel = uilabel(app.GridLayout);
            app.FLEDTWLabel.Text = 'FLEDTW算法相似度结果';
            app.SFDTWLabel = uilabel(app.GridLayout);
            app.SFDTWLabel.Text = 'SFDTW算法相似度结果';
            app.SFDTWTextArea = uitextarea(app.GridLayout);
            app.SFDTWTextArea.Editable = 'off';
            app.FLEDTWTextArea = uitextarea(app.GridLayout);
            app.FLEDTWTextArea.Editable = 'off';
            app.EWDTWTextArea = uitextarea(app.GridLayout);
            app.EWDTWTextArea.Editable = 'off';
            app.Path_import2 = uibutton(app.GridLayout, 'push');
            app.Path_import2.ButtonPushedFcn = createCallbackFcn(app, @Path_import2ButtonPushed, true);
            app.Path_import2.Text = '路径导入2';
            app.Path_import1 = uibutton(app.GridLayout, 'push');
            app.Path_import1.ButtonPushedFcn = createCallbackFcn(app, @Path_import1ButtonPushed, true);
            app.Path_import1.Text = '路径导入1';
            app.Button_6 = uibutton(app.GridLayout, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '开始计算';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '相似度加权处理';
            app.GridLayout_2 = uigridlayout(app.Tab_3);
            app.Label_3 = uilabel(app.GridLayout_2);
            app.Label_3.Text = '加权后相似度结果';
            app.Weighted_result = uieditfield(app.GridLayout_2, 'text');
            app.Weighted_result.Editable = 'off';
            app.Path_import2_2 = uibutton(app.GridLayout_2, 'push');
            app.Path_import2_2.ButtonPushedFcn = createCallbackFcn(app, @Path_import2_2ButtonPushed, true);
            app.Path_import2_2.Text = '路径导入2';
            app.Path_import1_2 = uibutton(app.GridLayout_2, 'push');
            app.Path_import1_2.ButtonPushedFcn = createCallbackFcn(app, @Path_import1_2ButtonPushed, true);
            app.Path_import1_2.Text = '路径导入1';
            app.Button_7 = uibutton(app.GridLayout_2, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '开始计算';
            app.FLEDTWLabel_3 = uilabel(app.GridLayout_2);
            app.FLEDTWLabel_3.Text = 'EWDTW算法相似度权重';
            app.EWDTW_weight = uieditfield(app.GridLayout_2, 'numeric');
            app.EWDTW_weight.Value = 1;
            app.FLEDTWEditFieldLabel = uilabel(app.GridLayout_2);
            app.FLEDTWEditFieldLabel.Text = 'FLEDTW算法相似度权重';
            app.FLEDTW_weight = uieditfield(app.GridLayout_2, 'numeric');
            app.FLEDTW_weight.Value = 1;
            app.SFDTWEditFieldLabel = uilabel(app.GridLayout_2);
            app.SFDTWEditFieldLabel.Text = 'SFDTW算法相似度权重';
            app.SFDTW_weight = uieditfield(app.GridLayout_2, 'numeric');
            app.SFDTW_weight.Value = 1;
            app.Label_4 = uilabel(app.GridLayout_2);
            app.Label_4.Text = '时间序列1';
            app.Sequence_1_2 = uieditfield(app.GridLayout_2, 'text');
            app.Sequence_1_2.Value = '1, 3, 2, 5, 4, 6, 7';
            app.Label_5 = uilabel(app.GridLayout_2);
            app.Label_5.Text = '时间序列2';
            app.Sequence_2_2 = uieditfield(app.GridLayout_2, 'text');
            app.Sequence_2_2.Value = '2, 4, 3, 6, 5, 7, 4, 3, 6, 5, 6, 8';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Similarity_Display

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end